classdef CCPStackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        CCPTopography               matlab.ui.control.UIAxes
        CCPImageMap                 matlab.ui.control.UIAxes
        OKButton                    matlab.ui.control.Button
        CancelGUI                   matlab.ui.control.Button
        CCPcalculatePanel           matlab.ui.container.Panel
        RunButtonCCP                matlab.ui.control.Button
        GridPanel                   matlab.ui.container.Panel
        PlotparametersPanel         matlab.ui.container.Panel
        SmoothingParEditField       matlab.ui.control.NumericEditField
        SmoothingkmLabel            matlab.ui.control.Label
        PickMohoButton              matlab.ui.control.Button
        CustomProfileButton         matlab.ui.control.Button
        SaveFigureButton            matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        Y2EditField                 matlab.ui.control.NumericEditField
        Y2Label                     matlab.ui.control.Label
        X2EditField                 matlab.ui.control.NumericEditField
        X2Label                     matlab.ui.control.Label
        Y1EditField                 matlab.ui.control.NumericEditField
        Y1Label                     matlab.ui.control.Label
        X1EditField                 matlab.ui.control.NumericEditField
        X1Label                     matlab.ui.control.Label
        CreategridPanel             matlab.ui.container.Panel
        VelocityModelDropDown       matlab.ui.control.DropDown
        VelocityModelDropDownLabel  matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        dzkmEditField               matlab.ui.control.NumericEditField
        dzkmLabel                   matlab.ui.control.Label
        zmaxkmEditField             matlab.ui.control.NumericEditField
        zmaxkmEditFieldLabel        matlab.ui.control.Label
        dykmEditField               matlab.ui.control.NumericEditField
        dykmLabel                   matlab.ui.control.Label
        dxEditField                 matlab.ui.control.NumericEditField
        dxkmLabel                   matlab.ui.control.Label
        StatusEditField             matlab.ui.control.EditField
        StatusEditFieldLabel        matlab.ui.control.Label
        CCPStackingPanelLabel       matlab.ui.control.Label
    end

    properties (Access = public)
        mainApp
        DataStruct       
        ccpResult
        gridStruct
        plotFlag=false
        cmap
        dem 
       
        currentProfileDistAll
        currentProfileDepthAll 
        currentProfileVAll
        currentProfilePoints

        currentStraightProfile_xi
        currentStraightProfile_yi
        currentProfile_xp_all
        currentProfile_yp_all
        Fi_current
    end
    
    methods (Access = private)
        
        function plotStationsInApp(app, gridStruct,plotFlag)
            delete(allchild(app.GridPanel));
            
            stationX = gridStruct.rx(:, 1);
            stationY = gridStruct.ry(:, 2);     
            XX = gridStruct.X;
            YY = gridStruct.Y;

            gax = axes('Parent', app.GridPanel, ...
                'Units', 'normalized', ...
                'Position', [0.08 0.12 0.90 0.80]); 
            hold(gax, 'on');
            
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'LineWidth', 1.5, ...
                'MarkerEdgeColor', 'r', ...
                'DisplayName', 'Distribution of station');
           
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', 'Grid');
            
            xlabel(gax, 'X (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(gax, 'Y (km)', 'FontSize', 12, 'FontWeight', 'bold');
            title(gax, 'Geometry', 'FontSize', 16, 'FontWeight', 'bold');
            
            box(gax,"on");
            grid(gax, 'on');
            grid(gax, 'minor'); 
            axis(gax, 'equal');
            
            xMargin = gridStruct.dx; 
            yMargin = gridStruct.dy; 
            xlim(gax, [min(XX(:)) - xMargin, max(XX(:)) + xMargin]);
            ylim(gax, [min(YY(:)) - yMargin, max(YY(:)) + yMargin]);
            
            set(gax, 'FontSize', 14, 'Color', 'w');
            set(app.GridPanel, 'BackgroundColor', 'w');

            if plotFlag == 1 
                coord1 = [app.X1EditField.Value,app.Y1EditField.Value];
                coord2 = [app.X2EditField.Value,app.Y2EditField.Value];
    
                plot(gax,[coord1(1),coord2(1)],[coord1(2),coord2(2)],'b-o','LineWidth',2)
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');  
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');

                % text(gax,coord1(1),coord1(2),'A','FontSize',20,'HorizontalAlignment','right')
                % text(gax,coord2(1),coord2(2),'B','FontSize',20,'HorizontalAlignment','left')
            end

            grid(gax, 'on');
            hold(gax, 'off');
        end

        
        function plotStationsProInApp(app,gridStruct)
            delete(allchild(app.GridPanel));
            
            stationX = gridStruct.rx(:, 1);
            stationY = gridStruct.ry(:, 2);     
            XX = gridStruct.X;
            YY = gridStruct.Y;

            gax = axes('Parent', app.GridPanel, ...
                'Units', 'normalized', ...
                'Position', [0.08 0.12 0.90 0.80]); 
            hold(gax, 'on');
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'LineWidth', 1.5, ...
                'MarkerEdgeColor', 'r', ...
                'DisplayName', 'Distribution of station');
           
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', 'Grid');
            
            xlabel(gax, 'X (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(gax, 'Y (km)', 'FontSize', 12, 'FontWeight', 'bold');
            title(gax, 'Geometry', 'FontSize', 14, 'FontWeight', 'bold');
            
            box(gax,"on");
            grid(gax, 'on');
            grid(gax, 'minor'); 
            axis(gax, 'equal');
            
            xMargin = gridStruct.dx; 
            yMargin = gridStruct.dy; 
            xlim(gax, [min(XX(:)) - xMargin, max(XX(:)) + xMargin]);
            ylim(gax, [min(YY(:)) - yMargin, max(YY(:)) + yMargin]);
            
            set(gax, 'FontSize', 14, 'Color', 'w');
            set(app.GridPanel, 'BackgroundColor', 'w');

            plot(gax,app.currentProfilePoints(:,1),app.currentProfilePoints(:,2),'b-o','LineWidth',2)

            for i = 1:size(app.currentProfilePoints,1)
                text(gax,app.currentProfilePoints(i,1), app.currentProfilePoints(i,2), num2str(i-1), ...
                    'VerticalAlignment', 'bottom', ...
                    'HorizontalAlignment', 'right', ...
                    'FontSize', 10, ...
                    'Color', 'blue', ...
                    'FontWeight', 'bold');
            end

            grid(gax, 'on');
            hold(gax, 'off');

        end

        
        function V_smooth = smoothWithDepth(app,V, z, R_func)
            nz = size(V,1);
            V_smooth = zeros(size(V));
            dx = app.gridStruct.dx;
            dy = app.gridStruct.dy;

            for iz = 1:nz
                radius_km = max(1, R_func(z(iz)));
                
                radius_x_pixels = radius_km / dx;
                radius_y_pixels = radius_km / dy;

                sigma_x = max(0.5, radius_x_pixels / 2);
                sigma_y = max(0.5, radius_y_pixels / 2);

                ksize_x = 2 * ceil(3 * sigma_x) + 1;
                ksize_y = 2 * ceil(3 * sigma_y) + 1;

                x = linspace(-ksize_x/2, ksize_x/2, ksize_x);
                y = linspace(-ksize_y/2, ksize_y/2, ksize_y);
                [X, Y] = meshgrid(x, y);

                kernel = exp(-(X.^2/(2*sigma_x^2) + Y.^2/(2*sigma_y^2)));
                kernel = kernel / sum(kernel(:));  

                slice = squeeze(V(iz, :, :));
                V_smooth(iz, :, :) = conv2(slice, kernel, 'same');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'CCPStacking App';
            app.cmap = load(fullfile(pwd,'visualization/colormap/roma.mat'));
            
        end

        % Callback function
        function CancelButtonPushed(app, event)
            answer = questdlg('Do you want to exit?', '', 'YES', 'NO', 'NO');
            switch answer
                case 'YES'
                    delete(app.UIFigure); 
                case 'NO'
                    return; 
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data  available!', 'Error','Icon','error');
                return;
            end
            tmp = app.DataStruct(1).StationInfo.sta;
            stnet = tmp(1:2);
            if strcmp(stnet,'SL') == 1
                app.dem = load('SichuanLongmenshan.mat'); 
                xnpts = 10;ynpts = 2;
            elseif strcmp(stnet,'BY') == 1
                app.dem = load('Baiyanebo_DEM.mat'); 
                xnpts = 5;ynpts = 5;
            else
                app.dem = load('dem_data.mat'); 
                xnpts = 10;ynpts = 5;
            end

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','Grid and velocity model', ...
                    'Message','Creating grid...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            % create grid
            app.gridStruct = createGrid(app.DataStruct, app.dxEditField.Value, app.dykmEditField.Value, app.dzkmEditField.Value, app.zmaxkmEditField.Value);

            % velocity model
            ModelType = app.VelocityModelDropDown.Value;

            if strcmp(ModelType,'Default (3D)')
                ModelType = '3D';
            end

            app.gridStruct = getVelocityModel(ModelType,app.gridStruct,xnpts,ynpts);
%             if strcmp(ModelType,'3D')
%                 depindx = find(app.gridStruct.z == 50);          
%                 Vs = app.gridStruct.VS(:,:,depindx);
%             end

            plotStationsInApp(app,app.gridStruct,app.plotFlag);
            app.StatusEditField.Value = 'Grid created';

            close(msg);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            smoothPara = app.SmoothingParEditField.Value;
        
            app.plotFlag = true;
            plotStationsInApp(app,app.gridStruct,app.plotFlag);
        
            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP results', ...
                    'Message','Plotting...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            
            X = app.ccpResult.X;
            Y = app.ccpResult.Y;
            Z = app.ccpResult.Z;
            depth = squeeze(Z(1,1,:));
            img = app.ccpResult.img;
            count = app.ccpResult.count;

            V = sum(img,4)./sum(count,4);
            V(isnan(V))=0;

            [~,~,nz] = size(X);

            if smoothPara>=0

                alpha = smoothPara;
                beta = 0.5;
                R_func = @(z_cur) alpha * z_cur^beta;

                V_smooth = smoothWithDepth(app,permute(V,[3,2,1]), depth, R_func);

                V_smooth = permute(V_smooth,[3,2,1]);

            else
                V_smooth = V;
            end

            % plot
            Fi = scatteredInterpolant(X(:),Y(:),Z(:),V_smooth(:));
            app.Fi_current = Fi;
        
            x1 = app.X1EditField.Value;
            x2 = app.X2EditField.Value;
            y1 = app.Y1EditField.Value;
            y2 = app.Y2EditField.Value;
        
            num_profile_points = 100; 
            xi = linspace(x1,x2,num_profile_points);
            yi = linspace(y1,y2,num_profile_points);

            xdist = linspace(0,sqrt((x1-x2)^2 + (y1-y2)^2),num_profile_points);

            xpro = repmat(xi,nz,1);
            ypro = repmat(yi,nz,1);
            zpro = repmat(depth,1,num_profile_points);

            Vpro = Fi(xpro,ypro,zpro);

            %% projected to 
            
            app.currentProfileDistAll = xdist;
            app.currentProfileDepthAll = depth; 
            app.currentProfileVAll = Vpro;

            app.currentStraightProfile_xi = xi;
            app.currentStraightProfile_yi = yi;  

            [lonp, latp] = ProjectedCoordsTolatlon(xi', yi', app.gridStruct); 
            elevp = interp2(app.dem.demLon, app.dem.demLat, double(app.dem.demZ), lonp, latp) / 1000.;
        
            cla(app.CCPTopography,"reset"); 
            plot(app.CCPTopography, xdist, elevp, 'k', 'linewidth', 2);
            patch(app.CCPTopography, [xdist(1); xdist'; xdist(end)], [0; elevp; 0], [0.8 0.8 0.8]);
            
            ylabel(app.CCPTopography, 'Elev. (km)');
            xlim(app.CCPTopography, [0, max(xdist)]);
            min_elev = (min(elevp)*0.9); 
            max_elev = (max(elevp)*1.1);
            ylim(app.CCPTopography, [min_elev, max_elev]);
            app.CCPTopography.XTickLabel = []; 
        
            %% 
            cla(app.CCPImageMap,"reset"); 
            imagesc(app.CCPImageMap, xdist, depth, Vpro); 
            axis(app.CCPImageMap, 'ij'); 
            xlabel(app.CCPImageMap, 'Distance (km)');
            ylabel(app.CCPImageMap, 'Depth (km)');
            xlim(app.CCPImageMap, [0, max(xdist)]);
            ylim(app.CCPImageMap, [0, 100]);
        
            text(app.CCPImageMap,0, max(depth)*0.05, 'A', 'FontSize', 14, 'VerticalAlignment', 'bottom');
            text(app.CCPImageMap,max(xdist), max(depth)*0.05, 'B', 'FontSize', 14, 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom');
        
            cmax = rms(Vpro(:)); 
            clim(app.CCPImageMap, [-cmax, cmax]); 
            colormap(app.CCPImageMap,flipud(app.cmap.roma));
            % colorbar(app.CCPImageMap);
        
            %% --- Axes ---
            linkaxes([app.CCPTopography, app.CCPImageMap], 'x');
        
            app.CCPTopography.ActivePositionProperty = 'position';
            app.CCPImageMap.ActivePositionProperty = 'position';
        
            pos_topo = get(app.CCPTopography, 'Position');
        
            pos_image = get(app.CCPImageMap, 'Position');
            pos_image(1) = pos_topo(1); 
            pos_image(3) = pos_topo(3);
            set(app.CCPImageMap, 'Position', pos_image);
            
            app.StatusEditField.Value = 'CCP plotting!';
            close(msg);
            uialert(app.UIFigure, 'CCP stacking done!', 'Success');

            %% projected
            dd = permute(V_smooth, [3, 2, 1]);  % 从 (y, x, z) -> (z, x, y)
            X1 = permute(X, [3, 2, 1]);
            Y1 = permute(Y, [3, 2, 1]);
            Z1 = permute(Z, [3, 2, 1]);

            A = [x1, y1];        B = [x2, y2];
            L = norm(B - A);     u = (B - A) / L; 

            X_vec = X1(:);  Y_vec = Y1(:);   Z_vec = Z1(:); V_vec = dd(:);
            s = (X_vec - A(1))*u(1) + (Y_vec - A(2))*u(2);
            zp = Z_vec;

            s_grid = 0:app.dxEditField.Value:L;           
            z_grid = 0:1:max(ceil(zp)); 

            ds = s_grid(2) - s_grid(1);
            s_edges = [s_grid - ds, s_grid(end) + ds];

            idx_s = discretize(s, s_edges);
            idx_z = max(1, min(length(z_grid), zp));

            valid = ~isnan(idx_s) & (idx_s >= 1) & (idx_s <= length(s_grid));
            idx_s = idx_s(valid);
            idx_z = idx_z(valid);
            V_vec_valid = V_vec(valid);

            V_sum = zeros(length(z_grid), length(s_grid));
            V_count = zeros(length(z_grid), length(s_grid));

            for i = 1:length(V_vec_valid)
                V_sum(idx_z(i), idx_s(i)) = V_sum(idx_z(i), idx_s(i)) + V_vec_valid(i);
                V_count(idx_z(i), idx_s(i)) = V_count(idx_z(i), idx_s(i)) + 1;
            end

            V_profile = V_sum ./ max(1, V_count);

            figure('Position', [200, 300, 1000, 500],'Color','w');
            ax1 = subplot(121);
            imagesc(s_grid, z_grid, V_profile);
            hold on;
            xlabel('Distance (km)');
            ylabel('Depth (km)');
            title('CCP (projected)');
            colormap(ax1, flipud(app.cmap.roma)); axis equal; box on;
            xlim([0 L]);ylim([0 100]);set(gca,'LineWidth',1.5,'FontSize',18)
            cmax = rms(V_profile(:));
            clim([-cmax, cmax]); 

            ax2=subplot(1,2,2);
            if smoothPara < 0
                R_all = zeros(length(depth),1);
                plot(R_all, depth, 'k-', 'LineWidth', 2);
                set(gca, 'YDir', 'reverse');
                grid on;
                xlabel('Smoothing Radius (km)');
                ylabel('Depth (km)');
                title('No Smoothing');
            else
                R_all = arrayfun(R_func, depth);
                plot(R_all, depth, 'k-', 'LineWidth', 2);
                set(gca, 'YDir', 'reverse');
                grid on;    box on;
                xlabel('Smoothing Radius (km)');
                ylabel('Depth (km)');
                axis ij;legend(['Param=',num2str(smoothPara)],'Location','southwest')
                set(gca, 'LineWidth', 1.5, 'FontSize', 18);
                title('Smoothing Radius vs Depth');
            end

            ax1.Position = [0.08, 0.12, 0.45, 0.80];
            ax2.Position = [0.58, 0.12, 0.30, 0.80];
        end

        % Callback function
        function SaveFigureButtonPushed(app, event)
            [filename, path, index] = uiputfile({...
                '*.png','PNG file'; ...
                '*.jpg','JPG file'}, 'Please input a file name');
            
            if isequal(filename, 0)
                disp('Cancel');
                return;
            end
                
            f = figure('Visible', 'off', 'Position', [100, 100, 1000, 500]);
            totalHeight = 1;
            topographyHeight = totalHeight / 3;
            ccpHeight = totalHeight * 2 / 3;
           
            ax1 = axes('Parent', f, 'Position', [0.1, ccpHeight + 0.05, 0.8, topographyHeight]);
            
            copyobj(app.CCPTopography.Children, ax1);
            
            title(ax1, 'Topography');
            ylabel(ax1, 'Elev. (km)');
            ax1.XTickLabel = [];
            grid(ax1, 'on');
            xlim(ax1, app.CCPTopography.XLim);
            ylim(ax1, app.CCPTopography.YLim);
            
            ax2 = axes('Parent', f, 'Position', [0.1, 0.05, 0.8, ccpHeight]);
            
            copyobj(app.CCPImageMap.Children, ax2);

            title(ax2, 'CCP Image');
            xlabel(ax2, 'Distance (km)');
            ylabel(ax2, 'Depth (km)');
            axis(ax2, 'ij');
            grid(ax2, 'on');
            colormap(ax2, flipud(app.cmap.roma));
            clim(ax2, app.CCPImageMap.CLim);
            xlim(ax2, app.CCPImageMap.XLim);
            ylim(ax2, app.CCPImageMap.YLim);
            
            cbar = colorbar(ax2, 'Location', 'eastoutside');
            cbar.Position(1) = 0.91; 
            
            formats = {'-dpng', '-djpeg'};
            format = formats{index};
            
            try
                print(f, fullfile(path, filename), format, '-r150');
                app.StatusEditField.Value = sprintf('Figures saved to: %s', filename);
                uialert(app.UIFigure, 'Image saved successfully.', 'Success');
            catch ME
                app.StatusEditField.Value = 'Save failed';
                uialert(app.UIFigure, sprintf('Save image failed:\n%s', ME.message), 'Error');
            end
            
            close(f);
        end

        % Button pushed function: CancelGUI
        function CancelGUIPushed(app, event)
            answer = questdlg('Do you want to exit?', '', 'YES', 'NO', 'NO');
            switch answer
                case 'YES'
                    delete(app.UIFigure); 
                case 'NO'
                    return; 
            end
        end

        % Button pushed function: RunButtonCCP
        function RunButtonCCPPushed(app, event)
            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','CCP Panel', ...
                    'Message','Computing CCP stacking...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');

            eventList = getEvents(app.DataStruct);
            eventid = {eventList.evid};

            dimg = []; 
            count = [];
            nMigratedEvents = 1; 

            for iEvent = 1:length(eventList)
            % for iEvent = 35
                evid = eventid{iEvent}; 
                gather = getCommonEventGather(app.DataStruct, evid);

                if length(gather) < 0.5 * length(app.gridStruct.stationLon)
                    continue
                end

                msg.Value = (iEvent-1)/length(eventid);
                msg.Message = sprintf('Processing：No. (%d/%d) earthquake', iEvent, length(eventid));
                drawnow; 

                Result = CCPCommonEventGather(gather, app.gridStruct);
                dimg(:,:,:,nMigratedEvents) = Result.img;
                count(:,:,:,nMigratedEvents) = Result.count;
                
                nMigratedEvents = nMigratedEvents + 1;
            end

            app.ccpResult.img = dimg;
            app.ccpResult.count = count;
            app.ccpResult.X = Result.X;
            app.ccpResult.Y = Result.Y;
            app.ccpResult.Z = Result.Z;

            msg.Value = 1;
            msg.Message = 'CCP stacking done!';
            drawnow;

            close(msg);
            uialert(app.UIFigure, 'CCP stacking done', 'Sucess');
            app.StatusEditField.Value = 'CCP done!';
        end

        % Button pushed function: OKButton, SaveFigureButton
        function OKButtonPushed(app, event)

            app.mainApp.ccpStruct = app.ccpResult;
            app.mainApp.CCPStackingStatus = 'OK';

            delete(app.UIFigure);
        end

        % Button pushed function: CustomProfileButton
        function CustomProfileButtonPushed(app, event)

            X = app.ccpResult.X;
            Y = app.ccpResult.Y;
            Z = app.ccpResult.Z;
        
            [~,~,nz] = size(X);
            dimg_smooth = [];
            smoothLength = fix(app.SmoothingParEditField.Value/app.dxEditField.Value) + 1;
        
            if rem(smoothLength,2)==0
                smoothLength = smoothLength +1;
            end

            if smoothLength>1
                for n = 1:size(app.ccpResult.img,4)
                    dimg_smooth(:,:,:,n) = smooth3(squeeze(app.ccpResult.img(:,:,:,n)),'box',smoothLength);
                end
                V_smooth = mean(dimg_smooth,4);
            else
                V_smooth = mean(app.ccpResult.img,4);
            end
            
            F = scatteredInterpolant(X(:),Y(:),Z(:),V_smooth(:));
            app.Fi_current = F;
            depth0 = 0:app.dzkmEditField.Value:max(Z(:));
        
            f_ginput = figure; 
            set(f_ginput,'Position',[50 50 800 800],'Color','w')
            h = slice(X,Y,Z,V_smooth,90,90,40);
            xlabel('X (km)');ylabel('Y (km)');zlabel('Z (km)');
            set(h(:),'EdgeColor','none');set(gca,'ZDir','reverse')
            colormap(flipud(app.cmap.roma));cmax = rms(V_smooth(:));clim([-cmax cmax]);box on
            view(0,90);
            axis equal;
            yrange = Y(:,1,1);
            xrange = squeeze(X(1,:,1));
            ylim([min(yrange)*0.8 max(yrange)*1.2]);
            xlim([min(xrange)*0.8 max(xrange)*1.2]);

            hold on
            scatter(app.gridStruct.rx(:, 1),app.gridStruct.ry(:, 2), 40, 'r^', 'filled')
            hold off
            title('Please click on the map to select profile points. Double-click or press Enter to finish.','FontSize',16);

            [xpoints,ypoints] = ginput;
            close(f_ginput); 
        
            if isempty(xpoints)
                uialert(app.UIFigure, 'None points selected.', 'Warning', 'Icon', 'warning');
                app.StatusEditField.Value = 'Exit Profile Selection';
                return;
            end  
            app.currentProfilePoints = [xpoints, ypoints];

            profileAll={};
            for n = 1:length(xpoints)-1
                profile=[xpoints(n),ypoints(n);xpoints(n+1),ypoints(n+1)];
                profileAll{n} = profile;
            end

            plotStationsProInApp(app,app.gridStruct);
        
            VAll=[];
            distAll = [];
            depthAll = [];
            totalDist = 0;
            elevAll = [];
            app.currentProfile_xp_all = []; 
            app.currentProfile_yp_all = []; 

            for n = 1:length(profileAll)
                x1 = profileAll{n}(1,1);
                y1 = profileAll{n}(1,2);
                x2 = profileAll{n}(2,1);
                y2 = profileAll{n}(2,2);
                l = (y2-y1)/(x2-x1);
                npoints = 100;
                xp = linspace(x1,x2,npoints)';
                yp = y1+(xp-x1)*l;
                dist0 = sqrt((xp-x1).^2 + (yp-y1).^2);

                Y_profile = repmat(yp',length(depth0),1);
                X_profile = repmat(xp',length(depth0),1);
                Z_profile = repmat(depth0',1,length(xp));
                DIST_profile = repmat(dist0',length(depth0),1);
                Vprofile = F(X_profile,Y_profile,Z_profile);
                Vprofile(isnan(Vprofile)) = NaN;
        
                if n > 1
                    totalDist = max(distAll(:));
                end
                distAll = [distAll, DIST_profile+totalDist];
                depthAll = [depthAll,Z_profile];
                VAll = [VAll, Vprofile];

                [lonp, latp] = ProjectedCoordsTolatlon(xp, yp, app.gridStruct);
                elevp = interp2(app.dem.demLon,app.dem.demLat,double(app.dem.demZ),lonp,latp)/1000.;
                elevAll = [elevAll; elevp];
                app.currentProfile_xp_all = [app.currentProfile_xp_all; xp];
                app.currentProfile_yp_all = [app.currentProfile_yp_all; yp];
            end
            distElev = distAll(1,:)';

            % ---  ---
            app.currentProfileDistAll = distAll;
            app.currentProfileDepthAll = depthAll;
            app.currentProfileVAll = VAll;   
            
            %% plot topohraphy
            cla(app.CCPTopography,"reset");
            plot(app.CCPTopography,distElev,elevAll,'k','linewidth',2);
            patch(app.CCPTopography,[distElev(1);distElev;distElev(end)],[0;elevAll;0],[0.8 0.8 0.8]);
            ylabel(app.CCPTopography,'Elev. (km)');

            xlim(app.CCPTopography, [0 max(distAll(1,:))]);
            
            min_elev = (min(elevp)*0.8); 
            max_elev = (max(elevp)*1.2);
            ylim(app.CCPTopography, [min_elev, max_elev]);

            box(app.CCPTopography,"on")
            app.CCPTopography.XTickLabel = [];
            set(app.CCPTopography,'fontsize',16,'linewidth',1.5,'Position',[15 225 660 80])
        
            cla(app.CCPImageMap,"reset");
            h=pcolor(app.CCPImageMap,distAll,depthAll,VAll);

            xlim(app.CCPImageMap, [0 max(distAll(1,:))]);
            ylim(app.CCPImageMap, [0 100]); 
            colormap(app.CCPImageMap,flipud(app.cmap.roma));
            cmax = rms(VAll(:));
            clim(app.CCPImageMap, [-cmax, cmax]);
            set(h,'EdgeColor','none');
            axis(app.CCPImageMap,'ij'); 

            hold(app.CCPImageMap,"on");
            xpts = [distElev(1);distElev((1:length(profileAll))*npoints)];
            scatter(app.CCPImageMap,xpts,zeros(1,1+length(profileAll)),'v','blue','filled')

            xlabel(app.CCPImageMap,'Distance (km)');
            ylabel(app.CCPImageMap,'Depth (km)');
            box(app.CCPImageMap, "on")
            % text(app.CCPImageMap,0,-5,'A','FontSize',14);
            % text(app.CCPImageMap,max(distAll(1,:))-5,-5,'B','FontSize',14);

            linkaxes([app.CCPTopography, app.CCPImageMap], 'x');

            app.CCPTopography.ActivePositionProperty = 'position';
            app.CCPImageMap.ActivePositionProperty = 'position';

            pos_topo = get(app.CCPTopography, 'Position');
            pos_image = get(app.CCPImageMap, 'Position');
            pos_image(1) = pos_topo(1)-15; 
            pos_image(3) = pos_topo(3)+15; 
            set(app.CCPImageMap, 'Position', pos_image,'fontsize',16,'linewidth',1.5,'XMinorTick','on','YMinorTick','on');

            app.StatusEditField.Value = 'Plotting CCP Image';
            uialert(app.UIFigure, 'completed successfully', 'Success');
        end

        % Button pushed function: PickMohoButton
        function PickMohoButtonPushed(app, event)
            if ~isempty(app.currentProfile_xp_all)
                app.currentStraightProfile_xi = [];
            end
            if isempty(app.ccpResult) || isempty(app.currentProfileDistAll)
                uialert(app.UIFigure, 'Please run the CCP calculation first', 'Error');
                return;
            end

            h_pick_fig = figure('Name', 'Manually pick the Moho', 'NumberTitle','off', ...
                'Position', [100 100 800 600], 'Color', 'w');
            ax = axes('Parent', h_pick_fig, 'Position', [0.1 0.1 0.8 0.8]);

            dist_vec = app.currentProfileDistAll(1,:);
            depth_vec = app.currentProfileDepthAll(:,1);
            imagesc(ax, dist_vec, depth_vec, app.currentProfileVAll);
            colormap(ax, flipud(app.cmap.roma));
            axis(ax, 'ij');
            title(ax, 'Click to select Moho points. Press Enter to finish.');
            xlabel(ax, 'Distance (km)');
            ylabel(ax, 'Depth (km)');
            clim(ax, [-0.01, 0.01]); 

            [x_dists, depths] = ginput();
            close(h_pick_fig);

            if isempty(x_dists)
                uialert(app.UIFigure, 'No points selected.', 'Note');
                return;
            end

            results = struct(...
                'ProfileDistance', num2cell(x_dists), ...
                'ClickedDepth', num2cell(depths), ...
                'OriginalX', [], ...
                'OriginalY', [], ...
                'MohoDepth', [], ...
                'Longitude', [], ...
                'Latitude', []);

            hold(app.CCPImageMap, 'on');

            h_click = plot(app.CCPImageMap, NaN, NaN, 'go', 'MarkerSize', 10, 'DisplayName', 'Select Depth');
            h_moho = plot(app.CCPImageMap, NaN, NaN, 'r*', 'MarkerSize', 15, 'DisplayName', 'Moho Depth');

            for i = 1:length(x_dists)

                current_dist = x_dists(i);
                clicked_depth = depths(i);

                [~, dist_idx] = min(abs(dist_vec - current_dist));

                search_min = max(1, find(depth_vec >= (clicked_depth - 5), 1));
                search_max = min(length(depth_vec), find(depth_vec <= (clicked_depth + 5), 1, 'last'));

                search_depths = depth_vec(search_min:search_max);
                search_values = app.currentProfileVAll(search_min:search_max, dist_idx);

                [~, max_idx] = max(search_values);
                moho_depth = search_depths(max_idx);

                if ~isempty(app.currentStraightProfile_xi)
                    [~, idx] = min(abs(dist_vec - current_dist));
                    orig_X = app.currentStraightProfile_xi(idx);
                    orig_Y = app.currentStraightProfile_yi(idx);
                elseif ~isempty(app.currentProfile_xp_all)
                    xp_all = app.currentProfile_xp_all;
                    yp_all = app.currentProfile_yp_all;

                    segments = sqrt(diff(xp_all).^2 + diff(yp_all).^2);
                    cum_dist = [0; cumsum(segments)];

                    [~, idx] = min(abs(cum_dist - current_dist));
                    orig_X = xp_all(idx);
                    orig_Y = yp_all(idx);
                else
                    uialert(app.UIFigure, 'Unable to determine coordinate mapping.', 'Error');
                    return;
                end

                [lon, lat] = ProjectedCoordsTolatlon(orig_X, orig_Y, app.gridStruct);

                results(i).OriginalX = orig_X;
                results(i).OriginalY = orig_Y;
                results(i).MohoDepth = moho_depth;
                results(i).Longitude = lon;
                results(i).Latitude = lat;

                plot(app.CCPImageMap, current_dist, clicked_depth, 'go', 'MarkerSize', 10);
                plot(app.CCPImageMap, current_dist, moho_depth, 'r*', 'MarkerSize', 15);

                text(app.CCPImageMap, current_dist, moho_depth+5, ...
                    sprintf('%.1f km', moho_depth), ...
                    'Color', 'r', 'FontSize', 10);
            end
            hold(app.CCPImageMap, 'off');
            legend(app.CCPImageMap, [h_click, h_moho], 'Location', 'best');

            choice = questdlg('Do you want to save?', '', 'Save', 'Cancel', 'Cancel');
            switch choice
                case 'Save'
                    [filename, pathname] = uiputfile('*.txt', 'Export Moho depth');
                    if filename == 0
                        uialert(app.UIFigure, sprintf('Save cancelled.'), 'Error');
                        return;
                    end

                    fullpath = fullfile(pathname, filename);
                    fid = fopen(fullpath, 'w');

                    fprintf(fid, '%-15s %-15s %-15s %-15s %-15s %-15s %-15s\n', ...
                        'ProfileDist(km)', 'ClickedDepth(km)', 'OriginalX(km)', 'OriginalY(km)', ...
                        'MohoDepth(km)', 'Longitude(deg)', 'Latitude(deg)');

                    for i = 1:length(results)
                        fprintf(fid, '%-15.3f %-15.3f %-15.3f %-15.3f %-15.3f %-15.6f %-15.6f\n', ...
                            results(i).ProfileDistance, ...
                            results(i).ClickedDepth, ...
                            results(i).OriginalX, ...
                            results(i).OriginalY, ...
                            results(i).MohoDepth, ...
                            results(i).Longitude, ...
                            results(i).Latitude);
                    end
                    fclose(fid);
                    uialert(app.UIFigure, sprintf('%d Moho picks exported to:\n%s', length(results), fullpath), 'Success');

                case 'Cancel'           
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CCPStackingPanelLabel = uilabel(app.UIFigure);
            app.CCPStackingPanelLabel.Text = 'CCP Stacking Panel';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.CreategridPanel = uipanel(app.UIFigure);
            app.CreategridPanel.Title = 'Create grid';
            app.dxkmLabel = uilabel(app.CreategridPanel);
            app.dxkmLabel.Text = 'dx (km):';
            app.dxEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dxEditField.Value = 10;
            app.dykmLabel = uilabel(app.CreategridPanel);
            app.dykmLabel.Text = 'dy (km):';
            app.dykmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dykmEditField.Value = 10;
            app.zmaxkmEditFieldLabel = uilabel(app.CreategridPanel);
            app.zmaxkmEditFieldLabel.Text = 'zmax (km):';
            app.zmaxkmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.zmaxkmEditField.Value = 100;
            app.dzkmLabel = uilabel(app.CreategridPanel);
            app.dzkmLabel.Text = 'dz (km):';
            app.dzkmEditField = uieditfield(app.CreategridPanel, 'numeric');
            app.dzkmEditField.Value = 1;
            app.CreateButton = uibutton(app.CreategridPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.VelocityModelDropDownLabel = uilabel(app.CreategridPanel);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model';
            app.VelocityModelDropDown = uidropdown(app.CreategridPanel);
            app.VelocityModelDropDown.Items = {'1D', '2D', '3D', 'Default (3D)'};
            app.VelocityModelDropDown.Value = 'Default (3D)';
            app.PlotparametersPanel = uipanel(app.UIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters:';
            app.X1Label = uilabel(app.PlotparametersPanel);
            app.X1Label.Text = 'X1';
            app.X1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y1Label = uilabel(app.PlotparametersPanel);
            app.Y1Label.Text = 'Y1';
            app.Y1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2Label = uilabel(app.PlotparametersPanel);
            app.X2Label.Text = 'X2';
            app.X2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditField.Value = 200;
            app.Y2Label = uilabel(app.PlotparametersPanel);
            app.Y2Label.Text = 'Y2';
            app.Y2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y2EditField.Value = 130;
            app.PlotButton = uibutton(app.PlotparametersPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveFigureButton = uibutton(app.PlotparametersPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.CustomProfileButton = uibutton(app.PlotparametersPanel, 'push');
            app.CustomProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CustomProfileButtonPushed, true);
            app.CustomProfileButton.Text = 'Custom Profile';
            app.PickMohoButton = uibutton(app.PlotparametersPanel, 'push');
            app.PickMohoButton.ButtonPushedFcn = createCallbackFcn(app, @PickMohoButtonPushed, true);
            app.PickMohoButton.Text = 'Pick Moho';
            app.SmoothingkmLabel = uilabel(app.PlotparametersPanel);
            app.SmoothingkmLabel.Text = 'Smoothing Par:';
            app.SmoothingParEditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.SmoothingParEditField.Value = 1;
            app.GridPanel = uipanel(app.UIFigure);
            app.GridPanel.Title = 'Grid Panel';
            app.CCPcalculatePanel = uipanel(app.UIFigure);
            app.CCPcalculatePanel.Title = 'CCP calculate';
            app.RunButtonCCP = uibutton(app.CCPcalculatePanel, 'push');
            app.RunButtonCCP.ButtonPushedFcn = createCallbackFcn(app, @RunButtonCCPPushed, true);
            app.RunButtonCCP.Text = 'Run';
            app.CancelGUI = uibutton(app.UIFigure, 'push');
            app.CancelGUI.ButtonPushedFcn = createCallbackFcn(app, @CancelGUIPushed, true);
            app.CancelGUI.Text = 'Cancel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.GridLayout = uigridlayout(app.Panel);
            app.CCPImageMap = uiaxes(app.GridLayout);
            title(app.CCPImageMap, 'CCP image')
            xlabel(app.CCPImageMap, 'Distance (km)')
            ylabel(app.CCPImageMap, 'Depth (km)')
            zlabel(app.CCPImageMap, 'Z')
            app.CCPTopography = uiaxes(app.GridLayout);
            title(app.CCPTopography, 'Topography')
            ylabel(app.CCPTopography, 'Y')
            zlabel(app.CCPTopography, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCPStackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end