classdef ConfigEditorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PreprocessingParamTab           matlab.ui.container.Tab
        resample_periodEditField        matlab.ui.control.NumericEditField
        resample_periodEditFieldLabel   matlab.ui.control.Label
        highsEditField                  matlab.ui.control.NumericEditField
        highsEditFieldLabel             matlab.ui.control.Label
        lowsEditField                   matlab.ui.control.NumericEditField
        lowsEditFieldLabel              matlab.ui.control.Label
        record_lenEditField             matlab.ui.control.NumericEditField
        record_lenEditFieldLabel        matlab.ui.control.Label
        sig_leaderEditField             matlab.ui.control.NumericEditField
        sig_leaderEditFieldLabel        matlab.ui.control.Label
        tendEditField                   matlab.ui.control.NumericEditField
        tendEditFieldLabel              matlab.ui.control.Label
        tstartEditField                 matlab.ui.control.NumericEditField
        tstartEditFieldLabel            matlab.ui.control.Label
        ResampleperiodsecLabel          matlab.ui.control.Label
        BandpassfilterhighcornerfrequencyHzLabel  matlab.ui.control.Label
        BandpassfilterlowcornerfrequencyHzLabel  matlab.ui.control.Label
        RecordlengthafterPwavearrivalsecLabel  matlab.ui.control.Label
        TimebeforePwavearrivalsecLabel  matlab.ui.control.Label
        EndtimesecrelativetoeventLabel  matlab.ui.control.Label
        StarttimesecrelativetoeventLabel  matlab.ui.control.Label
        DeconvParamTab                  matlab.ui.container.Tab
        radonfilterCheckBox             matlab.ui.control.CheckBox
        verboseCheckBox                 matlab.ui.control.CheckBox
        phaseshiftEditField             matlab.ui.control.NumericEditField
        phaseshiftEditFieldLabel        matlab.ui.control.Label
        minderrEditField                matlab.ui.control.NumericEditField
        minderrEditFieldLabel           matlab.ui.control.Label
        itmaxEditField                  matlab.ui.control.NumericEditField
        itmaxEditFieldLabel             matlab.ui.control.Label
        waterlevelEditField             matlab.ui.control.NumericEditField
        waterlevelEditFieldLabel        matlab.ui.control.Label
        gaussEditField                  matlab.ui.control.NumericEditField
        gaussEditFieldLabel             matlab.ui.control.Label
        UseRadonfiltertruefalseLabel    matlab.ui.control.Label
        VerboseoutputtruefalseLabel     matlab.ui.control.Label
        PhaseshiftfordeconvolutionLabel  matlab.ui.control.Label
        MinimumerrorfordeconvolutionLabel  matlab.ui.control.Label
        MaximumnumberofiterationsfordeconvolutionLabel  matlab.ui.control.Label
        WaterlevelparameterfordeconvolutionLabel  matlab.ui.control.Label
        GaussianparameterfordeconvolutionLabel  matlab.ui.control.Label
        RadonParamTab                   matlab.ui.container.Tab
        PlotRadonresultstruefalseLabel  matlab.ui.control.Label
        plotRadonCheckBox               matlab.ui.control.CheckBox
        N2EditField                     matlab.ui.control.NumericEditField
        N2EditFieldLabel                matlab.ui.control.Label
        N1EditField                     matlab.ui.control.NumericEditField
        N1EditField_2Label              matlab.ui.control.Label
        minTracesEditField              matlab.ui.control.NumericEditField
        minTracesEditFieldLabel         matlab.ui.control.Label
        pminEditField                   matlab.ui.control.NumericEditField
        pminEditFieldLabel              matlab.ui.control.Label
        pmaxEditField                   matlab.ui.control.NumericEditField
        pmaxEditFieldLabel              matlab.ui.control.Label
        NumberofouterloopiterationsLabel  matlab.ui.control.Label
        NumberofCGiterationsLabel       matlab.ui.control.Label
        MinimumnumberoftracespereventLabel  matlab.ui.control.Label
        MinimumslownessskmLabel         matlab.ui.control.Label
        MaximumslownessskmLabel         matlab.ui.control.Label
        RankReductionParamTab           matlab.ui.container.Tab
        tmaxEditField                   matlab.ui.control.NumericEditField
        tmaxEditFieldLabel              matlab.ui.control.Label
        MaximumtimeforreconstructionLabel  matlab.ui.control.Label
        fhighEditField                  matlab.ui.control.NumericEditField
        fhighEditFieldLabel             matlab.ui.control.Label
        flowEditField                   matlab.ui.control.NumericEditField
        flowEditFieldLabel              matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        KEditFieldLabel                 matlab.ui.control.Label
        epsEditField                    matlab.ui.control.NumericEditField
        epsEditFieldLabel               matlab.ui.control.Label
        modeEditField                   matlab.ui.control.NumericEditField
        modeEditFieldLabel              matlab.ui.control.Label
        niterEditField                  matlab.ui.control.NumericEditField
        niterEditFieldLabel             matlab.ui.control.Label
        rankEditField                   matlab.ui.control.NumericEditField
        rankEditFieldLabel              matlab.ui.control.Label
        nyEditField                     matlab.ui.control.NumericEditField
        nyEditFieldLabel                matlab.ui.control.Label
        nxEditField                     matlab.ui.control.NumericEditField
        nxEditFieldLabel                matlab.ui.control.Label
        latminEditFieldLabel            matlab.ui.control.Label
        lonminEditFieldLabel            matlab.ui.control.Label
        verboseCheckBox_2               matlab.ui.control.CheckBox
        latminEditField                 matlab.ui.control.NumericEditField
        lonminEditField                 matlab.ui.control.NumericEditField
        VerboseoutputtruefalseLabel_2   matlab.ui.control.Label
        UpperfrequencyboundLabel        matlab.ui.control.Label
        LowerfrequencyboundLabel        matlab.ui.control.Label
        DampingfactorLabel              matlab.ui.control.Label
        EpsilonforconvergenceLabel      matlab.ui.control.Label
        NumberofiterationsLabel         matlab.ui.control.Label
        RankforrankreductionionLabel    matlab.ui.control.Label
        NumberofgridpointsinydirectionLabel  matlab.ui.control.Label
        ModeforrankreductionLabel       matlab.ui.control.Label
        NumberofgridpointsinxdirectionLabel  matlab.ui.control.Label
        MinimumlatitudeforgridLabel     matlab.ui.control.Label
        MinimumlongitudeforgridLabel    matlab.ui.control.Label
        MigrationTab                    matlab.ui.container.Tab
        is_ssaCheckBox                  matlab.ui.control.CheckBox
        UseSSAmethod1ornot0Label        matlab.ui.control.Label
        GaussianparameterformigrationLabel  matlab.ui.control.Label
        NumberofiterationsforleastsquaresmigrationLabel  matlab.ui.control.Label
        WeightparameterforSSALabel      matlab.ui.control.Label
        PhaseshiftparameterformigrationLabel  matlab.ui.control.Label
        RankparameterforSSALabel        matlab.ui.control.Label
        MaximumfrequencyformigrationHzLabel  matlab.ui.control.Label
        MinimumfrequencyformigrationHzLabel  matlab.ui.control.Label
        phaseshiftEditField_2           matlab.ui.control.NumericEditField
        phaseshiftEditField_2Label      matlab.ui.control.Label
        gaussEditField_2                matlab.ui.control.NumericEditField
        gaussEditField_2Label           matlab.ui.control.Label
        n_interEditField                matlab.ui.control.NumericEditField
        n_interEditFieldLabel           matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        rank_pEditField                 matlab.ui.control.NumericEditField
        rank_pEditFieldLabel            matlab.ui.control.Label
        fhighEditField_2                matlab.ui.control.NumericEditField
        fhighEditField_2Label           matlab.ui.control.Label
        flowEditField_2                 matlab.ui.control.NumericEditField
        flowEditField_2Label            matlab.ui.control.Label
        OthersTab                       matlab.ui.container.Tab
        SelectfolderButton              matlab.ui.control.Button
        outputFolderEditField           matlab.ui.control.EditField
        outputFolderEditFieldLabel      matlab.ui.control.Label
        dataFolderEditField             matlab.ui.control.EditField
        dataFolderEditFieldLabel        matlab.ui.control.Label
        DatafolderpathLabel             matlab.ui.control.Label
        profilelengthdegLabel           matlab.ui.control.Label
        MaxazimuthdifferencedegLabel    matlab.ui.control.Label
        WhethertovisualizeresultsLabel  matlab.ui.control.Label
        WhethertosaveresultsLabel       matlab.ui.control.Label
        OutputfolderpathLabel           matlab.ui.control.Label
        visualizeResultsCheckBox        matlab.ui.control.CheckBox
        saveResultsCheckBox             matlab.ui.control.CheckBox
        profile_lengthEditField         matlab.ui.control.NumericEditField
        profile_lengthEditFieldLabel    matlab.ui.control.Label
        max_angle_diffEditField         matlab.ui.control.NumericEditField
        max_angle_diffEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        mainApp
        UpdatedConfig % 用于主App读取的输出
    end

    methods (Access = public)
        function loadConfigData(app, inputConfig)
            % 1) 把输入的 config 暂存到 app 的属性
            app.UpdatedConfig = inputConfig;

            % 2) 将 inputConfig 各字段值写到对应的控件：
            app.tstartEditField.Value    = inputConfig.PreprocessingParam.tstart;
            app.tendEditField.Value      = inputConfig.PreprocessingParam.tend;
            app.resample_periodEditField.Value = inputConfig.PreprocessingParam.resample_period;
            app.highsEditField.Value      = inputConfig.PreprocessingParam.highs;
            app.lowsEditField.Value      = inputConfig.PreprocessingParam.lows;
            app.record_lenEditField.Value      = inputConfig.PreprocessingParam.record_len;
            app.sig_leaderEditField.Value      = inputConfig.PreprocessingParam.sig_leader;

            app.gaussEditField.Value    = inputConfig.DeconvParam.gauss;
            app.waterlevelEditField.Value    = inputConfig.DeconvParam.waterlevel;
            app.itmaxEditField.Value    = inputConfig.DeconvParam.itmax;
            app.minderrEditField.Value    = inputConfig.DeconvParam.minderr;
            app.phaseshiftEditField.Value    = inputConfig.DeconvParam.phaseshift;
            app.verboseCheckBox.Value    = inputConfig.DeconvParam.verbose;
            app.radonfilterCheckBox.Value    = inputConfig.DeconvParam.radonfilter;

            app.plotRadonCheckBox.Value = inputConfig.RadonParam.plotRadon;
            app.N1EditField.Value = inputConfig.RadonParam.N1;
            app.N2EditField.Value = inputConfig.RadonParam.N2;
            app.minTracesEditField.Value = inputConfig.RadonParam.minTraces;
            app.pminEditField.Value = inputConfig.RadonParam.pmin;
            app.pmaxEditField.Value = inputConfig.RadonParam.pmax;

            app.fhighEditField.Value = inputConfig.RankReductionParam.fhigh;
            app.flowEditField.Value = inputConfig.RankReductionParam.flow;
            app.lonminEditField.Value = inputConfig.RankReductionParam.lonmin;
            app.latminEditField.Value = inputConfig.RankReductionParam.latmin;
            app.nxEditField.Value = inputConfig.RankReductionParam.nx;
            app.nyEditField.Value = inputConfig.RankReductionParam.ny;
            app.rankEditField.Value = inputConfig.RankReductionParam.rank;
            app.niterEditField.Value = inputConfig.RankReductionParam.niter;
            app.modeEditField.Value = inputConfig.RankReductionParam.mode;
            app.verboseCheckBox_2.Value = inputConfig.RankReductionParam.verb;
            app.epsEditField.Value = inputConfig.RankReductionParam.eps;
            app.KEditField.Value = inputConfig.RankReductionParam.K;
            app.tmaxEditField.Value = inputConfig.RankReductionParam.tmax;

            app.is_ssaCheckBox.Value = inputConfig.MigParam.is_ssa;
            app.flowEditField_2.Value = inputConfig.MigParam.flow;
            app.fhighEditField_2.Value = inputConfig.MigParam.fhigh;
            app.rank_pEditField.Value = inputConfig.MigParam.rank_p;
            app.alphaEditField.Value = inputConfig.MigParam.alpha;
            app.n_interEditField.Value = inputConfig.MigParam.n_iter;
            app.gaussEditField_2.Value = inputConfig.MigParam.gauss;
            app.phaseshiftEditField_2.Value = inputConfig.MigParam.phaseshift;

            app.dataFolderEditField.Value = inputConfig.dataFolder;
            app.outputFolderEditField.Value = inputConfig.outputFolder;
            app.profile_lengthEditField.Value= inputConfig.profile_length;
            app.max_angle_diffEditField.Value= inputConfig.max_angle_diff;
            app.saveResultsCheckBox.Value = inputConfig.saveResults;
            app.visualizeResultsCheckBox.Value = inputConfig.visualizeResults;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowStyle = 'modal';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % 从界面各个控件上读取新值
            % 例如:
            app.UpdatedConfig.PreprocessingParam.tstart = app.tstartEditField.Value;
            app.UpdatedConfig.PreprocessingParam.tend   = app.tendEditField.Value;
            app.UpdatedConfig.PreprocessingParam.sig_leader = app.sig_leaderEditField.Value;
            app.UpdatedConfig.PreprocessingParam.record_len = app.record_lenEditField.Value;
            app.UpdatedConfig.PreprocessingParam.lows = app.lowsEditField.Value;
            app.UpdatedConfig.PreprocessingParam.highs = app.highsEditField.Value;
            app.UpdatedConfig.PreprocessingParam.resample_period = app.resample_periodEditField.Value;

            app.UpdatedConfig.DeconvParam.gauss = app.gaussEditField.Value;
            app.UpdatedConfig.DeconvParam.waterlevel = app.waterlevelEditField.Value;
            app.UpdatedConfig.DeconvParam.itmax = app.itmaxEditField.Value;
            app.UpdatedConfig.DeconvParam.minder = app.minderrEditField.Value;
            app.UpdatedConfig.DeconvParam.phaseshift = app.phaseshiftEditField.Value;
            app.UpdatedConfig.DeconvParam.verbose = app.verboseCheckBox.Value;
            app.UpdatedConfig.DeconvParam.radonfilter = app.radonfilterCheckBox.Value;

%             app.UpdatedConfig.RadonParam.lows = app.lowsEditField.Value;
%             app.UpdatedConfig.RadonParam.highs = app.highsEditField.Value;
            app.UpdatedConfig.pmax = app.pmaxEditField.Value;
            app.UpdatedConfig.RadonParam.pmin = app.pminEditField.Value;
            app.UpdatedConfig.RadonParam.minTraces = app.minTracesEditField.Value;
            app.UpdatedConfig.RadonParam.N1 = app.N1EditField.Value;
            app.UpdatedConfig.RadonParam.N2 = app.N2EditField.Value;
            app.UpdatedConfig.RadonParam.plotRadon = app.plotRadonCheckBox.Value;

            app.UpdatedConfig.RankReductionParam.lonmin = app.lonminEditField.Value;
            app.UpdatedConfig.RankReductionParam.latmin = app.latminEditField.Value;
            app.UpdatedConfig.RankReductionParam.nx = app.nxEditField.Value;
            app.UpdatedConfig.RankReductionParam.ny = app.nyEditField.Value;
            app.UpdatedConfig.RankReductionParam.rank = app.rankEditField.Value;
            app.UpdatedConfig.RankReductionParam.niter = app.niterEditField.Value;
            app.UpdatedConfig.RankReductionParam.mode = app.modeEditField.Value;
            app.UpdatedConfig.RankReductionParam.verb = app.verboseCheckBox_2.Value;
            app.UpdatedConfig.RankReductionParam.eps = app.epsEditField.Value;
            app.UpdatedConfig.RankReductionParam.K = app.KEditField.Value;
            app.UpdatedConfig.RankReductionParam.tmax = app.tmaxEditField.Value;
            app.UpdatedConfig.RankReductionParam.flow = app.flowEditField_2.Value;
            app.UpdatedConfig.RankReductionParam.fhigh = app.fhighEditField_2.Value;

            app.UpdatedConfig.MigParam.is_ssa = app.is_ssaCheckBox.Value;
            app.UpdatedConfig.MigParam.flow = app.flowEditField_2.Value;
            app.UpdatedConfig.MigParam.fhigh = app.fhighEditField_2.Value;
            app.UpdatedConfig.MigParam.rank_p = app.rank_pEditField.Value;
            app.UpdatedConfig.MigParam.alpha = app.alphaEditField.Value;
            app.UpdatedConfig.MigParam.n_iter = app.n_interEditField.Value;
            app.UpdatedConfig.MigParam.gauss = app.gaussEditField_2.Value;
            app.UpdatedConfig.MigParam.phaseshift = app.phaseshiftEditField_2.Value;

            app.UpdatedConfig.max_angle_diff = app.max_angle_diffEditField.Value;
            app.UpdatedConfig.profile_length = app.profile_lengthEditField.Value;

            app.UpdatedConfig.dataFolder = app.dataFolderEditField.Value;
            app.UpdatedConfig.outputFolder = app.outputFolderEditField.Value;
            app.UpdatedConfig.saveResults = app.saveResultsCheckBox.Value;
            app.UpdatedConfig.visualizeResults = app.visualizeResultsCheckBox.Value;
            
            % 将其赋值给主 App
            app.mainApp.config = app.UpdatedConfig;
            app.mainApp.configEditStatus = 'OK';
            % 关闭窗口
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.configEditStatus = 'Cancel';
            delete(app.UIFigure);
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
             % 弹出文件夹选择对话框
            selectedFolder = uigetdir();
            
            % 如果用户选择了一个文件夹（没有点击取消）
            if selectedFolder ~= 0
                % 将选定的文件夹路径设置到文本框中
                app.dataFolderEditField.Value = selectedFolder;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreprocessingParamTab = uitab(app.TabGroup);
            app.PreprocessingParamTab.Title = 'PreprocessingParam';
            app.StarttimesecrelativetoeventLabel = uilabel(app.PreprocessingParamTab);
            app.StarttimesecrelativetoeventLabel.Text = 'Start time (sec) relative to event';
            app.EndtimesecrelativetoeventLabel = uilabel(app.PreprocessingParamTab);
            app.EndtimesecrelativetoeventLabel.Text = 'End time (sec) relative to event';
            app.TimebeforePwavearrivalsecLabel = uilabel(app.PreprocessingParamTab);
            app.TimebeforePwavearrivalsecLabel.Text = 'Time before P-wave arrival (sec)';
            app.RecordlengthafterPwavearrivalsecLabel = uilabel(app.PreprocessingParamTab);
            app.RecordlengthafterPwavearrivalsecLabel.Text = 'Record length after P-wave arrival (sec)';
            app.BandpassfilterlowcornerfrequencyHzLabel = uilabel(app.PreprocessingParamTab);
            app.BandpassfilterlowcornerfrequencyHzLabel.Text = 'Bandpass filter low corner frequency (Hz)';
            app.BandpassfilterhighcornerfrequencyHzLabel = uilabel(app.PreprocessingParamTab);
            app.BandpassfilterhighcornerfrequencyHzLabel.Text = 'Bandpass filter high corner frequency (Hz)';
            app.ResampleperiodsecLabel = uilabel(app.PreprocessingParamTab);
            app.ResampleperiodsecLabel.Text = 'Resample period (sec)';
            app.tstartEditFieldLabel = uilabel(app.PreprocessingParamTab);
            app.tstartEditFieldLabel.Text = 'tstart';
            app.tstartEditField = uieditfield(app.PreprocessingParamTab, 'numeric');
            app.tstartEditField.Value = -60;
            app.tendEditFieldLabel = uilabel(app.PreprocessingParamTab);
            app.tendEditFieldLabel.Text = 'tend';
            app.tendEditField = uieditfield(app.PreprocessingParamTab, 'numeric');
            app.tendEditField.Value = 600;
            app.sig_leaderEditFieldLabel = uilabel(app.PreprocessingParamTab);
            app.sig_leaderEditFieldLabel.Text = 'sig_leader';
            app.sig_leaderEditField = uieditfield(app.PreprocessingParamTab, 'numeric');
            app.sig_leaderEditField.Value = 30;
            app.record_lenEditFieldLabel = uilabel(app.PreprocessingParamTab);
            app.record_lenEditFieldLabel.Text = 'record_len';
            app.record_lenEditField = uieditfield(app.PreprocessingParamTab, 'numeric');
            app.record_lenEditField.Value = 120;
            app.lowsEditFieldLabel = uilabel(app.PreprocessingParamTab);
            app.lowsEditFieldLabel.Text = 'lows';
            app.lowsEditField = uieditfield(app.PreprocessingParamTab, 'numeric');
            app.lowsEditField.Value = 0.1;
            app.highsEditFieldLabel = uilabel(app.PreprocessingParamTab);
            app.highsEditFieldLabel.Text = 'highs';
            app.highsEditField = uieditfield(app.PreprocessingParamTab, 'numeric');
            app.highsEditField.Value = 2;
            app.resample_periodEditFieldLabel = uilabel(app.PreprocessingParamTab);
            app.resample_periodEditFieldLabel.Text = 'resample_period';
            app.resample_periodEditField = uieditfield(app.PreprocessingParamTab, 'numeric');
            app.resample_periodEditField.Value = 0.1;
            app.DeconvParamTab = uitab(app.TabGroup);
            app.DeconvParamTab.Title = 'DeconvParam';
            app.GaussianparameterfordeconvolutionLabel = uilabel(app.DeconvParamTab);
            app.GaussianparameterfordeconvolutionLabel.Text = 'Gaussian parameter for deconvolution';
            app.WaterlevelparameterfordeconvolutionLabel = uilabel(app.DeconvParamTab);
            app.WaterlevelparameterfordeconvolutionLabel.Text = 'Water-level parameter for deconvolution';
            app.MaximumnumberofiterationsfordeconvolutionLabel = uilabel(app.DeconvParamTab);
            app.MaximumnumberofiterationsfordeconvolutionLabel.Text = 'Maximum number of iterations for deconvolution';
            app.MinimumerrorfordeconvolutionLabel = uilabel(app.DeconvParamTab);
            app.MinimumerrorfordeconvolutionLabel.Text = 'Minimum error for deconvolution';
            app.PhaseshiftfordeconvolutionLabel = uilabel(app.DeconvParamTab);
            app.PhaseshiftfordeconvolutionLabel.Text = 'Phase shift for deconvolution';
            app.VerboseoutputtruefalseLabel = uilabel(app.DeconvParamTab);
            app.VerboseoutputtruefalseLabel.Text = 'Verbose output (true/false)';
            app.UseRadonfiltertruefalseLabel = uilabel(app.DeconvParamTab);
            app.UseRadonfiltertruefalseLabel.Text = 'Use Radon filter (true/false)';
            app.gaussEditFieldLabel = uilabel(app.DeconvParamTab);
            app.gaussEditFieldLabel.Text = 'gauss';
            app.gaussEditField = uieditfield(app.DeconvParamTab, 'numeric');
            app.gaussEditField.Value = 5;
            app.waterlevelEditFieldLabel = uilabel(app.DeconvParamTab);
            app.waterlevelEditFieldLabel.Text = 'waterlevel';
            app.waterlevelEditField = uieditfield(app.DeconvParamTab, 'numeric');
            app.waterlevelEditField.Value = 0.01;
            app.itmaxEditFieldLabel = uilabel(app.DeconvParamTab);
            app.itmaxEditFieldLabel.Text = 'itmax';
            app.itmaxEditField = uieditfield(app.DeconvParamTab, 'numeric');
            app.itmaxEditField.Value = 100;
            app.minderrEditFieldLabel = uilabel(app.DeconvParamTab);
            app.minderrEditFieldLabel.Text = 'minderr';
            app.minderrEditField = uieditfield(app.DeconvParamTab, 'numeric');
            app.minderrEditField.Value = 1e-05;
            app.phaseshiftEditFieldLabel = uilabel(app.DeconvParamTab);
            app.phaseshiftEditFieldLabel.Text = 'phaseshift';
            app.phaseshiftEditField = uieditfield(app.DeconvParamTab, 'numeric');
            app.phaseshiftEditField.Value = 5;
            app.verboseCheckBox = uicheckbox(app.DeconvParamTab);
            app.verboseCheckBox.Text = 'verbose';
            app.radonfilterCheckBox = uicheckbox(app.DeconvParamTab);
            app.radonfilterCheckBox.Text = 'radonfilter';
            app.RadonParamTab = uitab(app.TabGroup);
            app.RadonParamTab.Title = 'RadonParam';
            app.MaximumslownessskmLabel = uilabel(app.RadonParamTab);
            app.MaximumslownessskmLabel.Text = 'Maximum slowness (s/km)';
            app.MinimumslownessskmLabel = uilabel(app.RadonParamTab);
            app.MinimumslownessskmLabel.Text = 'Minimum slowness (s/km)';
            app.MinimumnumberoftracespereventLabel = uilabel(app.RadonParamTab);
            app.MinimumnumberoftracespereventLabel.Text = 'Minimum number of traces per event';
            app.NumberofCGiterationsLabel = uilabel(app.RadonParamTab);
            app.NumberofCGiterationsLabel.Text = 'Number of CG iterations';
            app.NumberofouterloopiterationsLabel = uilabel(app.RadonParamTab);
            app.NumberofouterloopiterationsLabel.Text = 'Number of outer loop iterations';
            app.pmaxEditFieldLabel = uilabel(app.RadonParamTab);
            app.pmaxEditFieldLabel.Text = 'pmax';
            app.pmaxEditField = uieditfield(app.RadonParamTab, 'numeric');
            app.pmaxEditField.Value = 0.05;
            app.pminEditFieldLabel = uilabel(app.RadonParamTab);
            app.pminEditFieldLabel.Text = 'pmin';
            app.pminEditField = uieditfield(app.RadonParamTab, 'numeric');
            app.pminEditField.Value = -0.05;
            app.minTracesEditFieldLabel = uilabel(app.RadonParamTab);
            app.minTracesEditFieldLabel.Text = 'minTraces';
            app.minTracesEditField = uieditfield(app.RadonParamTab, 'numeric');
            app.minTracesEditField.Value = 60;
            app.N1EditField_2Label = uilabel(app.RadonParamTab);
            app.N1EditField_2Label.Text = 'N1';
            app.N1EditField = uieditfield(app.RadonParamTab, 'numeric');
            app.N1EditField.Value = 30;
            app.N2EditFieldLabel = uilabel(app.RadonParamTab);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.RadonParamTab, 'numeric');
            app.N2EditField.Value = 1;
            app.plotRadonCheckBox = uicheckbox(app.RadonParamTab);
            app.plotRadonCheckBox.Text = 'plotRadon';
            app.PlotRadonresultstruefalseLabel = uilabel(app.RadonParamTab);
            app.PlotRadonresultstruefalseLabel.Text = 'Plot Radon results (true/false)';
            app.RankReductionParamTab = uitab(app.TabGroup);
            app.RankReductionParamTab.Title = 'RankReductionParam';
            app.MinimumlongitudeforgridLabel = uilabel(app.RankReductionParamTab);
            app.MinimumlongitudeforgridLabel.Text = 'Minimum longitude for grid';
            app.MinimumlatitudeforgridLabel = uilabel(app.RankReductionParamTab);
            app.MinimumlatitudeforgridLabel.Text = 'Minimum latitude for grid';
            app.NumberofgridpointsinxdirectionLabel = uilabel(app.RankReductionParamTab);
            app.NumberofgridpointsinxdirectionLabel.Text = 'Number of grid points in x-direction';
            app.ModeforrankreductionLabel = uilabel(app.RankReductionParamTab);
            app.ModeforrankreductionLabel.Text = 'Mode for rank reduction';
            app.NumberofgridpointsinydirectionLabel = uilabel(app.RankReductionParamTab);
            app.NumberofgridpointsinydirectionLabel.Text = 'Number of grid points in y-direction';
            app.RankforrankreductionionLabel = uilabel(app.RankReductionParamTab);
            app.RankforrankreductionionLabel.Text = 'Rank for rank reductionion';
            app.NumberofiterationsLabel = uilabel(app.RankReductionParamTab);
            app.NumberofiterationsLabel.Text = 'Number of iterations';
            app.EpsilonforconvergenceLabel = uilabel(app.RankReductionParamTab);
            app.EpsilonforconvergenceLabel.Text = 'Epsilon for convergence';
            app.DampingfactorLabel = uilabel(app.RankReductionParamTab);
            app.DampingfactorLabel.Text = 'Damping factor';
            app.LowerfrequencyboundLabel = uilabel(app.RankReductionParamTab);
            app.LowerfrequencyboundLabel.Text = 'Lower frequency bound';
            app.UpperfrequencyboundLabel = uilabel(app.RankReductionParamTab);
            app.UpperfrequencyboundLabel.Text = 'Upper frequency bound';
            app.VerboseoutputtruefalseLabel_2 = uilabel(app.RankReductionParamTab);
            app.VerboseoutputtruefalseLabel_2.Text = 'Verbose output (true/false)';
            app.lonminEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.lonminEditField.Value = 109;
            app.latminEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.latminEditField.Value = 41.1667;
            app.verboseCheckBox_2 = uicheckbox(app.RankReductionParamTab);
            app.verboseCheckBox_2.Text = 'verbose';
            app.lonminEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.lonminEditFieldLabel.Text = 'lonmin';
            app.latminEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.latminEditFieldLabel.Text = 'latmin';
            app.nxEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.nxEditFieldLabel.Text = 'nx';
            app.nxEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.nxEditField.Value = 11;
            app.nyEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.nyEditFieldLabel.Text = 'ny';
            app.nyEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.nyEditField.Value = 14;
            app.rankEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.rankEditFieldLabel.Text = 'rank';
            app.rankEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.rankEditField.Value = 5;
            app.niterEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.niterEditFieldLabel.Text = 'niter';
            app.niterEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.niterEditField.Value = 10;
            app.modeEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.modeEditFieldLabel.Text = 'mode';
            app.modeEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.modeEditField.Value = 1;
            app.epsEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.epsEditFieldLabel.Text = 'eps';
            app.epsEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.epsEditField.Value = 1e-05;
            app.KEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.KEditField.Value = 4;
            app.flowEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.flowEditFieldLabel.Text = 'flow';
            app.flowEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.flowEditField.Value = 0.1;
            app.fhighEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.fhighEditFieldLabel.Text = 'fhigh';
            app.fhighEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.fhighEditField.Value = 1.2;
            app.MaximumtimeforreconstructionLabel = uilabel(app.RankReductionParamTab);
            app.MaximumtimeforreconstructionLabel.Text = 'Maximum time for reconstruction';
            app.tmaxEditFieldLabel = uilabel(app.RankReductionParamTab);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.RankReductionParamTab, 'numeric');
            app.tmaxEditField.Value = 50;
            app.MigrationTab = uitab(app.TabGroup);
            app.MigrationTab.Title = 'Migration';
            app.flowEditField_2Label = uilabel(app.MigrationTab);
            app.flowEditField_2Label.Text = 'flow';
            app.flowEditField_2 = uieditfield(app.MigrationTab, 'numeric');
            app.flowEditField_2.Value = 0.1;
            app.fhighEditField_2Label = uilabel(app.MigrationTab);
            app.fhighEditField_2Label.Text = 'fhigh';
            app.fhighEditField_2 = uieditfield(app.MigrationTab, 'numeric');
            app.fhighEditField_2.Value = 1.2;
            app.rank_pEditFieldLabel = uilabel(app.MigrationTab);
            app.rank_pEditFieldLabel.Text = 'rank_p';
            app.rank_pEditField = uieditfield(app.MigrationTab, 'numeric');
            app.rank_pEditField.Value = 8;
            app.alphaEditFieldLabel = uilabel(app.MigrationTab);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.MigrationTab, 'numeric');
            app.alphaEditField.Value = 0.9;
            app.n_interEditFieldLabel = uilabel(app.MigrationTab);
            app.n_interEditFieldLabel.Text = 'n_inter';
            app.n_interEditField = uieditfield(app.MigrationTab, 'numeric');
            app.n_interEditField.Value = 20;
            app.gaussEditField_2Label = uilabel(app.MigrationTab);
            app.gaussEditField_2Label.Text = 'gauss';
            app.gaussEditField_2 = uieditfield(app.MigrationTab, 'numeric');
            app.gaussEditField_2.Value = 2.5;
            app.phaseshiftEditField_2Label = uilabel(app.MigrationTab);
            app.phaseshiftEditField_2Label.Text = 'phaseshift';
            app.phaseshiftEditField_2 = uieditfield(app.MigrationTab, 'numeric');
            app.phaseshiftEditField_2.Value = 5;
            app.MinimumfrequencyformigrationHzLabel = uilabel(app.MigrationTab);
            app.MinimumfrequencyformigrationHzLabel.Text = 'Minimum frequency for migration (Hz)';
            app.MaximumfrequencyformigrationHzLabel = uilabel(app.MigrationTab);
            app.MaximumfrequencyformigrationHzLabel.Text = 'Maximum frequency for migration (Hz)';
            app.RankparameterforSSALabel = uilabel(app.MigrationTab);
            app.RankparameterforSSALabel.Text = 'Rank parameter for SSA';
            app.PhaseshiftparameterformigrationLabel = uilabel(app.MigrationTab);
            app.PhaseshiftparameterformigrationLabel.Text = 'Phase shift parameter for migration';
            app.WeightparameterforSSALabel = uilabel(app.MigrationTab);
            app.WeightparameterforSSALabel.Text = 'Weight parameter for SSA';
            app.NumberofiterationsforleastsquaresmigrationLabel = uilabel(app.MigrationTab);
            app.NumberofiterationsforleastsquaresmigrationLabel.Text = 'Number of iterations for least-squares migration';
            app.GaussianparameterformigrationLabel = uilabel(app.MigrationTab);
            app.GaussianparameterformigrationLabel.Text = 'Gaussian parameter for migration';
            app.UseSSAmethod1ornot0Label = uilabel(app.MigrationTab);
            app.UseSSAmethod1ornot0Label.Text = 'Use SSA method (1) or not (0)';
            app.is_ssaCheckBox = uicheckbox(app.MigrationTab);
            app.is_ssaCheckBox.Text = 'is_ssa';
            app.OthersTab = uitab(app.TabGroup);
            app.OthersTab.Title = 'Others';
            app.max_angle_diffEditFieldLabel = uilabel(app.OthersTab);
            app.max_angle_diffEditFieldLabel.Text = 'max_angle_diff';
            app.max_angle_diffEditField = uieditfield(app.OthersTab, 'numeric');
            app.max_angle_diffEditField.Value = 15;
            app.profile_lengthEditFieldLabel = uilabel(app.OthersTab);
            app.profile_lengthEditFieldLabel.Text = 'profile_length';
            app.profile_lengthEditField = uieditfield(app.OthersTab, 'numeric');
            app.profile_lengthEditField.Value = 4;
            app.saveResultsCheckBox = uicheckbox(app.OthersTab);
            app.saveResultsCheckBox.Text = 'saveResults';
            app.visualizeResultsCheckBox = uicheckbox(app.OthersTab);
            app.visualizeResultsCheckBox.Text = 'visualizeResults';
            app.OutputfolderpathLabel = uilabel(app.OthersTab);
            app.OutputfolderpathLabel.Text = 'Output folder path';
            app.WhethertosaveresultsLabel = uilabel(app.OthersTab);
            app.WhethertosaveresultsLabel.Text = 'Whether to save results';
            app.WhethertovisualizeresultsLabel = uilabel(app.OthersTab);
            app.WhethertovisualizeresultsLabel.Text = 'Whether to visualize results';
            app.MaxazimuthdifferencedegLabel = uilabel(app.OthersTab);
            app.MaxazimuthdifferencedegLabel.Text = 'Max azimuth difference (deg)';
            app.profilelengthdegLabel = uilabel(app.OthersTab);
            app.profilelengthdegLabel.Text = 'profile length (deg)';
            app.DatafolderpathLabel = uilabel(app.OthersTab);
            app.DatafolderpathLabel.Text = 'Data folder path';
            app.dataFolderEditFieldLabel = uilabel(app.OthersTab);
            app.dataFolderEditFieldLabel.Text = 'dataFolder';
            app.dataFolderEditField = uieditfield(app.OthersTab, 'text');
            app.dataFolderEditField.Value = './data/event_waveforms_BY';
            app.outputFolderEditFieldLabel = uilabel(app.OthersTab);
            app.outputFolderEditFieldLabel.Text = 'outputFolder';
            app.outputFolderEditField = uieditfield(app.OthersTab, 'text');
            app.outputFolderEditField.Value = './results';
            app.SelectfolderButton = uibutton(app.OthersTab, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigEditorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end