classdef DenseArrayToolkit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        ToolsMenu                     matlab.ui.container.Menu
        RadonTransformMenu            matlab.ui.container.Menu
        RankReductionMenu             matlab.ui.container.Menu
        ImagingMenu                   matlab.ui.container.Menu
        HkStackingMenu                matlab.ui.container.Menu
        CCPMenu                       matlab.ui.container.Menu
        Migration2DMenu               matlab.ui.container.Menu
        Migration3DMenu               matlab.ui.container.Menu
        EditingMenu                   matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        VersionLabel                  matlab.ui.control.Label
        VisualizationLabel            matlab.ui.control.Label
        statusLabel                   matlab.ui.control.Label
        processingWorkflowLabel       matlab.ui.control.Label
        InfoTabGroup                  matlab.ui.container.TabGroup
        EventTab                      matlab.ui.container.Tab
        EventTable                    matlab.ui.control.Table
        StationTab                    matlab.ui.container.Tab
        StationTable                  matlab.ui.control.Table
        ButtonGroup                   matlab.ui.container.ButtonGroup
        StationMapPanel               matlab.ui.container.Panel
        EventMapPanel                 matlab.ui.container.Panel
        SetupPathsButton              matlab.ui.control.Button
        LoadConfigurationButton       matlab.ui.control.Button
        plotStationsEventsButton      matlab.ui.control.Button
        plotCommonEventGatherButton   matlab.ui.control.Button
        plotComonStationGatherButton  matlab.ui.control.Button
        plotWaveformsButton           matlab.ui.control.Button
        StackingRFsButton             matlab.ui.control.Button
        DeconvolutionButton           matlab.ui.control.Button
        PreprocesssingButton          matlab.ui.control.Button
        ReadSACDataButton             matlab.ui.control.Button
    end

    properties (Access = public)
        config                                                             % Configuration parameters
        DataStruct                                                         % Seismic data structure
        ccpStruct                                                          % CCP results
        configEditStatus                                                   % 'OK','Cancel', or other status
        preprocessingStatus = 'Cancel'                                     % 'OK','Cancel', or other status
        rankReductionStatus = 'Cancel'                                     % 'OK','Cancel', or other status
        hkStackingStatus = 'Cancel';                                       % 'OK','Cancel', or other status
        CCPStackingStatus = 'Cancel'                                       % 'OK','Cancel', or other status
        migration2DStatus = 'Cancel'                                       % 'OK','Cancel', or other status
        migration3DStatus = 'Cancel'                                       % 'OK','Cancel', or other status
        radonTransformStatus = 'Cancel'

    end

    properties (Access = private)
        seisout                                                            % Stacking results
        depth0                                                             % Depth or time coordinates for stacking results
        mohoStruct                                                         % Moho information storage
        Events                                                             % Structure array for event information
        Stations                                                           % Selected station names
        selectedEvid                                                       % Selected event IDs
        selectedStation                                                    % Selected station names
    end
    
    methods (Access = private)
        function tableData=getEventTable(app)
            % 1) Get event information
            app.Events = getEvents(app.DataStruct); 
        
            % 2) Calculate number of stations for each event
            nEvents = numel(app.Events);
            stationCounts = zeros(nEvents,1);
            for i = 1:nEvents
                evid   = app.Events(i).evid;
                gather = getCommonEventGather(app.DataStruct, evid);
                stationCounts(i) = numel(gather);
            end
        
            % 3) Build table data
            tableData = cell(nEvents,6);
            for i = 1:nEvents
                tableData{i,1} = app.Events(i).evid;                       % Event ID
                tableData{i,2} = sprintf('%.4f', app.Events(i).evla);      % Latitude
                tableData{i,3} = sprintf('%.4f', app.Events(i).evlo);      % Longitude
                tableData{i,4} = sprintf('%.2f', app.Events(i).evdp);      % Depth
                tableData{i,5} = sprintf('%.2f', app.Events(i).mag);       % Magnitude
                tableData{i,6} = sprintf('%d',   stationCounts(i));        % Station count
            end
        end

        function tableData=getStationTable(app)
            % 1) Get station information
            app.Stations = getStations(app.DataStruct);

            % 2) Calculate number of events for each station
            nStations = numel(app.Stations);
            eventCounts = zeros(nStations,1);
            for i = 1:nStations
                sta   = app.Stations(i).sta;
                gather = getCommonStationGather(app.DataStruct, sta);
                eventCounts(i) = numel(gather);
            end

            % 3) Build table data
            tableData = cell(nStations,6);
            for i = 1:nStations
                tableData{i,1} = app.Stations(i).network;                  % Network
                tableData{i,2} = app.Stations(i).sta;                      % Station
                tableData{i,3} = sprintf('%.4f', app.Stations(i).stla);    % Latitude
                tableData{i,4} = sprintf('%.4f', app.Stations(i).stlo);    % Longitude
                tableData{i,5} = sprintf('%.2f', app.Stations(i).stel);    % Elevation
                tableData{i,6} = sprintf('%d',   eventCounts(i));          % Event count
            end
        end

        function plotStationsInApp(app, selectedEvid, selectedStation)
           % PLOTSTATIONSINAPP Plot using GeographicAxes:
            %   - All stations (gray)
            %   - Selected event stations (red)
            %   - Selected single station (blue)

           % Get all stations
           allSt = getStations(app.DataStruct);  % struct array
           allLats  = [allSt.stla];
           allLons  = [allSt.stlo];
           allNames = {allSt.sta};

           % Get event stations
           if ~exist('selectedEvid','var') || isempty(selectedEvid)
               eventLats  = [];
               eventLons  = [];
               eventNames = {};
           else
               gather  = getCommonEventGather(app.DataStruct, selectedEvid);
               eventSt = getStations(gather);
               eventLats  = [eventSt.stla];
               eventLons  = [eventSt.stlo];
               eventNames = {eventSt.sta};
           end

           % Determine geographic range
           if ~isempty(allLats)
               latMin = min(allLats) - 0.25;
               latMax = max(allLats) + 0.25;
               lonMin = min(allLons) - 0.25;
               lonMax = max(allLons) + 0.25;
               if latMin == latMax, latMax = latMax + 0.5; end
               if lonMin == lonMax, lonMax = lonMax + 0.5; end
           else
               latMin = 30; latMax = 40;
               lonMin = 90; lonMax = 100;
           end

           % Clear panel and create geoaxes
           delete(allchild(app.StationMapPanel));
           gax = geoaxes('Parent', app.StationMapPanel, ...
               'Units','normalized',...
               'Position',[0 0 1 1]);
           hold(gax, 'on');

           geobasemap(gax, 'topographic');
           % geobasemap(gax, 'darkwater');
           geolimits(gax, [latMin latMax], [lonMin lonMax]);

           % Plot all stations (gray)
           if ~isempty(allLats)
               geoscatter(gax, allLats, allLons, ...
                   80, [0.7 0.7 0.7], '^','filled', ...
                   'MarkerEdgeColor','k');

%                % mark station name
%                for i = 1:numel(allLats)
%                    text(gax, allLats(i), allLons(i), allNames{i}, ...
%                        'FontSize',10, 'Color','k', ...
%                        'VerticalAlignment','bottom','HorizontalAlignment','left');
%                end
               
           end

           % Highlight event stations (red)
           if ~isempty(eventLats)
               geoscatter(gax, eventLats, eventLons, ...
                   100, 'r','^','filled', ...
                   'MarkerEdgeColor','k');
%                for i = 1:numel(eventLats)
%                    text(gax, eventLats(i), eventLons(i), eventNames{i}, ...
%                        'FontSize',12, 'Color','r', ...
%                        'VerticalAlignment','bottom','HorizontalAlignment','right');
%                end
           end

           % Highlight selected station (blue)
           if exist('selectedStation','var') && ~isempty(selectedStation)
               idxSel = find(strcmp(allNames, selectedStation), 1);
               if ~isempty(idxSel)
                   selLat = allLats(idxSel);
                   selLon = allLons(idxSel);

                   geoscatter(gax, selLat, selLon, ...
                       120, 'b','^','filled', ...
                       'MarkerEdgeColor','k');

                   text(gax, selLat, selLon, selectedStation, ...
                       'FontSize',12, 'Color','b',...
                       'VerticalAlignment','bottom','HorizontalAlignment','center');
               else
                   disp(['Station ', selectedStation, ' not found in allSt.']);
               end
           end

           % Plot faults (optional)
           faultDir = './visualization/faults/';
           if exist(faultDir,'dir')
               fault_files = dir([faultDir,'*txt']);
               for k = 1:length(fault_files)
                   faults = read_faults_gmt(fullfile(fault_files(k).folder, fault_files(k).name));
                   for l = 1:length(faults)
                       fault = faults{l};  % Nx2, [lon, lat]
                       geoplot(gax, fault(:,2), fault(:,1), 'LineWidth',2,'Color','k');
                   end
               end
           end
           hold(gax, 'off');
       end

        function plotAllEventsInApp(app,selectedEvid)
            % PLOTALLEVENTSINAPP Display event distribution in app.EventMapPanel
            if nargin < 2
                selectedEvid = 'All';
            end
                
            if isempty(app.Events)
                disp('No events to plot.');
                return;
            end
        
            lats = [app.Events.evla];
            lons = [app.Events.evlo];
            evidList = {app.Events.evid};
     
            delete(allchild(app.EventMapPanel));
        
            gax = geoaxes('Parent', app.EventMapPanel, ...
                         'Units','normalized', ...
                         'Position',[0 0 1 1]);
            
            % (Optional) For interactive GeographicAxes functionality
            % GA.ButtonDownFcn = @(~,~) disp('Clicked on geoaxes');
        
            geoscatter(gax, lats, lons, 50, 'k', 'filled');
            hold(gax, 'on');

%             Stations = getStations(app.DataStruct);
%             slat = mean([Stations.stla]);
%             slon = mean([Stations.stlo]);
            slat  = mean([app.Stations.stla]);
            slon  = mean([app.Stations.stlo]);
            geoscatter(gax, slat, slon, 100, 'r', '^','filled','MarkerEdgeColor','k');


            idx = find(strcmp({app.Events.evid}, selectedEvid), 1);
            if ~isempty(idx)
                geoscatter(gax, app.Events(idx).evla, app.Events(idx).evlo,50, ...
                    'ro','LineWidth',2);
               text(gax, app.Events(idx).evla, app.Events(idx).evlo, app.Events(idx).evid, ...
                   'FontSize',12, 'Color','r', ...
                   'VerticalAlignment','bottom','HorizontalAlignment','center');
            end
              
            geobasemap(gax, 'topographic');
        
            latMargin = 5;
            lonMargin = 5;
            latMin = min([slat lats]) - latMargin;
            latMax = max([slat lats]) + latMargin;
            lonMin = min([slon lons]) - lonMargin;
            lonMax = max([slon lons]) + lonMargin;
            if latMin<-90
                latMin=-90;
            end
            if latMax>90
                latMax=90;
            end
            geolimits(gax, [latMin latMax], [lonMin lonMax]);
            title(gax, 'All Events (GeographicAxes)');
        
            % Event ID (optional)
%             for i = 1:numel(lats)
%                 text(gax, lats(i), lons(i), ['  ' evidList{i}], ...
%                     'FontSize',8, 'Color','k');
%             end
            
            hold(gax, 'off');
        end
   
        function updateEventStationTable(app)
            eventTableData = getEventTable(app);
            app.EventTable.Data = eventTableData;
            app.EventTable.ColumnName = {'Event ID','Latitude','Longitude','Depth','Mag','Station Count'};

            stationTableData = getStationTable(app);
            app.StationTable.Data = stationTableData;
            app.StationTable.ColumnName = {'Network','Station','Latitude','Longitude','Elevation','Event Count'};

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'DAT main App';
            app.statusLabel.Text = 'Ready to go!';
        end

        % Button pushed function: ReadSACDataButton
        function ReadSACDataButtonPushed(app, event)
            clc
            if isempty(app.config)
                uialert(app.UIFigure, 'Please load configuration file first!', 'Error','Icon','error');
                return;
            end
            try
                % 1) Create a progress bar dialog
                d = uiprogressdlg(app.UIFigure, ...
                    'Title','Reading Data', ...
                    'Message','Reading SAC files...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');  
                
                % 2) read_SAC()
                tic
                %----------------------------------------------------------
                fileSuffix = '*Z.*.SAC';
                %----------------------------------------------------------
                dataFolder = app.config.dataFolder;
                sacFiles = dir(fullfile(dataFolder, fileSuffix));
                nFiles = numel(sacFiles);
                DataStruct = [];
                for i = 1:nFiles
                    d.Value = (i-1)/nFiles;
                    d.Message = sprintf('Reading：%s (%d/%d)', sacFiles(i).name, i, nFiles);
                    drawnow;  

                    sacFilePath = fullfile(dataFolder, sacFiles(i).name);
                    
                    tmpStruct = read_SAC_single(sacFilePath);
                    
                    if isempty(fieldnames(tmpStruct))
                        continue
                    end
                    DataStruct = [DataStruct tmpStruct];
                end
                
                d.Value = 1;
                d.Message = 'All SAC files read successfully!';
                drawnow;

                pause(0.5);
                close(d);

                app.DataStruct = DataStruct;
                uialert(app.UIFigure, 'Reading Data completed!', 'Success');
            catch ME
                if exist('d','var') && isvalid(d)
                    close(d);
                end
                uialert(app.UIFigure, sprintf('Check if the project path is configured.\n%s', ME.message), 'Error', 'Icon', 'error');
                return;
            end
            app.statusLabel.Text = 'Data loading completed';

            updateEventStationTable(app)
            %             eventTableData = getEventTable(app);
            %             app.EventTable.Data = eventTableData;
            %             app.EventTable.ColumnName = {'Event ID','Latitude','Longitude','Depth','Mag','Station Count'};
            %
            %             stationTableData = getStationTable(app);
            %             app.StationTable.Data = stationTableData;
            %             app.StationTable.ColumnName = {'Network','Station','Latitude','Longitude','Elevation','Event Count'};
            plotAllEventsInApp(app)
            plotStationsInApp(app)
            
            toc
        end

        % Button pushed function: PreprocesssingButton
        function PreprocesssingButtonPushed(app, event)
%             % 2.start preprocessing
            clc
            try
                if isempty(app.DataStruct)
                    uialert(app.UIFigure,'No data loaded!','Error');
                    return;
                end
                % 
                if ~isfield(app.config,'PreprocessingParam')
                    uialert(app.UIFigure,'PreprocessingParam not found!','Error');
                    return;
                end
                defaultParam = app.config.PreprocessingParam;

                % Create and open sub-app
                preprocessingApp = PreprocessingApp();
                preprocessingApp.mainApp = app;
                preprocessingApp.DataStruct = app.DataStruct;
                preprocessingApp.loadConfigData(defaultParam);
                % Provide sub-app with reference to main app -> preprocessingApp.mainApp = app;

                waitfor(preprocessingApp.UIFigure);
                
                switch app.preprocessingStatus
                    case 'OK'
                        uialert(app.UIFigure,'Preprocessing completed!','Success');
                        app.statusLabel.Text = 'Preprocessing completed';
                        
                        updateEventStationTable(app)
                    case 'Cancel'
                        uialert(app.UIFigure,'Preprocessing canceled!','Warning');
                        app.statusLabel.Text = 'Preprocessing canceled';
                    otherwise
                        uialert(app.UIFigure,'Unknown operation...','Warning');
                end
                
            catch ME
                uialert(app.UIFigure, ...
                    ['Error opening Preprocessing parameters: ', ME.message], ...
                    'Error','Icon','error');
            end

        end

        % Button pushed function: DeconvolutionButton
        function DeconvolutionButtonPushed(app, event)
            % 3. receiver function module
            clc
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data loaded or preprocessed!', 'Error','Icon','error');
                return;
            end
            if ~isfield(app.config,'DeconvParam')
                uialert(app.UIFigure, 'DeconvParam missing in configuration!', 'Error','Icon','error');
                return;
            end
            try
                d = uiprogressdlg(app.UIFigure,...
                    'Title','Calculating Receiver Functions',...
                    'Message','Performing deconvolution...',...
                    'Value',0, ...
                    'Indeterminate','off',...
                    'Cancelable','off');

                tic
                nFiles = length(app.DataStruct);
                noresults = [];
                for i = 1:nFiles
                    d.Value = (i-1)/nFiles;
                    d.Message = sprintf('Deconvolving: (%d/%d) trace', i, nFiles);
                    drawnow;
       
                    [app.DataStruct(i),noresult] = deconv_single(app.DataStruct(i), app.config.DeconvParam);
                    if noresult
                        noresults = [noresults,i];
                    end
                end
                d.Value = 1;
                d.Message = 'RF calculation completed!';
                drawnow;
                toc

                pause(0.5);
                close(d);
                uialert(app.UIFigure, 'RF calculation completed!', 'Success');
                
            catch ME
                if exist('d','var') && isvalid(d)
                    close(d);
                end
                uialert(app.UIFigure, ['RF calculation failed: ', ME.message], 'Error','Icon','error');
            end

            if ~isempty(noresults)
                % If desired, remove the invalid or unsuccessful records
                app.DataStruct(noresults) = [];
                fprintf('Removed %d traces with no valid decon results.\n', length(noresult));
            end

            app.statusLabel.Text = 'RF calculation completed';

            updateEventStationTable(app)
%             eventTableData = getEventTable(app);
%             app.EventTable.Data = eventTableData;
%             app.EventTable.ColumnName = {'Event ID','Latitude','Longitude','Depth','Mag','Station Count'};
%             stationTableData = getStationTable(app);
%             app.StationTable.Data = stationTableData;
%             app.StationTable.ColumnName = {'Network','Station','Latitude','Longitude','Elevation','Event Count'};
        end

        % Button pushed function: StackingRFsButton
        function StackingRFsButtonPushed(app, event)
            % 4. stacking RF
            clc
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No RF data available!', 'Error','Icon','error');
                return;
            end
            try
                [app.seisout, app.depth0, app.mohoStruct] = stackCommonStationGather(app.DataStruct);
                uialert(app.UIFigure, 'RF stacking completed!', 'Success');
            catch ME
                uialert(app.UIFigure, ['Stacking failed:', ME.message], 'Error','Icon','error');
            end
            app.statusLabel.Text = 'RF stacking completed';
        end

        % Button pushed function: SetupPathsButton
        function SetupPathsButtonPushed(app, event)
            clc
            try
                setupPaths();
                uialert(app.UIFigure, 'Project paths configured successfully!', 'Success');
            catch ME
                uialert(app.UIFigure, ['Path setup failed: ', ME.message], 'Error', 'Icon','error');
            end
            app.statusLabel.Text = 'Path setup completed';
        end

        % Button pushed function: LoadConfigurationButton
        function LoadConfigurationButtonPushed(app, event)
            clc
            try
                if ~isempty(app.config)
                    defaultCfg = app.config;
                else
                    defaultCfg = loadConfig();
                end
                app.configEditStatus = 'Initial';
            
                configEditor = ConfigEditorApp();
                configEditor.mainApp = app;
                configEditor.loadConfigData(defaultCfg);
                waitfor(configEditor.UIFigure);

                switch app.configEditStatus
                    case 'OK'
                        uialert(app.UIFigure,'Configuration loaded and updated!','Success');
                        app.statusLabel.Text = 'Configuration loaded';
                    case 'Cancel'
                        uialert(app.UIFigure,'Configuration update canceled, using default settings!','Warning');
                        app.statusLabel.Text = 'Configuration update canceled, using default settings';
                    otherwise
                        uialert(app.UIFigure,'Unknown operation...','Warning');
                end
            
            catch ME
                uialert(app.UIFigure, ...
                    ['Error loading/editing configuration: ', ME.message], ...
                    'Error','Icon','error');
            end

        end

        % Button pushed function: plotWaveformsButton
        function plotWaveformsButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data available!', 'Error','Icon','error');
                return;
            end
            answer = inputdlg({'Enter trace index to plot:'}, ...
                              'Plot Single Trace', ...
                              [1 35], ...
                              {'100'});
            if isempty(answer)
                return; 
            end
            traceIndex = str2double(answer{1});
            if isnan(traceIndex) || traceIndex <= 0
                uialert(app.UIFigure, 'Trace index must be a positive integer!', 'Error','Icon','error');
                return;
            end
            try
%                 figure('Name','Single Trace','NumberTitle','off');
                plotWaveforms(app.DataStruct, traceIndex);
            catch ME
                uialert(app.UIFigure, ['Plotting failed:', ME.message], 'Error','Icon','error');
            end
        end

        % Button pushed function: plotComonStationGatherButton
        function plotComonStationGatherButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data available!', 'Error','Icon','error');
                return;
            end
            try
                stations = getStations(app.DataStruct);
                stationList = {stations.sta};
                [selIndex, ok] = listdlg('PromptString','Select station:',...
                                         'SelectionMode','single',...
                                         'ListString',stationList);
                if ~ok, return; end
                chosenStation = stationList{selIndex};
                
%                 figure('Name','Common Station Gather','NumberTitle','off');
                plotCommonStationGather(app.DataStruct, chosenStation);
            catch ME
                uialert(app.UIFigure, ['Plotting failed:', ME.message], 'Error','Icon','error');
            end
        end

        % Button pushed function: plotCommonEventGatherButton
        function plotCommonEventGatherButtonPushed(app, event)

            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data available!', 'Error','Icon','error');
                return;
            end

            try
                events = getEvents(app.DataStruct);
                eventList = {events.evid};  % cell array of strings
            catch ME
                uialert(app.UIFigure, ['Cannot get event list: ', ME.message], 'Error','Icon','error');
                return;
            end

            extendedEventList = [{'All'}, eventList];

            [selIndex, ok] = listdlg('PromptString',   'Please select events: ',...
                'ListString',     extendedEventList,...
                'SelectionMode',  'multiple',...
                'ListSize',       [200 300]);
            if ~ok
                return;
            end

            % 4) saving
            %         outputDir = uigetdir(pwd, 'select direction');
            %         if outputDir == 0
            outputDir = './figures';
            %         end

            if any(selIndex == 1)
                selectedEventIDs = eventList;
            else
                realIndex = selIndex - 1;
                selectedEventIDs = eventList(realIndex);
            end

            try
                for iE = 1:numel(selectedEventIDs)
                    evid = selectedEventIDs{iE};

                    plotCommonEventGather(app.DataStruct, evid, 'trace','wigb');

                    filename = fullfile(outputDir, [evid, '.png']);
                    saveas(gcf, filename);
                    if numel(selectedEventIDs) > 1
                        close(gcf);
                    end
                end

                uialert(app.UIFigure, 'Event gather plotting and saving completed!','Success');
            catch ME
                uialert(app.UIFigure, ['Plotting or saving failed:  ', ME.message], 'Error','Icon','error');
            end

        end

        % Button pushed function: plotStationsEventsButton
        function plotStationsEventsButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data available!', 'Error','Icon','error');
                return;
            end
            try
%                 demFile = 'Qaidam_DEM.mat';
                demFile = 'dem_data.mat';
                if ~exist(demFile,'file')
                    warndlg(['DEM file not found:', demFile, ...
                        '. Plotting stations and events only.'],'Warning');
                    demFile = [];
                end

                plotStations(app.DataStruct, demFile);
                pause(0.05);
                
                plotEvents(app.DataStruct);

            catch ME
                uialert(app.UIFigure, ['Plotting failed: ', ME.message], 'Error','Icon','error');
            end
        end

        % Cell selection callback: EventTable
        function EventTableCellSelection(app, event)
            indices = event.Indices;
            % Value changed function: EventsTable
            if isempty(indices)
                return; 
            end
            selectedRow = indices(1);
            app.selectedEvid = app.Events(selectedRow).evid;

            plotAllEventsInApp(app,app.selectedEvid)
            plotStationsInApp(app,app.selectedEvid);
        end

        % Selection change function: InfoTabGroup
        function InfoTabGroupSelectionChanged(app, event)
            selectedTab = app.InfoTabGroup.SelectedTab;
            switch selectedTab.Title
                case 'Event Table'
                    app.EventTable.Visible = 'on';
                    app.StationTable.Visible = 'off';

                case 'Station Table'
                    app.StationTable.Visible = 'on';
                    app.EventTable.Visible = 'off';  
            end
        end

        % Cell selection callback: StationTable
        function StationTableCellSelection(app, event)
            idx = event.Indices;
            if isempty(idx)
                return;
            end
            rowIdx = idx(1);
            
            app.selectedStation = app.StationTable.Data{rowIdx,2};
        
            plotStationsInApp(app, app.selectedEvid, app.selectedStation);
        end

        % Menu selected function: LoadMenu
        function onLoadMenuSelected(app, event)
            clc
            try
                [filename, pathname] = uigetfile({'*.mat;*.csv;*.txt','Data Files'}, ...
                    'Select data file to load');
                if isequal(filename,0) || isequal(pathname,0)
                    return;
                end

                fullpath = fullfile(pathname, filename);

                [~,~,ext] = fileparts(filename);
                switch lower(ext)
                    case '.mat'
                        loaded = load(fullpath); 
                        if isfield(loaded,'DataStruct')
                            app.DataStruct = loaded.DataStruct;
                        else
  
                            app.DataStruct = loaded;
                        end
                    otherwise

                        if strcmp(ext,'.csv')
                            T = readtable(fullpath);
                            app.DataStruct = table2struct(T);
                        elseif strcmp(ext,'.txt')
                            % ...
                        end
                end

                uialert(app.UIFigure, 'Data loaded successfully!', 'Success');

                eventTableData = getEventTable(app);
                app.EventTable.Data = eventTableData;
                app.EventTable.ColumnName = {'Event ID','Latitude','Longitude','Depth','Mag','Station Count'};
    
                stationTableData = getStationTable(app);
                app.StationTable.Data = stationTableData;
                app.StationTable.ColumnName = {'Network','Station','Latitude','Longitude','Elevation','Event Count'};
                plotAllEventsInApp(app)
                plotStationsInApp(app)
            catch ME
                uialert(app.UIFigure, ...
                    ['Error loading data: ', ME.message], ...
                    'Error','Icon','error');
            end
        end

        % Menu selected function: SaveMenu
        function onSaveMenuSelected(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data to save', 'Warning');
                return;
            end

            try
                [filename, pathname] = uiputfile('*.mat','Select file');
                if isequal(filename,0) || isequal(pathname,0)
                    return;
                end

                fullpath = fullfile(pathname, filename);

                DataStruct0=app.DataStruct;
                save(fullpath,'DataStruct0','-v7.3');

                uialert(app.UIFigure, ['Data saved to: ', fullpath], 'Sucess');

            catch ME
                uialert(app.UIFigure, ...
                    ['Error saving data:', ME.message], ...
                    'Error','Icon','error');
            end
        end

        % Menu selected function: RankReductionMenu
        function onRankReductionMenuSelected(app, event)
            clc
            try
                if isempty(app.DataStruct)
                    uialert(app.UIFigure, 'No data loaded!','Error');
                    return;
                end

                if ~isfield(app.config,'RankReductionParam')
                    uialert(app.UIFigure, 'RankReductionParam not found!','Error');
                    return;
                end
                defaultParam = app.config.RankReductionParam;

                rankApp = RankReductionApp();
                rankApp.mainApp = app;
                rankApp.DataStruct = app.DataStruct;
                rankApp.loadConfigData(defaultParam);

                waitfor(rankApp.UIFigure);
                
                switch app.rankReductionStatus
                    case 'OK'
                        uialert(app.UIFigure,'Rank reduction processing completed!','Sucess');
                        app.statusLabel.Text = 'Rank reduction processing completed';
                    case 'Cancel'
                        uialert(app.UIFigure,'Rank reduction canceled!','Warning');
                        app.statusLabel.Text = 'Rank reduction canceled';
                    otherwise
                        uialert(app.UIFigure,'Unknown operation...','Warning');
                end


            catch ME
                uialert(app.UIFigure, ...
                    ['Error opening Rank Reduction parameters: ',ME.message], ...
                    'Error','Icon','error');
            end

           
        end

        % Menu selected function: HkStackingMenu
        function HkStackingMenuSelected(app, event)
            clc
            try
                if isempty(app.DataStruct)
                    uialert(app.UIFigure,'No data loaded!','Error');
                    return;
                end
                
                defaultParam = app.config.HKStackingParam;
        
                hkApp = HkStackingApp();
                hkApp.mainApp = app;
                hkApp.DataStruct = app.DataStruct;

                hkApp.loadConfigData(defaultParam)
                
                waitfor(hkApp.UIFigure);

                switch app.hkStackingStatus
                    case 'OK'
                        uialert(app.UIFigure,'HK Stacking processing completed!','Success');
                        app.statusLabel.Text = 'HK Stacking completed';
                    case 'Cancel'
                        uialert(app.UIFigure,'HK Stacking canceled!','Warning');
                        app.statusLabel.Text = 'HK Stacking canceled';
                    otherwise
                        uialert(app.UIFigure,'Unknown operation...','Warning');
                end
            catch ME
                uialert(app.UIFigure, ...
                    ['Error opening HK Stacking parameters: ', ME.message], ...
                    'Error','Icon','error');
            end
        end

        % Menu selected function: CCPMenu
        function CCPMenuSelected(app, event)
            clc
            try
                if isempty(app.DataStruct)
                    uialert(app.UIFigure,'No data loaded!','Error');
                    return;
                end
                
                ccpApp = CCPStackingApp();
                ccpApp.mainApp = app;
                ccpApp.DataStruct = app.DataStruct;

                waitfor(ccpApp.UIFigure);

                switch app.CCPStackingStatus
                    case 'OK'
                        uialert(app.UIFigure,'CCP stacking processing completed!','Success');
                        app.statusLabel.Text = 'CCP stacking processing completed';
                    case 'Cancel'
                        uialert(app.UIFigure,'CCP stacking canceled!','Warning');
                        app.statusLabel.Text = 'CCP stacking canceled';
                    otherwise
                        uialert(app.UIFigure,'Unknown operation...','Warning');
                end

            catch ME
                uialert(app.UIFigure, ...
                    ['Error opening CommonConversionPoint parameters: ',ME.message], ...
                    'Error','Icon','error');
            end
        end

        % Menu selected function: Migration2DMenu
        function Migration2DMenuSelected(app, event)
            % imaging->2D migration
            clc
            try
                defaultMigParam = app.config.MigParam;
                max_angle_diff = app.config.max_angle_diff;


                mig2dApp = Migration2DApp();
                mig2dApp.mainApp = app;
                mig2dApp.DataStruct = app.DataStruct;

                mig2dApp.loadConfigData(defaultMigParam,max_angle_diff);

                waitfor(mig2dApp.UIFigure);

                switch app.migration2DStatus
                    case 'OK'
                        uialert(app.UIFigure,'2D migration results saved!','Success');
                        app.statusLabel.Text = '2D migration done';
                    case 'Cancel'
                        uialert(app.UIFigure,'2D migration canceled!','Warning');
                        app.statusLabel.Text = '2D migration canceled';
                    otherwise
                        uialert(app.UIFigure,'Unknown operation...','Warning');
                end

            catch ME
                uialert(app.UIFigure, ...
                    ['Error opening 2D migration parameters: ',ME.message], ...
                    'Error','Icon','error');
            end
        end

        % Menu selected function: Migration3DMenu
        function Migration3DMenuSelected(app, event)
            % imaging->3D migration
            clc
            try
                defaultMigParam = app.config.MigParam;
                
                mig3dApp = Migration3DApp();
                mig3dApp.mainApp = app;
                mig3dApp.DataStruct = app.DataStruct;

                mig3dApp.loadConfigData(defaultMigParam);

                waitfor(mig3dApp.UIFigure);

                switch app.migration3DStatus
                    case 'OK'
                        uialert(app.UIFigure,'3D migration results saved!','Success');
                        app.statusLabel.Text = '3D migration done';
                    case 'Cancel'
                        uialert(app.UIFigure,'3D migration cancel!','Warning');
                        app.statusLabel.Text = '3D migration cancel!';
                    otherwise
                        uialert(app.UIFigure,'Known operation...','Warning');
                end

            catch ME
                uialert(app.UIFigure, ...
                    ['Error opening 3D migration parameters: ',ME.message], ...
                    'Error','Icon','error');
            end
        end

        % Menu selected function: RadonTransformMenu
        function RadonTransformMenuSelected(app, event)
            clc
            try
                if isempty(app.DataStruct)
                    uialert(app.UIFigure, 'No data loaded!','Error');
                    return;
                end

                if ~isfield(app.config,'RadonParam')
                    uialert(app.UIFigure, 'RadonParam not found!','Error');
                    return;
                end
                radonParam = app.config.RadonParam;
                deconvParam = app.config.DeconvParam;

                randonApp = RadonTransformApp();
                randonApp.mainApp = app;
                randonApp.DataStruct = app.DataStruct;

                randonApp.loadConfigData(radonParam,deconvParam);

                waitfor(randonApp.UIFigure);
                
                switch app.radonTransformStatus
                    case 'OK'
                        uialert(app.UIFigure,'Radon Transform processing completed!','Sucess');
                        app.statusLabel.Text = 'Radon Transform processing completed';
                    case 'Cancel'
                        uialert(app.UIFigure,'Radon Transform canceled!','Warning');
                        app.statusLabel.Text = 'Radon Transform canceled';
                    otherwise
                        uialert(app.UIFigure,'Unknown operation...','Warning');
                end

            catch ME
                uialert(app.UIFigure, ...
                    ['Error opening Radon Transform parameters: ', ME.message], ...
                    'Error','Icon','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @onLoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @onSaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RadonTransformMenu = uimenu(app.ToolsMenu);
            app.RadonTransformMenu.MenuSelectedFcn = createCallbackFcn(app, @RadonTransformMenuSelected, true);
            app.RadonTransformMenu.Text = 'Radon Transform';
            app.RankReductionMenu = uimenu(app.ToolsMenu);
            app.RankReductionMenu.MenuSelectedFcn = createCallbackFcn(app, @onRankReductionMenuSelected, true);
            app.RankReductionMenu.Text = 'Rank Reduction';
            app.ImagingMenu = uimenu(app.UIFigure);
            app.ImagingMenu.Text = 'Imaging';
            app.HkStackingMenu = uimenu(app.ImagingMenu);
            app.HkStackingMenu.MenuSelectedFcn = createCallbackFcn(app, @HkStackingMenuSelected, true);
            app.HkStackingMenu.Text = 'H-k Stacking';
            app.CCPMenu = uimenu(app.ImagingMenu);
            app.CCPMenu.MenuSelectedFcn = createCallbackFcn(app, @CCPMenuSelected, true);
            app.CCPMenu.Text = 'CCP';
            app.Migration2DMenu = uimenu(app.ImagingMenu);
            app.Migration2DMenu.MenuSelectedFcn = createCallbackFcn(app, @Migration2DMenuSelected, true);
            app.Migration2DMenu.Text = 'Migration 2D';
            app.Migration3DMenu = uimenu(app.ImagingMenu);
            app.Migration3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Migration3DMenuSelected, true);
            app.Migration3DMenu.Text = 'Migration 3D';
            app.EditingMenu = uimenu(app.UIFigure);
            app.EditingMenu.Text = 'Editing';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.ReadSACDataButton = uibutton(app.UIFigure, 'push');
            app.ReadSACDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadSACDataButtonPushed, true);
            app.ReadSACDataButton.Text = 'ReadSACData';
            app.PreprocesssingButton = uibutton(app.UIFigure, 'push');
            app.PreprocesssingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocesssingButtonPushed, true);
            app.PreprocesssingButton.Text = 'Preprocesssing';
            app.DeconvolutionButton = uibutton(app.UIFigure, 'push');
            app.DeconvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @DeconvolutionButtonPushed, true);
            app.DeconvolutionButton.Text = 'Deconvolution';
            app.StackingRFsButton = uibutton(app.UIFigure, 'push');
            app.StackingRFsButton.ButtonPushedFcn = createCallbackFcn(app, @StackingRFsButtonPushed, true);
            app.StackingRFsButton.Text = 'Stacking RFs';
            app.plotWaveformsButton = uibutton(app.UIFigure, 'push');
            app.plotWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @plotWaveformsButtonPushed, true);
            app.plotWaveformsButton.Text = 'Plot Single Trace';
            app.plotComonStationGatherButton = uibutton(app.UIFigure, 'push');
            app.plotComonStationGatherButton.ButtonPushedFcn = createCallbackFcn(app, @plotComonStationGatherButtonPushed, true);
            app.plotComonStationGatherButton.Text = 'Plot Station Gather';
            app.plotCommonEventGatherButton = uibutton(app.UIFigure, 'push');
            app.plotCommonEventGatherButton.ButtonPushedFcn = createCallbackFcn(app, @plotCommonEventGatherButtonPushed, true);
            app.plotCommonEventGatherButton.Text = 'Plot Event Gather';
            app.plotStationsEventsButton = uibutton(app.UIFigure, 'push');
            app.plotStationsEventsButton.ButtonPushedFcn = createCallbackFcn(app, @plotStationsEventsButtonPushed, true);
            app.plotStationsEventsButton.Text = 'Plot Station–Event Map';
            app.LoadConfigurationButton = uibutton(app.UIFigure, 'push');
            app.LoadConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigurationButtonPushed, true);
            app.LoadConfigurationButton.Text = 'LoadConfiguration';
            app.SetupPathsButton = uibutton(app.UIFigure, 'push');
            app.SetupPathsButton.ButtonPushedFcn = createCallbackFcn(app, @SetupPathsButtonPushed, true);
            app.SetupPathsButton.Text = 'SetupPaths';
            app.EventMapPanel = uipanel(app.UIFigure);
            app.EventMapPanel.Title = 'Event Map Panel';
            app.StationMapPanel = uipanel(app.UIFigure);
            app.StationMapPanel.Title = 'Station Map Panel';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.InfoTabGroup = uitabgroup(app.UIFigure);
            app.InfoTabGroup.SelectionChangedFcn = createCallbackFcn(app, @InfoTabGroupSelectionChanged, true);
            app.EventTab = uitab(app.InfoTabGroup);
            app.EventTab.Title = 'Event Table';
            app.EventTable = uitable(app.EventTab);
            app.EventTable.ColumnName = {'Event ID'; 'Longitude'; 'Latitude'; 'Depth'; 'Magnitude'; 'Station Count'};
            app.EventTable.RowName = {};
            app.EventTable.ColumnEditable = true;
            app.EventTable.CellSelectionCallback = createCallbackFcn(app, @EventTableCellSelection, true);
            app.StationTab = uitab(app.InfoTabGroup);
            app.StationTab.Title = 'Station Table';
            app.StationTable = uitable(app.StationTab);
            app.StationTable.ColumnName = {'Network'; 'Station'; 'Longitude'; 'Latitude'; 'Elevation'; 'Event Count'};
            app.StationTable.RowName = {};
            app.StationTable.ColumnEditable = true;
            app.StationTable.CellSelectionCallback = createCallbackFcn(app, @StationTableCellSelection, true);
            app.processingWorkflowLabel = uilabel(app.UIFigure);
            app.processingWorkflowLabel.Text = 'Processing status:';
            app.statusLabel = uilabel(app.UIFigure);
            app.statusLabel.Text = '';
            app.VisualizationLabel = uilabel(app.UIFigure);
            app.VisualizationLabel.Text = 'Visualization:';
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'DAT-GUI version 0.1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DenseArrayToolkit_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end