classdef Migration2DApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FilesMenu                   matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        EventIDLabel                matlab.ui.control.Label
        QuitButton                  matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        LSMPanel                    matlab.ui.container.Panel
        MuEditField                 matlab.ui.control.NumericEditField
        MuEditFieldLabel            matlab.ui.control.Label
        IterationEditField          matlab.ui.control.NumericEditField
        IterationEditFieldLabel     matlab.ui.control.Label
        RunButtonLSM                matlab.ui.control.Button
        RadonTransformPanel         matlab.ui.container.Panel
        dpEditField                 matlab.ui.control.NumericEditField
        dpEditFieldLabel            matlab.ui.control.Label
        Niter2EditField             matlab.ui.control.NumericEditField
        Niter2EditFieldLabel        matlab.ui.control.Label
        Niter1EditField             matlab.ui.control.NumericEditField
        Niter1Label                 matlab.ui.control.Label
        pmaxskmEditField            matlab.ui.control.NumericEditField
        pmaxskmEditFieldLabel       matlab.ui.control.Label
        pminskmEditField            matlab.ui.control.NumericEditField
        pminskmEditFieldLabel       matlab.ui.control.Label
        RunButtonRT                 matlab.ui.control.Button
        CreateGridPanel             matlab.ui.container.Panel
        dxkmEditField               matlab.ui.control.NumericEditField
        dxkmEditFieldLabel          matlab.ui.control.Label
        dzkmEditField               matlab.ui.control.NumericEditField
        dzkmEditFieldLabel          matlab.ui.control.Label
        VelocityModelDropDown       matlab.ui.control.DropDown
        VelocityModelDropDownLabel  matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        SelectEventsDropDown        matlab.ui.control.DropDown
        Migration2DPanelLabel       matlab.ui.control.Label
        UIAxes_lsm_radon            matlab.ui.control.UIAxes
        UIAxes_lsm                  matlab.ui.control.UIAxes
    end

    %% properties 
    properties (Access = public)
        mainApp             
        MigrationParam
        DataStruct
        gridStruct

    end
    
    properties (Access = private)
        evt2d
        max_angle_diff
        selectedEvid
        DataStructRadon
        cmap
        img
        imgRadon
    end

    %% methods
    methods (Access = private)
        
        function updateEventDropdown(app)
            if isempty(app.DataStruct)
                app.SelectEventsDropDown.Items = {'No events'};
                app.SelectEventsDropDown.Value = 'No events';
                return;
            end

            stationList = getStations(app.DataStruct);
            eventList   = getEvents(app.DataStruct);
            stlo = [stationList.stlo]';  % Station longitude (degrees)
            stla = [stationList.stla]';  % Station latitude (degrees)
            evla = [eventList.evla]';    % Event epicenter latitude (degrees)
            evlo = [eventList.evlo]';    % Event epicenter longitude (degrees)

            idxConsistentEQ = filter_earthquakes_by_azimuth(stlo, stla, evlo, evla, app.max_angle_diff);

            % Create filtered list of event IDs that meet azimuthal criteria
            % Only events with consistent back-azimuths (within config.max_angle_diff) are retained
            eventid = {eventList.evid};
            eIDs = eventid(idxConsistentEQ);

            app.evt2d = eIDs;   % save

            items = [{'Select events','All'}, eIDs];
            app.SelectEventsDropDown.Items = items;
            app.SelectEventsDropDown.Value = items{1}; 
        end

        
        function plotMigImages(app,selectedEvid,migResult,migResultRadon)
            
            if isempty(selectedEvid)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end

            cla(app.UIAxes_lsm,"reset");
            cla(app.UIAxes_lsm_radon,"reset");
            
            x = migResult.x - min(migResult.x);
            z = migResult.z;
            lsm1 = migResult.migls;
            lsm2 = migResultRadon.migls;

            imagesc(app.UIAxes_lsm,x,z,lsm1./max(abs(lsm1(:))));
            xlabel(app.UIAxes_lsm,'Distance (km)'); ylabel(app.UIAxes_lsm,'Depth (km)');
            title(app.UIAxes_lsm,'LSM image (Raw)');
            set(app.UIAxes_lsm,'FontSize',18,'linewidth',1.5);
            axis(app.UIAxes_lsm,'ij')
            clim(app.UIAxes_lsm,[-0.1 0.1]);  
            box(app.UIAxes_lsm_radon,"on");
            colorbar(app.UIAxes_lsm, 'eastoutside');
            colormap(app.UIAxes_lsm,app.cmap)
            axis(app.UIAxes_lsm,[0 x(end) 0 100]);

            imagesc(app.UIAxes_lsm_radon,x,z,lsm2./max(abs(lsm2(:))));
            xlabel(app.UIAxes_lsm_radon,'Distance (km)'); ylabel(app.UIAxes_lsm_radon,'Depth (km)');
            title(app.UIAxes_lsm_radon,'LSM image (Processed)');
            set(app.UIAxes_lsm_radon,'FontSize',14);
            clim(app.UIAxes_lsm_radon,[-0.1 0.1]); 
            box(app.UIAxes_lsm_radon,"on");
            axis(app.UIAxes_lsm_radon,'ij');
            colorbar(app.UIAxes_lsm_radon,'eastoutside');
            colormap(app.UIAxes_lsm_radon,app.cmap);
            set(app.UIAxes_lsm_radon,'FontSize',18,'linewidth',1.5);
            axis(app.UIAxes_lsm_radon,[0 x(end) 0 100]);
            
        end
    end
    
    methods (Access = public)
        
        function loadConfigData(app, defaultMigParam,max_angle_diff)
            app.MigrationParam = defaultMigParam;  
            app.max_angle_diff = max_angle_diff;

            updateEventDropdown(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Migration2D App';
            load(fullfile(pwd,'/visualization/colormap/roma.mat'));
            app.cmap = flipud(roma);
            updateEventDropdown(app)
        end

        % Value changed function: SelectEventsDropDown
        function SelectEventsDropDownValueChanged(app, event)
            selectedID = app.SelectEventsDropDown.Value;
            
            if strcmp(selectedID,'All')
                app.selectedEvid = app.evt2d;
            else
                app.selectedEvid = {selectedID};
            end
            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data loaded!', 'Error','Icon','error');
                return;
            end

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','Meshgrid', ...
                    'Message','Generate grid and velocity model...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            % create grid
            dx = app.dxkmEditField.Value;
            dy = dx;    % in 2D case, dx = dx; default
            dz = app.dzkmEditField.Value;
            zmax = 100;
            xpad = 20;    % km
            ypad = 20;

            ModelType = app.VelocityModelDropDown.Value;
            app.gridStruct = createGrid(app.DataStruct,dx, dy, dz, zmax, xpad, ypad);
           
            if strcmp(ModelType,'Default (1D)')
                ModelType = '1D';
            end
            xnpts = 10; ynpts = 2;
            app.gridStruct = getVelocityModel(ModelType,app.gridStruct,xnpts, ynpts);

            close(msg);
        end

        % Button pushed function: RunButtonRT
        function RunButtonRTPushed(app, event)
            if isempty(app.gridStruct)
                uialert(app.UIFigure, 'Create grid first!', 'Note');
                return;
            end
            app.DataStructRadon = [];
            % perform Radon transform
            shorts = app.selectedEvid;
            nshots = length(shorts);
            if isempty(shorts)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end
            msg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Radon Transform', ...
                'Message', 'Starting...', ...
                'Cancelable', 'on', ...
                'Value', 0);

            % param
            pmax = app.pmaxskmEditField.Value;
            pmin = app.pminskmEditField.Value;
            dp = app.dpEditField.Value;
            p = pmin:dp:pmax;
            np = length(p);

            N1 = app.Niter1EditField.Value;
            N2 = app.Niter2EditField.Value;

            for iEvent = 1:nshots
                msg.Value = iEvent / nshots;
                msg.Message = ['Processing ', num2str(iEvent), ' of ', num2str(nshots)];
                if msg.CancelRequested
                    return;
                end
                
                evid = shorts{iEvent};
                gather = getCommonEventGather(app.DataStruct, evid);

                isMatch = arrayfun(@(x) strcmp(x.EventInfo.evid, evid), gather);
                foundIndices = find(isMatch);

                if isempty(foundIndices) || length(foundIndices) < 0.6 * length(app.gridStruct.stationLon)
                    % uialert(app.UIFigure,['No data found or too few stations for eventID: ', evid], 'Warning');
                    disp(['No data found or too few stations for eventID: ', evid])
                    continue;
                end

                distArr = [];
                for idx = foundIndices
                    distArr(1,end+1) = gather(idx).TravelInfo.distDeg*(2*pi*6371/360);
                end

                % extract RF
                % build data matrix d0  [Nt x Ntrace]
                t = gather(1).RF.ittime;
                idxT = (t <= max(t));
                itrCell = {gather.RF};
                validMask = cellfun(@(rf) isfield(rf,'itr') && ~isempty(rf.itr), itrCell);
                itrCell  = itrCell(validMask);
                % combine
                rf0 = cell2mat(cellfun(@(rf) rf.itr(idxT), itrCell,'UniformOutput', false));

                % 2D radon 
                xdist = distArr - min(distArr);
                nt = size(rf0,1);
                dt = gather(1).TimeAxis.dt_resample;
                
                Param.h = xdist;
                Param.v = 1./p;
                Param.nt = nt;
                Param.dt = dt;
                Param.type = 1;
                ma = zeros(nt,np);

                [mi_rf, ~]  = yc_pcg(@radon_op, Param, rf0, zeros(size(ma)), N1, N2, 1);
                rfradon     = radon_op(mi_rf, Param, 1);

                % save each event
                % 
                gatherRadon = gather;
                validIdx = find(validMask);
                for k = 1:length(validIdx)
                    col = k;  % The column in d1
                    gInd = validIdx(k);
                    if col <= size(rfradon,2)
                        gatherRadon(gInd).RF.itr    = rfradon(:, col);  % [Nt], reconstructed
                        gatherRadon(gInd).RF.ittime = t;          % new time axis
                    end
                end

                app.DataStructRadon = [app.DataStructRadon; gatherRadon(:)];

            end
            app.DataStructRadon = app.DataStructRadon';

        end

        % Button pushed function: RunButtonLSM
        function RunButtonLSMPushed(app, event)
            % 
            if isempty(app.DataStructRadon)
                uialert(app.UIFigure, 'Perform Randon transform first!', 'Note');
                return;
            end
            
            % run lsm2d
            migResults = [];
            migResultsRadon = [];
            shots = app.selectedEvid;
            if isempty(shots)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end
            if ~isfield(app.gridStruct,'VP')     
                uialert(app.UIFigure, 'Create grid first!', 'Note');
                return;
            end
            msg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Migration', ...
                'Message', 'Starting...', ...
                'Cancelable', 'on', ...
                'Value', 0);

            % param
            app.gridStruct.vp = app.gridStruct.VP(:,:,1);                  % 3D to 2D
            app.gridStruct.vs = app.gridStruct.VS(:,:,1);

            MigParam = app.MigrationParam;
            MigParam.itermax = app.IterationEditField.Value;
            MigParam.mu = app.MuEditField.Value;

            nshots = length(shots);
            for iEvent = 1:nshots
                msg.Value = iEvent / nshots;
                msg.Message = ['Processing ', num2str(iEvent), ' of ', num2str(nshots)];
                if msg.CancelRequested
                    return;
                end

                evid = shots{iEvent};
                % not processed        
                gather = getCommonEventGather(app.DataStruct, evid);

                if length(gather) < 0.6 * length(app.gridStruct.stationLon)
                    % uialert(app.UIFigure,['Too few stations for eventID: ', evid], 'Warning');
                    disp(['No data found or too few stations for eventID: ', evid])
                    continue;
                end

                migResult = leastSquaresMig2D(gather, app.gridStruct, MigParam);
                migResults = [migResults; migResult];

                % after radon
                gatherRadon = getCommonEventGather(app.DataStructRadon, evid);
                migResultRadon = leastSquaresMig2D(gatherRadon, app.gridStruct, MigParam);
                migResultsRadon = [migResultsRadon; migResultRadon];

                % plot each event
                if nshots == 1
                    plotMigImages(app,app.selectedEvid,migResult,migResultRadon)
                end

            end
            app.img = migResults;
            app.imgRadon = migResultsRadon;

            if nshots > 1
                stackedImage1 = zeros(size(migResults(1).migls));
                stackedImage2 = stackedImage1;
                
                for n=1:length(migResultsRadon)
                    img1 = migResults(n).migls;
                    img2 = migResultsRadon(n).migls;

                    smoothLength = 3;  % grid point
                    K = (1/smoothLength^2)*ones(smoothLength,smoothLength);
                    img1 = conv2(img1,K,'same');
                    img2 = conv2(img2,K,'same');

                    stackedImage1 = stackedImage1 + img1;
                    stackedImage2 = stackedImage2 + img2;

                end
                d2dlsm = stackedImage1/nshots;
                d2dlsm_radon = stackedImage2/nshots;

                migResultStacking.x = migResult.x;
                migResultStacking.z = migResult.z;

                migResultStacking.migls = d2dlsm;
                migResultRadonStacking.migls = d2dlsm_radon;

                plotMigImages(app,app.selectedEvid,migResultStacking,migResultRadonStacking)
            end

        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            answer = questdlg('Do you want to quit?', '', 'YES', 'NO', 'NO');
            switch answer
                case 'YES'
                    delete(app.UIFigure); 
                case 'NO'
                    return; 
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainApp.migration2DStatus = 'OK';
            
            % write_MigResult(['./results', '/mig2D.mat'], app.img);
            % write_MigResult(['./results', '/mig2DRadon.mat'], app.imgRadon);

            delete(app.UIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilesMenu = uimenu(app.UIFigure);
            app.FilesMenu.Text = 'Files';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UIAxes_lsm = uiaxes(app.UIFigure);
            title(app.UIAxes_lsm, 'LSM image')
            xlabel(app.UIAxes_lsm, 'Distance (km)')
            ylabel(app.UIAxes_lsm, 'Depth (km)')
            zlabel(app.UIAxes_lsm, 'Z')
            app.UIAxes_lsm_radon = uiaxes(app.UIFigure);
            title(app.UIAxes_lsm_radon, 'LSM image')
            xlabel(app.UIAxes_lsm_radon, 'Distance (km)')
            ylabel(app.UIAxes_lsm_radon, 'Depth (km)')
            zlabel(app.UIAxes_lsm_radon, 'Z')
            app.Migration2DPanelLabel = uilabel(app.UIFigure);
            app.Migration2DPanelLabel.Text = '2D Migration Panel';
            app.SelectEventsDropDown = uidropdown(app.UIFigure);
            app.SelectEventsDropDown.Items = {'Select Events'};
            app.SelectEventsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEventsDropDownValueChanged, true);
            app.SelectEventsDropDown.Value = 'Select Events';
            app.CreateGridPanel = uipanel(app.UIFigure);
            app.CreateGridPanel.Title = 'Create Grid';
            app.CreateButton = uibutton(app.CreateGridPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.VelocityModelDropDownLabel = uilabel(app.CreateGridPanel);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model:';
            app.VelocityModelDropDown = uidropdown(app.CreateGridPanel);
            app.VelocityModelDropDown.Items = {'1D', '2D', 'Default (1D)'};
            app.VelocityModelDropDown.Value = 'Default (1D)';
            app.dzkmEditFieldLabel = uilabel(app.CreateGridPanel);
            app.dzkmEditFieldLabel.Text = 'dz (km):';
            app.dzkmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dzkmEditField.Value = 1;
            app.dxkmEditFieldLabel = uilabel(app.CreateGridPanel);
            app.dxkmEditFieldLabel.Text = 'dx (km):';
            app.dxkmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dxkmEditField.Value = 5;
            app.RadonTransformPanel = uipanel(app.UIFigure);
            app.RadonTransformPanel.Title = 'Radon Transform';
            app.RunButtonRT = uibutton(app.RadonTransformPanel, 'push');
            app.RunButtonRT.ButtonPushedFcn = createCallbackFcn(app, @RunButtonRTPushed, true);
            app.RunButtonRT.Text = 'Run';
            app.pminskmEditFieldLabel = uilabel(app.RadonTransformPanel);
            app.pminskmEditFieldLabel.Text = 'pmin (s/km):';
            app.pminskmEditField = uieditfield(app.RadonTransformPanel, 'numeric');
            app.pminskmEditField.Value = -0.03;
            app.pmaxskmEditFieldLabel = uilabel(app.RadonTransformPanel);
            app.pmaxskmEditFieldLabel.Text = 'pmax (s/km):';
            app.pmaxskmEditField = uieditfield(app.RadonTransformPanel, 'numeric');
            app.pmaxskmEditField.Value = 0.03;
            app.Niter1Label = uilabel(app.RadonTransformPanel);
            app.Niter1Label.Text = 'Niter1:';
            app.Niter1EditField = uieditfield(app.RadonTransformPanel, 'numeric');
            app.Niter1EditField.Value = 20;
            app.Niter2EditFieldLabel = uilabel(app.RadonTransformPanel);
            app.Niter2EditFieldLabel.Text = 'Niter2:';
            app.Niter2EditField = uieditfield(app.RadonTransformPanel, 'numeric');
            app.Niter2EditField.Value = 2;
            app.dpEditFieldLabel = uilabel(app.RadonTransformPanel);
            app.dpEditFieldLabel.Text = 'dp: ';
            app.dpEditField = uieditfield(app.RadonTransformPanel, 'numeric');
            app.dpEditField.Value = 0.001;
            app.LSMPanel = uipanel(app.UIFigure);
            app.LSMPanel.Title = 'LSM ';
            app.RunButtonLSM = uibutton(app.LSMPanel, 'push');
            app.RunButtonLSM.ButtonPushedFcn = createCallbackFcn(app, @RunButtonLSMPushed, true);
            app.RunButtonLSM.Text = 'Run';
            app.IterationEditFieldLabel = uilabel(app.LSMPanel);
            app.IterationEditFieldLabel.Text = 'Iteration:';
            app.IterationEditField = uieditfield(app.LSMPanel, 'numeric');
            app.IterationEditField.Value = 3;
            app.MuEditFieldLabel = uilabel(app.LSMPanel);
            app.MuEditFieldLabel.Text = 'Mu:';
            app.MuEditField = uieditfield(app.LSMPanel, 'numeric');
            app.MuEditField.Value = 0.1;
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.EventIDLabel = uilabel(app.UIFigure);
            app.EventIDLabel.Text = 'Event ID:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Migration2DApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end