classdef Migration3DApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImportMenu                  matlab.ui.container.Menu
        SyntheticDataMenu           matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        MigrationPanel              matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        LSMTopography               matlab.ui.control.UIAxes
        LSMImageMap                 matlab.ui.control.UIAxes
        EventIDLabel                matlab.ui.control.Label
        GridPanel                   matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        SSAParamTab                 matlab.ui.container.Tab
        NiterEditField              matlab.ui.control.NumericEditField
        NiterEditFieldLabel         matlab.ui.control.Label
        KdampingEditField           matlab.ui.control.NumericEditField
        KdampingLabel               matlab.ui.control.Label
        RankEditField               matlab.ui.control.NumericEditField
        RankEditFieldLabel          matlab.ui.control.Label
        Is_SSADropDown              matlab.ui.control.DropDown
        Is_SSALabel                 matlab.ui.control.Label
        LSMParamTab                 matlab.ui.container.Tab
        MuEditField                 matlab.ui.control.NumericEditField
        MuEditFieldLabel            matlab.ui.control.Label
        IterationEditField          matlab.ui.control.NumericEditField
        IterationEditField_2Label   matlab.ui.control.Label
        RunButtonLSM                matlab.ui.control.Button
        SelectEventsDropDown        matlab.ui.control.DropDown
        PlotparametersPanel         matlab.ui.container.Panel
        PickMohoButton              matlab.ui.control.Button
        CustomProfileButton         matlab.ui.control.Button
        SaveFigureButton            matlab.ui.control.Button
        PlotProfileButton           matlab.ui.control.Button
        Y2EditField                 matlab.ui.control.NumericEditField
        Y2EditFieldLabel            matlab.ui.control.Label
        X2EditField                 matlab.ui.control.NumericEditField
        X2EditFieldLabel            matlab.ui.control.Label
        Y1EditField                 matlab.ui.control.NumericEditField
        Y1EditFieldLabel            matlab.ui.control.Label
        X1EditField                 matlab.ui.control.NumericEditField
        X1EditFieldLabel            matlab.ui.control.Label
        CreateGridPanel             matlab.ui.container.Panel
        dxkmEditField               matlab.ui.control.NumericEditField
        dxLabel                     matlab.ui.control.Label
        dzkmEditField               matlab.ui.control.NumericEditField
        dzkmEditFieldLabel          matlab.ui.control.Label
        dykmEditField               matlab.ui.control.NumericEditField
        dykmEditFieldLabel          matlab.ui.control.Label
        VelocityModelDropDown       matlab.ui.control.DropDown
        VelocityModelDropDownLabel  matlab.ui.control.Label
        CreateButton                matlab.ui.control.Button
        QuitButton                  matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        Migration3DPanelLabel       matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    %% user define properties
    properties (Access = public)
        mainApp
        MigrationParam
        DataStruct
        gridStruct
        migResults
        
    end
    
    properties (Access = private)
        selectedEvid 
        cmap
        dem
        Fi_current
        currentProfilePoints
        currentProfileDistAll
        currentProfileDepthAll
        currentProfileVAll 

        currentStraightProfile_xi  
        currentStraightProfile_yi
        currentProfile_xp_all
        currentProfile_yp_all
        
    end
    %% user define methods
    methods (Access = public)
        function loadConfigData(app, defaultParam)
            app.MigrationParam = defaultParam;  

            if defaultParam.is_ssa == 1
                app.Is_SSADropDown.Value='YES';
            elseif defaultParam.is_ssa == 0
                app.Is_SSADropDown.Value='NO';
            end

            app.RankEditField.Value = defaultParam.rank_p;
            app.KdampingEditField.Value = defaultParam.K;  % damping factor (default: 4)
            app.NiterEditField.Value = defaultParam.n_iter; % 

            updateEventDropdown(app)
        end

    end

    methods (Access = private)
        function updateEventDropdown(app)

            if isempty(app.DataStruct)
                app.SelectEventsDropDown.Items = {'No events'};
                app.SelectEventsDropDown.Value = 'No events';
                return;
            end

            eList = getEvents(app.DataStruct);
            eIDs  = {eList.evid};
            items = [{'Select events','All'}, eIDs];

            app.SelectEventsDropDown.Items = items;
            app.SelectEventsDropDown.Value = items{1}; 
        end

        function plotGridInApp(app, gridStruct,isplotLine)

            delete(allchild(app.GridPanel));
            
            stationX = gridStruct.rx(:, 1);
            stationY = gridStruct.ry(:, 2);  
            
            XX = gridStruct.X;
            YY = gridStruct.Y;
            
            gax = axes('Parent', app.GridPanel, ...
                'Units', 'normalized', ...
                'Position', [0.05 0.12 0.95 0.80]);
            hold(gax, 'on');
            
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'LineWidth', 1.5, ...
                'MarkerEdgeColor', 'r', ...
                'DisplayName', 'Station');
            
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', 'Grid');
            
            xlabel(gax, 'X (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(gax, 'Y (km)', 'FontSize', 12, 'FontWeight', 'bold');
            title(gax, 'Station geometry', 'FontSize', 14, 'FontWeight', 'bold');

            box(gax,"on");
            grid(gax, 'on');
            grid(gax, 'minor');
            
            axis(gax, 'equal');

            xMargin = 0.1 * (max(XX(:)) - min(XX(:))); 
            yMargin = 0.1 * (max(YY(:)) - min(YY(:))); 
            xlim(gax, [min(XX(:)) - xMargin, max(XX(:)) + xMargin]);
            ylim(gax, [min(YY(:)) - yMargin, max(YY(:)) + yMargin]);
            
            set(gax, 'FontSize', 10);
            set(gax, 'Color', 'w');
            set(app.GridPanel, 'BackgroundColor', 'w');

            if isplotLine
                coord1 = [app.X1EditField.Value,app.Y1EditField.Value];
                coord2 = [app.X2EditField.Value,app.Y2EditField.Value];
    
                plot(gax,[coord1(1),coord2(1)],[coord1(2),coord2(2)],'b-o','LineWidth',2)
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r'); 
                % plot(gax,coord1, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
    
                text(gax,coord1(1),coord1(2),'A','FontSize',20,'HorizontalAlignment','right')
                text(gax,coord2(1),coord2(2),'B','FontSize',20,'HorizontalAlignment','left')

            end

            grid(gax, 'on');
            hold(gax, 'off');
        end
        
        
        function plotStationsProInApp(app,gridStruct)
            delete(allchild(app.GridPanel));
            
            stationX = gridStruct.rx(:, 1);
            stationY = gridStruct.ry(:, 2);     
            XX = gridStruct.X;
            YY = gridStruct.Y;

            gax = axes('Parent', app.GridPanel, ...
                'Units', 'normalized', ...
                'Position', [0.05 0.12 0.95 0.80]); 
            hold(gax, 'on');
            
        
            scatter(gax, stationX, stationY, 40, 'r^', 'filled', ...
                'LineWidth', 1.5, ...
                'MarkerEdgeColor', 'r', ...
                'DisplayName', 'Distribution of station');
           
            scatter(gax, XX, YY, 80, [0.5 0.5 0.5], '.', ...
                'filled', 'MarkerEdgeColor', 'k', ...
                'DisplayName', 'Grid');
            
            xlabel(gax, 'X (km)', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(gax, 'Y (km)', 'FontSize', 12, 'FontWeight', 'bold');
            title(gax, 'Geometry', 'FontSize', 14, 'FontWeight', 'bold');
            
            box(gax,"on");
            grid(gax, 'on');
            grid(gax, 'minor'); 
            
            axis(gax, 'equal');
            
            xMargin = 0.1 * (max(XX(:)) - min(XX(:))); 
            yMargin = 0.1 * (max(YY(:)) - min(YY(:))); 
            xlim(gax, [min(XX(:)) - xMargin, max(XX(:)) + xMargin]);
            ylim(gax, [min(YY(:)) - yMargin, max(YY(:)) + yMargin]);
            
            set(gax, 'FontSize', 10);
            set(gax, 'Color', 'w');
            set(app.GridPanel, 'BackgroundColor', 'w');

            plot(gax,app.currentProfilePoints(:,1),app.currentProfilePoints(:,2),'b-o','LineWidth',2)

            for i = 1:size(app.currentProfilePoints,1)
                text(gax,app.currentProfilePoints(i,1), app.currentProfilePoints(i,2), num2str(i-1), ...
                    'VerticalAlignment', 'bottom', ...
                    'HorizontalAlignment', 'right', ...
                    'FontSize', 10, ...
                    'Color', 'blue', ...
                    'FontWeight', 'bold');
            end

            grid(gax, 'on');
            hold(gax, 'off');

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Migration3D App';
            app.cmap = load(fullfile(pwd,'/visualization/colormap/roma.mat'));
            updateEventDropdown(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.mainApp.migration3DStatus = 'OK';
  
            % write_MigResult(['./results', '/mig3D.mat'], app.migResults);

            delete(app.UIFigure);
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.DataStruct)
                uialert(app.UIFigure, 'No data loaded!', 'Error','Icon','error');
                return;
            end
            tmp = app.DataStruct(1).StationInfo.sta;
            stnet = tmp(1:2);

            if strcmp(stnet,'BY') == 1
                app.dem = load('Baiyanebo_DEM.mat'); 
                xnpts = 5;ynpts = 5;
            else
                app.dem = load('dem_data.mat'); 
                xnpts = 10;ynpts = 5;
            end

            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','Meshgrid', ...
                    'Message','Generate grid and velocity model...', ...
                    'Cancelable','off', ...
                    'Value',0, ...
                    'Indeterminate','off');
            % create grid
            dx = app.dxkmEditField.Value;
            dy = app.dykmEditField.Value;
            dz = app.dzkmEditField.Value;
             % velocity model
            ModelType = app.VelocityModelDropDown.Value;
           
            app.gridStruct = createGrid(app.DataStruct, dx, dy, dz);
           
            if strcmp(ModelType,'Default')
                ModelType = '3D';
            end

            app.gridStruct = getVelocityModel(ModelType,app.gridStruct,xnpts,ynpts);

            plotGridInApp(app,app.gridStruct,0);

            close(msg);
        end

        % Value changed function: SelectEventsDropDown
        function SelectEventsDropDownValueChanged(app, event)

            selectedID = app.SelectEventsDropDown.Value;
            if strcmp(selectedID,'All')

                eList = getEvents(app.DataStruct);  
                app.selectedEvid = {eList.evid}; 
            else

                app.selectedEvid = {selectedID};
            end
        end

        % Button pushed function: RunButtonLSM
        function RunButtonLSMPushed(app, event)
            shots = app.selectedEvid;
            if isempty(shots)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end
            if ~isfield(app.gridStruct,'VP')     
                uialert(app.UIFigure, 'Create grid first!', 'Note');
                return;
            end
            msg = uiprogressdlg(app.UIFigure, ...
                    'Title','LSM', ...
                    'Message',['3D LSM begins...'], ...
                    'Cancelable','on', ...
                    'Value',0, ...
                    'Indeterminate','off');

            MigParam = app.MigrationParam;
            MigParam.paramMig = setMigParam3D(app.gridStruct);
            MigParam.paramMig.itermax = app.IterationEditField.Value;
            MigParam.paramMig.mu = app.MuEditField.Value;
            
            is_ssa = app.Is_SSADropDown.Value;
            if strcmp(is_ssa,'Default') | strcmp(is_ssa,'NO')
                is_ssa = 0;
            elseif strcmp(is_ssa,'YES')
                is_ssa = 1;
                RankReductionParam.nx = app.gridStruct.nx;
                RankReductionParam.ny = app.gridStruct.ny;
                RankReductionParam.ox = min(app.gridStruct.x);
                RankReductionParam.oy = min(app.gridStruct.y);
                RankReductionParam.mx = max(app.gridStruct.x);
                RankReductionParam.my = max(app.gridStruct.y);
                
                RankReductionParam.rank = app.RankEditField.Value;
                RankReductionParam.K = app.KdampingEditField.Value;
                RankReductionParam.niter = app.NiterEditField.Value;

            end
            MigParam.paramMig.isReconRFs = is_ssa;

            tic
            nshots = length(shots);
            dout = struct();
            for iEvent = 1:nshots
                
                msg.Value = iEvent / nshots;
                msg.Message = ['Processing ', num2str(iEvent), ' of ', num2str(nshots)];
                if msg.CancelRequested
                    return;
                end
                
                evid = shots{iEvent};
                
                gather = getCommonEventGather(app.DataStruct, evid);

                if length(gather) < 0.6 * length(app.gridStruct.stationLon)
                    % uialert(app.UIFigure,['Too few stations for eventID: ', evid], 'Warning');
                    disp(['Not enough stations available for eventID: ', evid])
                    continue;
                end

                if is_ssa
                    [gather, dout_recon_regular] = rankReduction_new(gather, app.gridStruct, RankReductionParam);
                    MigParam.paramMig.dotg = dout_recon_regular;
                end
                % Perform 3D least-squares migration
                % This method provides improved resolution compared to standard migration
                mig = leastSquaresMig3D(gather, app.gridStruct, MigParam);
                dout(iEvent).images = mig;

            end
            app.migResults = dout;
            close(msg)
            toc
            uialert(app.UIFigure, 'LSM done!', 'Success');
        end

        % Button pushed function: PlotProfileButton
        function PlotProfileButtonPushed(app, event)
            %% plot results
            plotGridInApp(app,app.gridStruct,1);
            %% 
            if isempty(app.migResults)
                uialert(app.UIFigure, 'no data!', 'Note');
                return;
            end
            
            x1 = app.X1EditField.Value;
            x2 = app.X2EditField.Value;
            y1 = app.Y1EditField.Value;
            y2 = app.Y2EditField.Value;

            dout = app.migResults;       % all events
            dout = dout(~arrayfun(@(x) isempty(x.images), dout));  % remove empty

            V1 = zeros(size(dout(1).images.mig));
            V2 = V1;
            for n=1:length(dout)
                V1 = V1 + dout(n).images.mig;   % migration
                V2 = V2 + dout(n).images.migls;  % LSM
            end
            V1 = V1/length(dout);
            V1 = permute(V1,[3,2,1]);

            V2 = V2/length(dout);
            V2 = permute(V2,[3,2,1]);


            X = dout(1).images.X;
            Y = dout(1).images.Y;
            Z = dout(1).images.Z;
            [~,~,nz] = size(X);

            % shotsID = app.selectedEvid;
            Fi = scatteredInterpolant(X(:),Y(:),Z(:),V2(:));

            ninterp = 100;
            xi = linspace(x1,x2,ninterp);
            yi = linspace(y1,y2,ninterp);
            depth = squeeze(Z(1,1,:));

            app.currentStraightProfile_xi = xi;  
            app.currentStraightProfile_yi = yi; 

            xdist = linspace(0,sqrt((x1-x2)^2 + (y1-y2)^2),ninterp);

            xpro = repmat(xi,nz,1);
            ypro = repmat(yi,nz,1);
            zpro = repmat(depth,1,ninterp);

            Vpro = Fi(xpro,ypro,zpro);

            app.currentProfileDistAll = xdist; 
            app.currentProfileDepthAll = depth; 
            app.currentProfileVAll = Vpro;

            %% topography
            [lonp, latp] = ProjectedCoordsTolatlon(xi', yi', app.gridStruct); 
            elevp = interp2(app.dem.demLon, app.dem.demLat, double(app.dem.demZ), lonp, latp) / 1000.;
        
            %
            cla(app.LSMTopography,"reset"); 
            plot(app.LSMTopography, xdist, elevp, 'k', 'linewidth', 2);hold("on")
            patch(app.LSMTopography, [xdist(1); xdist'; xdist(end)], [0; elevp; 0], [0.8 0.8 0.8]);
            
            ylabel(app.LSMTopography, 'Elev. (km)');
            xlim(app.LSMTopography, [0, max(xdist)]);
            min_elev = (min(elevp)*0.9); 
            max_elev = (max(elevp)*1.1);
            ylim(app.LSMTopography, [min_elev, max_elev]);
            app.LSMTopography.XTickLabel = []; 

            % image
            cla(app.LSMImageMap,"reset");  
            % axes(app.UIAxes_lsm);
            % hold(app.UIAxes_lsm, 'on'); 
            imagesc(app.LSMImageMap, xdist, depth, Vpro); 
            
            axis(app.LSMImageMap, 'ij');
            title(app.LSMImageMap,'LSM image')
            xlabel(app.LSMImageMap, 'Distance (km)');
            ylabel(app.LSMImageMap, 'Depth (km)');
            xlim(app.LSMImageMap, [0, max(xdist)]);
            ylim(app.LSMImageMap, [0, 100]);
            text(app.LSMImageMap,0,-5,'A','FontSize',14)
            text(app.LSMImageMap,max(xdist)-5,-5,'B','FontSize',14)
                        
            cmax = rms(Vpro(:)); 
            clim(app.LSMImageMap, [-cmax, cmax]);
            % caxis(app.LSMImageMap, [-1 1]);
%             colormap(app.LSMImageMap, 'jet'); 
            colormap(app.LSMImageMap,flipud(app.cmap.roma));
            % colorbar(app.LSMImageMap)

        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            answer = questdlg('Do you want to quit?', '', 'YES', 'NO', 'NO');
            switch answer
                case 'YES'
                    delete(app.UIFigure); 
                case 'NO'
                    return; 
            end
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            % save the figure
            if isempty(app.migResults)
                uialert(app.UIFigure, 'No results', 'Error', 'Icon','error');
                return;
            end
            % export_fig('./results/',app.UIAxes_lsm)
            uialert(app.UIFigure,'Saved the results','Sucess');
           
        end

        % Button pushed function: CustomProfileButton
        function CustomProfileButtonPushed(app, event)
            dout = app.migResults; 
            dout = dout(~arrayfun(@(x) isempty(x.images), dout));
           
            X = dout(1).images.X;
            Y = dout(1).images.Y;
            Z = dout(1).images.Z;

            %% stacking
            V1 = zeros(size(dout(1).images.mig));
            V2 = V1;
            for n=1:length(dout)
                V1 = V1 + dout(n).images.mig;
                V2 = V2 + dout(n).images.migls;
            end
            % V1 = V1/length(dout);
            % V1 = permute(V1,[3,2,1]);

            V2 = V2/length(dout);    % lsm
            V2 = permute(V2,[3,2,1]);

            % smoothing
            dimg_smooth = [];
            smoothLength = 1;
            if rem(smoothLength,2)==0
                smoothLength = smoothLength +1;
            end
            V_smooth = smooth3(V2,'box',smoothLength);

            F = scatteredInterpolant(X(:),Y(:),Z(:),V_smooth(:));
            app.Fi_current = F;

            depth0 = 0:app.dzkmEditField.Value:max(Z(:));  % depth axis

            f_ginput = figure; 
            set(f_ginput,'Position',[50 50 800 800],'Color','w')
            h = slice(X,Y,Z,V_smooth,[],[],40);
            xlabel('X (km)');ylabel('Y (km)');zlabel('Z (km)');
            set(h(:),'EdgeColor','none');set(gca,'ZDir','reverse');colormap(flipud(app.cmap.roma));
            cmax = rms(V_smooth(:));
            clim([-cmax cmax]);view(0,90);
            axis equal;
            yrange = Y(:,1,1);
            xrange = squeeze(X(1,:,1));
            ylim([min(yrange)*0.8 max(yrange)*1.2]);
            xlim([min(xrange)*0.8 max(xrange)*1.2]);
            
            hold on
            scatter(app.gridStruct.rx(:, 1),app.gridStruct.ry(:, 2), 40, 'r^', 'filled')
            hold off
            title('Please click on the map to select profile points. Double-click or press Enter to finish.');

            [xpoints,ypoints] = ginput;
            close(f_ginput); 
        
            if isempty(xpoints)
                uialert(app.UIFigure, 'None points selected.', 'Warning', 'Icon', 'warning');
                app.StatusEditField.Value = 'Exit Profile Selection';
                return;
            end
        
            app.currentProfilePoints = [xpoints, ypoints];

            profileAll={};
            for n = 1:length(xpoints)-1
                profile=[xpoints(n),ypoints(n);xpoints(n+1),ypoints(n+1)];
                profileAll{n} = profile;
            end

            plotStationsProInApp(app,app.gridStruct);

            VAll=[];
            distAll = [];
            depthAll = [];
            totalDist = 0;
            elevAll = [];
            app.currentProfile_xp_all = []; 
            app.currentProfile_yp_all = [];

            for n = 1:length(profileAll)
                x1 = profileAll{n}(1,1);
                y1 = profileAll{n}(1,2);
                x2 = profileAll{n}(2,1);
                y2 = profileAll{n}(2,2);
                
                l = (y2-y1)/(x2-x1);

                npoints = 100;
                xp = linspace(x1,x2,npoints)';
                yp = y1+(xp-x1)*l;
                dist0 = sqrt((xp-x1).^2 + (yp-y1).^2);

                Y_profile = repmat(yp',length(depth0),1);
                X_profile = repmat(xp',length(depth0),1);
                Z_profile = repmat(depth0',1,length(xp));
                DIST_profile = repmat(dist0',length(depth0),1);
                Vprofile = F(X_profile,Y_profile,Z_profile);
                Vprofile(isnan(Vprofile)) = NaN;
        
                if n > 1
                    totalDist = max(distAll(:));
                end
                distAll = [distAll, DIST_profile+totalDist];
                depthAll = [depthAll,Z_profile];
                VAll = [VAll, Vprofile];

                [lonp, latp] = ProjectedCoordsTolatlon(xp, yp, app.gridStruct);
                elevp = interp2(app.dem.demLon,app.dem.demLat,double(app.dem.demZ),lonp,latp)/1000.;
                elevAll = [elevAll; elevp];

                app.currentProfile_xp_all = [app.currentProfile_xp_all; xp];
                app.currentProfile_yp_all = [app.currentProfile_yp_all; yp];
                
            end
            distElev = distAll(1,:)';

            app.currentProfileDistAll = distAll;
            app.currentProfileDepthAll = depthAll;
            app.currentProfileVAll = VAll;  

            % plot
            cla(app.LSMTopography,"reset");

            plot(app.LSMTopography,distElev,elevAll,'k','linewidth',2); hold("on")
            patch(app.LSMTopography,[distElev(1);distElev;distElev(end)],[0;elevAll;0],[0.8 0.8 0.8]);
            ylabel(app.LSMTopography,'Elev. (km)');
            min_elev = (min(elevp)*0.9); 
            max_elev = (max(elevp)*1.1);
            xlim(app.LSMTopography, [0 max(distAll(1,:))]);
            ylim(app.LSMTopography, [min_elev, max_elev]);box(app.LSMTopography,"on")
            
            app.LSMTopography.XTickLabel = [];
            set(app.LSMTopography,'fontsize',16,'linewidth',1.5,'Position',[15 225 615 80])

            % migration results
            cla(app.LSMImageMap,"reset");  
            h=pcolor(app.LSMImageMap,distAll,depthAll,VAll);

            xlim(app.LSMImageMap, [0 max(distAll(1,:))]);
            ylim(app.LSMImageMap, [0 100]);
            colormap(app.LSMImageMap,flipud(app.cmap.roma));
            cmax = rms(VAll(:));
            clim(app.LSMImageMap, [-cmax, cmax]);
            set(h,'EdgeColor','none');
            axis(app.LSMImageMap,'ij');

            hold(app.LSMImageMap,"on");
            xpts = [distElev(1);distElev((1:length(profileAll))*npoints)];
            scatter(app.LSMImageMap,xpts,zeros(1,1+length(profileAll)),'v','blue','filled')
            
            pos_topo = get(app.LSMTopography, 'Position');
            pos_image = get(app.LSMImageMap, 'Position');
            pos_image(1) = pos_topo(1)-15; 
            pos_image(3) = pos_topo(3)+15; 

            xlabel(app.LSMImageMap,'Distance (km)');
            ylabel(app.LSMImageMap,'Depth (km)');
            set(app.LSMImageMap, 'Position', pos_image,'fontsize',16,'linewidth',1.5,'XMinorTick','on','YMinorTick','on');

            uialert(app.UIFigure, 'completed successfully', 'Success');

        end

        % Button pushed function: PickMohoButton
        function PickMohoButtonPushed(app, event)
            if ~isempty(app.currentProfile_xp_all)
                app.currentStraightProfile_xi = [];
            end
            if isempty(app.migResults) || isempty(app.currentProfileDistAll)
                uialert(app.UIFigure, 'Please run the Migration calculation first', 'Error');
                return;
            end

            h_pick_fig = figure('Name', 'Manually pick the Moho', 'NumberTitle','off', ...
                'Position', [100 100 800 600], 'Color', 'w');
            ax = axes('Parent', h_pick_fig, 'Position', [0.1 0.1 0.8 0.8]);

            dist_vec = app.currentProfileDistAll(1,:);
            depth_vec = app.currentProfileDepthAll(:,1);
            imagesc(ax, dist_vec, depth_vec, app.currentProfileVAll);
            colormap(ax, flipud(app.cmap.roma));
            axis(ax, 'ij');
            title(ax, 'Click to select Moho points. Press Enter to finish.');
            xlabel(ax, 'Distance (km)');
            ylabel(ax, 'Depth (km)');
            clim(ax, [-0.01, 0.01]); 

            [x_dists, depths] = ginput();
            close(h_pick_fig);

            if isempty(x_dists)
                uialert(app.UIFigure, 'No points selected.', 'Note');
                return;
            end

            results = struct(...
                'ProfileDistance', num2cell(x_dists), ...
                'ClickedDepth', num2cell(depths), ...
                'OriginalX', [], ...
                'OriginalY', [], ...
                'MohoDepth', [], ...
                'Longitude', [], ...
                'Latitude', []);

            hold(app.LSMImageMap, 'on');

            h_click = plot(app.LSMImageMap, NaN, NaN, 'go', 'MarkerSize', 10, 'DisplayName', 'Select Depth');
            h_moho = plot(app.LSMImageMap, NaN, NaN, 'r*', 'MarkerSize', 15, 'DisplayName', 'Moho Depth');

            for i = 1:length(x_dists)
                current_dist = x_dists(i);
                clicked_depth = depths(i);

                [~, dist_idx] = min(abs(dist_vec - current_dist));

                search_min = max(1, find(depth_vec >= (clicked_depth - 5), 1));
                search_max = min(length(depth_vec), find(depth_vec <= (clicked_depth + 5), 1, 'last'));

                search_depths = depth_vec(search_min:search_max);
                search_values = app.currentProfileVAll(search_min:search_max, dist_idx);

                [~, max_idx] = max(search_values);
                moho_depth = search_depths(max_idx);

                if ~isempty(app.currentStraightProfile_xi)
                    [~, idx] = min(abs(dist_vec - current_dist));
                    orig_X = app.currentStraightProfile_xi(idx);
                    orig_Y = app.currentStraightProfile_yi(idx);
                elseif ~isempty(app.currentProfile_xp_all)
                    xp_all = app.currentProfile_xp_all;
                    yp_all = app.currentProfile_yp_all;

                    segments = sqrt(diff(xp_all).^2 + diff(yp_all).^2);
                    cum_dist = [0; cumsum(segments)];

                    [~, idx] = min(abs(cum_dist - current_dist));
                    orig_X = xp_all(idx);
                    orig_Y = yp_all(idx);
                else
                    uialert(app.UIFigure, 'Unable to determine coordinate mapping.', 'Error');
                    return;
                end

                [lon, lat] = ProjectedCoordsTolatlon(orig_X, orig_Y, app.gridStruct);

                results(i).OriginalX = orig_X;
                results(i).OriginalY = orig_Y;
                results(i).MohoDepth = moho_depth;
                results(i).Longitude = lon;
                results(i).Latitude = lat;

                plot(app.LSMImageMap, current_dist, clicked_depth, 'go', 'MarkerSize', 10);
                plot(app.LSMImageMap, current_dist, moho_depth, 'r*', 'MarkerSize', 15);

                text(app.LSMImageMap, current_dist+5, moho_depth, ...
                    sprintf('%.1f km', moho_depth), ...
                    'Color', 'r', 'FontSize', 10);
            end
            hold(app.LSMImageMap, 'off');
            legend(app.LSMImageMap, [h_click, h_moho], 'Location', 'best');

            choice = questdlg('Do you want to save results?', ...
                  'Save Confirmation', ...
                  'Save', 'Don''t Save', 'Cancel', 'Cancel');

            switch choice
                case 'Save'
                    [filename, pathname] = uiputfile('*.txt', 'Export Moho depth');
                    if filename == 0
                        uialert(app.UIFigure, sprintf('Save cancelled.'), 'Error');
                        return;
                    end

                    fullpath = fullfile(pathname, filename);
                    fid = fopen(fullpath, 'w');

                    fprintf(fid, '%-15s %-15s %-15s %-15s %-15s %-15s %-15s\n', ...
                        'ProfileDist(km)', 'ClickedDepth(km)', 'OriginalX(km)', 'OriginalY(km)', ...
                        'MohoDepth(km)', 'Longitude(deg)', 'Latitude(deg)');

                    for i = 1:length(results)
                        fprintf(fid, '%-15.3f %-15.3f %-15.3f %-15.3f %-15.3f %-15.6f %-15.6f\n', ...
                            results(i).ProfileDistance, ...
                            results(i).ClickedDepth, ...
                            results(i).OriginalX, ...
                            results(i).OriginalY, ...
                            results(i).MohoDepth, ...
                            results(i).Longitude, ...
                            results(i).Latitude);
                    end
                    fclose(fid);
                    uialert(app.UIFigure, sprintf('%d Moho picks exported to:\n%s', length(results), fullpath), 'Success');

                case 'Cancel'           
                    return;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportMenu = uimenu(app.UIFigure);
            app.ImportMenu.Text = 'Import';
            app.SyntheticDataMenu = uimenu(app.ImportMenu);
            app.SyntheticDataMenu.Text = 'Synthetic Data';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Migration3DPanelLabel = uilabel(app.UIFigure);
            app.Migration3DPanelLabel.Text = '3D Migration Panel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.CreateGridPanel = uipanel(app.UIFigure);
            app.CreateGridPanel.Title = 'Create Grid';
            app.CreateButton = uibutton(app.CreateGridPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.VelocityModelDropDownLabel = uilabel(app.CreateGridPanel);
            app.VelocityModelDropDownLabel.Text = 'Velocity Model:';
            app.VelocityModelDropDown = uidropdown(app.CreateGridPanel);
            app.VelocityModelDropDown.Items = {'1D', '2D', '3D', 'Default'};
            app.VelocityModelDropDown.Value = 'Default';
            app.dykmEditFieldLabel = uilabel(app.CreateGridPanel);
            app.dykmEditFieldLabel.Text = 'dy (km):';
            app.dykmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dykmEditField.Value = 5;
            app.dzkmEditFieldLabel = uilabel(app.CreateGridPanel);
            app.dzkmEditFieldLabel.Text = 'dz (km):';
            app.dzkmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dzkmEditField.Value = 1;
            app.dxLabel = uilabel(app.CreateGridPanel);
            app.dxLabel.Text = 'dx (km):';
            app.dxkmEditField = uieditfield(app.CreateGridPanel, 'numeric');
            app.dxkmEditField.Value = 5;
            app.PlotparametersPanel = uipanel(app.UIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters:';
            app.X1EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.X2EditField.Value = 200;
            app.Y2EditFieldLabel = uilabel(app.PlotparametersPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.Y2EditField.Value = 130;
            app.PlotProfileButton = uibutton(app.PlotparametersPanel, 'push');
            app.PlotProfileButton.ButtonPushedFcn = createCallbackFcn(app, @PlotProfileButtonPushed, true);
            app.PlotProfileButton.Text = 'Plot Profile';
            app.SaveFigureButton = uibutton(app.PlotparametersPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.CustomProfileButton = uibutton(app.PlotparametersPanel, 'push');
            app.CustomProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CustomProfileButtonPushed, true);
            app.CustomProfileButton.Text = 'Custom Profile';
            app.PickMohoButton = uibutton(app.PlotparametersPanel, 'push');
            app.PickMohoButton.ButtonPushedFcn = createCallbackFcn(app, @PickMohoButtonPushed, true);
            app.PickMohoButton.Text = 'Pick Moho';
            app.SelectEventsDropDown = uidropdown(app.UIFigure);
            app.SelectEventsDropDown.Items = {'Select Events'};
            app.SelectEventsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEventsDropDownValueChanged, true);
            app.SelectEventsDropDown.Value = 'Select Events';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SSAParamTab = uitab(app.TabGroup);
            app.SSAParamTab.Title = 'SSA Param';
            app.Is_SSALabel = uilabel(app.SSAParamTab);
            app.Is_SSALabel.Text = 'Is_SSA:';
            app.Is_SSADropDown = uidropdown(app.SSAParamTab);
            app.Is_SSADropDown.Items = {'Default', 'YES', 'NO'};
            app.Is_SSADropDown.Value = 'Default';
            app.RankEditFieldLabel = uilabel(app.SSAParamTab);
            app.RankEditFieldLabel.Text = 'Rank:';
            app.RankEditField = uieditfield(app.SSAParamTab, 'numeric');
            app.RankEditField.Value = 8;
            app.KdampingLabel = uilabel(app.SSAParamTab);
            app.KdampingLabel.Text = 'K(damping):';
            app.KdampingEditField = uieditfield(app.SSAParamTab, 'numeric');
            app.KdampingEditField.Value = 4;
            app.NiterEditFieldLabel = uilabel(app.SSAParamTab);
            app.NiterEditFieldLabel.Text = 'Niter:';
            app.NiterEditField = uieditfield(app.SSAParamTab, 'numeric');
            app.NiterEditField.Value = 10;
            app.LSMParamTab = uitab(app.TabGroup);
            app.LSMParamTab.Title = 'LSM Param';
            app.RunButtonLSM = uibutton(app.LSMParamTab, 'push');
            app.RunButtonLSM.ButtonPushedFcn = createCallbackFcn(app, @RunButtonLSMPushed, true);
            app.RunButtonLSM.Text = 'Run';
            app.IterationEditField_2Label = uilabel(app.LSMParamTab);
            app.IterationEditField_2Label.Text = 'Iteration:';
            app.IterationEditField = uieditfield(app.LSMParamTab, 'numeric');
            app.IterationEditField.Value = 20;
            app.MuEditFieldLabel = uilabel(app.LSMParamTab);
            app.MuEditFieldLabel.Text = 'Mu:';
            app.MuEditField = uieditfield(app.LSMParamTab, 'numeric');
            app.MuEditField.Value = 0.1;
            app.GridPanel = uipanel(app.UIFigure);
            app.GridPanel.Title = 'Grid Panel';
            app.EventIDLabel = uilabel(app.UIFigure);
            app.EventIDLabel.Text = 'Event ID:';
            app.MigrationPanel = uipanel(app.UIFigure);
            app.MigrationPanel.Title = 'Migration Panel';
            app.GridLayout = uigridlayout(app.MigrationPanel);
            app.LSMImageMap = uiaxes(app.GridLayout);
            title(app.LSMImageMap, 'LSM image')
            xlabel(app.LSMImageMap, 'Distance (km)')
            ylabel(app.LSMImageMap, 'Depth (km)')
            zlabel(app.LSMImageMap, 'Z')
            app.LSMTopography = uiaxes(app.GridLayout);
            title(app.LSMTopography, 'Topography')
            ylabel(app.LSMTopography, 'Y')
            zlabel(app.LSMTopography, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Migration3DApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end