classdef RadonTransformApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RadonParamPanel        matlab.ui.container.Panel
        TypeDropDown           matlab.ui.control.DropDown
        TypeLabel              matlab.ui.control.Label
        EventIDLabel           matlab.ui.control.Label
        dpEditField            matlab.ui.control.NumericEditField
        dpEditFieldLabel       matlab.ui.control.Label
        CancelButton           matlab.ui.control.Button
        RunButton              matlab.ui.control.Button
        SelectEventsDropDown   matlab.ui.control.DropDown
        N1CGEditField          matlab.ui.control.NumericEditField
        N1Label                matlab.ui.control.Label
        N2EditField            matlab.ui.control.NumericEditField
        N2EditFieldLabel       matlab.ui.control.Label
        PmaxskmEditField       matlab.ui.control.NumericEditField
        PmaxskmLabel           matlab.ui.control.Label
        PminskmEditField       matlab.ui.control.NumericEditField
        PminskmEditFieldLabel  matlab.ui.control.Label
        RadonTransformPanel    matlab.ui.control.Label
        UIAxesRF2              matlab.ui.control.UIAxes
        UIAxesZcomp2           matlab.ui.control.UIAxes
        UIAxesRcomp2           matlab.ui.control.UIAxes
        UIAxesRF1              matlab.ui.control.UIAxes
        UIAxesZcomp1           matlab.ui.control.UIAxes
        UIAxesRcomp1           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mainApp
        DataStruct
        
    end
    
    properties (Access = private)
        RadonParam
        deconvParam
        selectedEvid
        DataStructRadon
        scale = 2.5; % 1
    end
    
    methods (Access = public)
        
        function loadConfigData(app, defaultParam,deconvParam)
            % load default parameters
            app.RadonParam = defaultParam;
            app.deconvParam = deconvParam;

            app.PmaxskmEditField.Value = defaultParam.pmax;
            app.PminskmEditField.Value = defaultParam.pmin;
            app.N1CGEditField.Value = defaultParam.N1;
            app.N2EditField.Value = defaultParam.N2;
            app.dpEditField.Value = 0.001;

            updateEventDropdown(app)
        end
        
    end
    
    methods (Access = private)

        function plotWaveformsRaw(app,selectedEvid)
            nshots = length(selectedEvid);
            if isempty(selectedEvid)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end
            if nshots > 1
                uialert(app.UIFigure, 'Only plot the last earthquake!', 'Note');   
            end

            evid = selectedEvid{end};
            gather = getCommonEventGather(app.DataStruct, evid);

            isMatch = arrayfun(@(x) strcmp(x.EventInfo.evid, evid), gather);
            foundIndices = find(isMatch);

            if isempty(foundIndices)
                warning('No data found for eventID = %s', EventID);
                return;
            end

            rfmatrix = [];
            distArr = [];
            for idx = foundIndices
                Rcomp(:,idx) = gather(idx).Waveforms.dataProcessed(:,2); %  R
                Zcomp(:,idx) = gather(idx).Waveforms.dataProcessed(:,3); %  Z
                irf = gather(idx).RF.itr;
                if isnan(sum(irf))
                    irf = zeros(size(irf));     
                end
                rfmatrix(:, end+1) = irf;
                distArr(1,end+1) = gather(idx).TravelInfo.distDeg;

            end
            [nt, nx] = size(rfmatrix);
            [nt, ~] = size(Rcomp);
            dtwave = gather(1).TimeAxis.dt_resample;
            twave = (0:nt-1)*dtwave;
            trf = gather(foundIndices(1)).RF.ittime;

            %% plot
            Rcomp = Rcomp./max(Rcomp(:));
            Zcomp = Zcomp./max(Zcomp(:));
            rfmatrix = rfmatrix./max(rfmatrix(:));

            cla(app.UIAxesRcomp1,"reset");
            cla(app.UIAxesZcomp1,"reset");
            cla(app.UIAxesRF1,"reset");
            
            dx1 = abs(distArr(2:nx)-distArr(1:nx-1));
            dx = median(dx1);
            
            Rcomp = Rcomp*dx/mean(max(abs(Rcomp)));
            Zcomp = Zcomp*dx/mean(max(abs(Zcomp)));
            rfmatrix = rfmatrix*dx/mean(max(abs(rfmatrix)));

            if nx > 150
                step = fix((nx - 1)/(150 - 1));
            else
                step = 1;
            end

            for itrace = 1:step:nx
                plot(app.UIAxesRcomp1,app.scale * Rcomp(:,itrace) + distArr(itrace),twave,"Color",[0.5 0.5 0.5])
                hold(app.UIAxesRcomp1,"on");

                plot(app.UIAxesZcomp1,app.scale * Zcomp(:,itrace) + distArr(itrace),twave,"Color",[0.5 0.5 0.5])
                hold(app.UIAxesZcomp1,"on");

                y_data = app.scale * rfmatrix(1:round(30/dtwave), itrace) + distArr(itrace);
                x_data = trf(1:round(30/dtwave));

                threshold = distArr(itrace);
                above_threshold = y_data > threshold;
                area_fill = y_data;
                area_fill(~above_threshold) = threshold;
                fill(app.UIAxesRF1,[threshold; area_fill; threshold], [x_data(1); x_data; x_data(end)], 'r', 'EdgeColor', 'none');

                hold(app.UIAxesRF1, 'on');
                plot(app.UIAxesRF1, y_data, x_data, 'LineWidth', 1,'Color',[0.5 0.5 0.5]);
                
                % patch(app.UIAxesRF1,scale * rfmatrix(1:round(30/dtwave),itrace) + distArr(itrace),trf(1:round(30/dtwave)),[0.5 0.5 0.5])   
                % patch(app.UIAxesRF1,scale * rfmatrix(1:round(30/dtwave),itrace) + foundIndices(itrace),trf(1:round(30/dtwave)),[0.5 0.5 0.5])    
                hold(app.UIAxesRF1,"on");

            end
            set(app.UIAxesRcomp1,'YDir', 'reverse')
            xlabel(app.UIAxesRcomp1, 'Distance (deg)');
            xlim(app.UIAxesRcomp1,[min(distArr)-0.1 max(distArr)+0.1])
            xlabel(app.UIAxesRcomp1,'Distance (deg)')
            ylabel(app.UIAxesRcomp1,'Time (s)')
            hold(app.UIAxesRcomp1,"off");
            title(app.UIAxesRcomp1,'R component')

            set(app.UIAxesZcomp1,'YDir', 'reverse')
            xlabel(app.UIAxesZcomp1, 'Distance (deg)');
            xlim(app.UIAxesZcomp1,[min(distArr)-0.1 max(distArr)+0.1])
            xlabel(app.UIAxesZcomp1,'Distance (deg)')
            ylabel(app.UIAxesZcomp1,'Time (s)')
            hold(app.UIAxesZcomp1,"off");
            title(app.UIAxesZcomp1,'Z component')

            set(app.UIAxesRF1,'YDir', 'reverse')
            xlabel(app.UIAxesRF1, 'Distance (deg)');
            xlim(app.UIAxesRF1,[min(distArr)-0.1 max(distArr)+0.1])
            xlabel(app.UIAxesRF1,'Distance (deg)')
            ylabel(app.UIAxesRF1,'Time (s)')
            hold(app.UIAxesRF1,"off");
            title(app.UIAxesRF1,'RF')

        end
        
        function updateEventDropdown(app)
            if isempty(app.DataStruct)
                app.SelectEventsDropDown.Items = {'No events'};
                app.SelectEventsDropDown.Value = 'No events';
                return;
            end

            eList = getEvents(app.DataStruct);
            eIDs  = {eList.evid};
            items = [{'None','All'}, eIDs];

            app.SelectEventsDropDown.Items = items;
            app.SelectEventsDropDown.Value = 'None'; 
            
        end
        
        function plotWaveformsRadon(app,selectedEvid,Rcomp,Zcomp,itr_rt,distArr)
            nshots = length(selectedEvid);
            if isempty(selectedEvid)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end
            if nshots > 1
                uialert(app.UIFigure, 'Only plot the last earthquake!', 'Note');   
            end

            evid = selectedEvid{end};
            gather = getCommonEventGather(app.DataStruct, evid);
            trf = gather(1).RF.ittime;
            % 
            [nt,nx] = size(Rcomp);
            %% plot
            Rcomp = Rcomp./max(Rcomp(:));
            Zcomp = Zcomp./max(Zcomp(:));
            rfmatrix = itr_rt./max(itr_rt(:));
            dtwave = gather(1).TimeAxis.dt_resample;
            twave = (0:nt-1)*dtwave;

            cla(app.UIAxesRcomp2,"reset");
            cla(app.UIAxesZcomp2,"reset");
            cla(app.UIAxesRF2,"reset");
            
            dx1 = abs(distArr(2:nx)-distArr(1:nx-1));
            dx = median(dx1);
            
            Rcomp = Rcomp*dx/mean(max(abs(Rcomp)));
            Zcomp = Zcomp*dx/mean(max(abs(Zcomp)));
            rfmatrix = rfmatrix*dx/mean(max(abs(rfmatrix)));

            if nx > 150
                step = fix((nx - 1)/(150 - 1));
            else
                step = 1;
            end
            for itrace = 1:step:nx
                plot(app.UIAxesRcomp2,app.scale * Rcomp(:,itrace) + distArr(itrace),twave,"Color",[0.5 0.5 0.5])
                hold(app.UIAxesRcomp2,"on");

                plot(app.UIAxesZcomp2,app.scale * Zcomp(:,itrace) + distArr(itrace),twave,"Color",[0.5 0.5 0.5])
                hold(app.UIAxesZcomp2,"on");

                y_data = app.scale * rfmatrix(1:round(30/dtwave), itrace) + distArr(itrace);
                x_data = trf(1:round(30/dtwave));
                
                threshold = distArr(itrace);
                above_threshold = y_data > threshold;
                area_fill = y_data;
                area_fill(~above_threshold) = threshold;
                fill(app.UIAxesRF2,[threshold; area_fill; threshold], [x_data(1); x_data; x_data(end)], 'r', 'EdgeColor', 'none');

                hold(app.UIAxesRF2, 'on');
                plot(app.UIAxesRF2, y_data, x_data, 'LineWidth', 1,'Color',[0.5 0.5 0.5]);
                hold(app.UIAxesRF2, 'off');

                % patch(app.UIAxesRF2,scale * rfmatrix(1:round(30/dtwave),itrace) + distArr(itrace),trf(1:round(30/dtwave)),[0.5 0.5 0.5])   
                % patch(app.UIAxesRF2,scale * rfmatrix(1:round(30/dtwave),itrace) + foundIndices(itrace),trf(1:round(30/dtwave)),[0.5 0.5 0.5])    
                hold(app.UIAxesRF2,"on");

            end
            set(app.UIAxesRcomp2,'YDir', 'reverse')
            xlabel(app.UIAxesRcomp2, 'Distance (deg)');
            xlim(app.UIAxesRcomp2,[min(distArr)-0.1 max(distArr)+0.1])
            xlabel(app.UIAxesRcomp2,'Distance (deg)')
            ylabel(app.UIAxesRcomp2,'Time (s)')
            hold(app.UIAxesRcomp2,"off");
            title(app.UIAxesRcomp2,'R component')

            set(app.UIAxesZcomp2,'YDir', 'reverse')
            xlabel(app.UIAxesZcomp2, 'Distance (deg)');
            xlim(app.UIAxesZcomp2,[min(distArr)-0.1 max(distArr)+0.1])
            xlabel(app.UIAxesZcomp2,'Distance (deg)')
            ylabel(app.UIAxesZcomp2,'Time (s)')
            hold(app.UIAxesZcomp2,"off");
            title(app.UIAxesZcomp2,'Z component')

            set(app.UIAxesRF2,'YDir', 'reverse')
            xlabel(app.UIAxesRF2, 'Distance (deg)');
            xlim(app.UIAxesRF2,[min(distArr)-0.1 max(distArr)+0.1])
            xlabel(app.UIAxesRF2,'Distance (deg)')
            ylabel(app.UIAxesRF2,'Time (s)')
            hold(app.UIAxesRF2,"off");
            title(app.UIAxesRF2,'RF')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'RadonTransform App';
            updateEventDropdown(app)
        end

        % Value changed function: SelectEventsDropDown
        function SelectEventsDropDownValueChanged(app, event)
            
            selectedID = app.SelectEventsDropDown.Value;
            if strcmp(selectedID,'All')
                eList = getEvents(app.DataStruct);  
                app.selectedEvid = {eList.evid}; 
            else
                app.selectedEvid = {selectedID};
            end

            plotWaveformsRaw(app,app.selectedEvid)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            shorts = app.selectedEvid;
            nshots = length(shorts);
            app.DataStructRadon = [];
            if isempty(shorts)
                uialert(app.UIFigure, 'Select at least one earthquake!', 'Note');
                return;
            end

            % param
            pmax = app.PmaxskmEditField.Value;
            pmin = app.PminskmEditField.Value;
            dp = app.dpEditField.Value;
            p = pmin:dp:pmax;
            np = length(p);

            N1 = app.N1CGEditField.Value;
            N2 = app.N2EditField.Value;
            type = app.TypeDropDown.Value;

            msg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Radon Transform', ...
                'Message', 'Starting...', ...
                'Cancelable', 'on', ...
                'Value', 0);
            for iEvent = 1:nshots
                msg.Value = iEvent / nshots;
                msg.Message = ['Processing ', num2str(iEvent), ' of ', num2str(nshots)];
                if msg.CancelRequested
                    return;
                end
                
                evid = shorts{iEvent};
                gather = getCommonEventGather(app.DataStruct, evid);
                gatherRadon = gather;

                isMatch = arrayfun(@(x) strcmp(x.EventInfo.evid, evid), gather);
                foundIndices = find(isMatch);

                if isempty(foundIndices)
                    warning('No data found for eventID = %s', EventID);
                    return;
                end

                nt = size(gather(1).Waveforms.dataProcessed(:,1),1);
                nx = length(foundIndices);
                Rcomp = zeros(nt,nx);
                Zcomp = zeros(nt,nx);
                distArr = [];

                for idx = foundIndices
                    Rcomp(:,idx) = gather(idx).Waveforms.dataProcessed(:,2); %  R
                    Zcomp(:,idx) = gather(idx).Waveforms.dataProcessed(:,3); %  Z

                    if  strcmp(type,'2D')
                        distArr(1,end+1) = gather(idx).TravelInfo.distDeg*(2*pi*6371/360);
                    end
                end

                tic
                dt = gather(1).TimeAxis.dt_resample;
                t = (0:size(Rcomp,1)-1)*dt; % Time vector
                % 2D radon 
                if  strcmp(type,'2D') == 1
                    h = distArr - min(distArr);
                    % [nt, nx] = size(Rcomp);

                    Param.h = h;
                    Param.v = 1./p;
                    Param.nt = nt;
                    Param.dt = dt;
                    Param.type = 1;
                    ma = zeros(nt,np);
                    % R component
                    [mi_r, ~]  = yc_pcg(@radon_op, Param, Rcomp, zeros(size(ma)), N1, N2, 1);
                    dp_r       = radon_op(mi_r, Param, 1);
                    % Z component
                    [mi_z, ~] = yc_pcg(@radon_op, Param, Zcomp, zeros(size(ma)), N1, N2, 1);
                    dp_z      = radon_op(mi_z, Param, 1);
                    

                elseif strcmp(type,'3D') == 1  % 3D Radon for raw waveforms
                    stationList = getStations(gather);
                    dx = 10;dy = 10;
                    gridStruct = createGrid(app.DataStruct, dx, dy,1,100,5,5);
                    stlo = [stationList.stlo]';
                    stla = [stationList.stla]';
                    [rx, ry] = latlonToProjectedCoords(stlo, stla, gridStruct);
                    px = p; % 20 slowness values from -0.01 to 0.01 s/m
                    py = p; % Same range for y direction
                    % Extract spatial coordinates from reconstructed grid
                    hx = rx; % x-coordinates of receivers
                    hy = ry; % y-coordinates of receivers

                    % Create parameter structure for Radon transform
                    Param.hx  = hx;       % Receiver x-coordinates (required by radon_op)
                    Param.hy  = hy;       % Receiver y-coordinates
                    Param.px  = px;       % Slowness parameters in x-direction
                    Param.py  = py;       % Slowness parameters in y-direction
                    Param.nt = length(t); % Number of time samples
                    Param.dt = dt;        % Time sampling interval
                    Param.type = 1;       % Transform type (1 = linear Radon transform)

                    % Get number of slowness parameters
                    npx = length(px);
                    npy = length(py);
                    % Preallocate transform model matrix
                    ma = zeros(Param.nt, npx, npy); % Initial model (all zeros)

                    % input:  irregular grid
                    mi_r = yc_pcg(@radon3d_op, Param, Rcomp, ma, N1, N2, 1);
                    % Perform forward Radon transform to reconstruct data from model
                    dp_r = radon3d_op(mi_r, Param, 1);
                    mi_z = yc_pcg(@radon3d_op, Param, Zcomp, ma, N1, N2, 1);
                    % Perform forward Radon transform to reconstruct data from model
                    dp_z = radon3d_op(mi_z, Param, 1);

                end

                itr_rt = zeros(nt, nx);
                for itrace = 1:nx
                    R  = dp_r(1:nt, itrace);
                    Z  = dp_z(1:nt, itrace);
                    [itr_rt(:, itrace), ~] = makeRFitdecon_la_norm(R, Z, dt, nt, ...
                               app.deconvParam.phaseshift, app.deconvParam.gauss, app.deconvParam.itmax, app.deconvParam.minderr);
                end

                toc
                % save
                itrCell = {gather.RF};
                t = gather(1).RF.ittime;
                validMask = cellfun(@(rf) isfield(rf,'itr') && ~isempty(rf.itr), itrCell);
                validIdx = find(validMask);
                for k = 1:length(validIdx)
                    col = k;  % The column in d1
                    gInd = validIdx(k);
                    if col <= size(itr_rt,2)
                        gatherRadon(gInd).RF.itr_radon    = itr_rt(:, col);  % [Nt], reconstructed
                        gatherRadon(gInd).RF.ittime = t;          % new time axis
                    end
                end
                % save
                app.DataStructRadon = [app.DataStructRadon; gatherRadon(:)];
            end
            % format
            app.DataStructRadon = app.DataStructRadon';
            
            % plot the last one if run all events
            dist2deg = distArr/(2*pi*6371/360);
            plotWaveformsRadon(app,app.selectedEvid,dp_r,dp_z,itr_rt,dist2deg)
            
            close(msg)

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            answer = questdlg('Do you want to exist?', '', 'YES', 'NO', 'NO');
            switch answer
                case 'YES'
                    delete(app.UIFigure); 
                case 'NO'
                    return; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesRcomp1 = uiaxes(app.UIFigure);
            title(app.UIAxesRcomp1, 'R component')
            xlabel(app.UIAxesRcomp1, 'X')
            ylabel(app.UIAxesRcomp1, 'Y')
            zlabel(app.UIAxesRcomp1, 'Z')
            app.UIAxesZcomp1 = uiaxes(app.UIFigure);
            title(app.UIAxesZcomp1, 'Z component')
            xlabel(app.UIAxesZcomp1, 'X')
            ylabel(app.UIAxesZcomp1, 'Y')
            zlabel(app.UIAxesZcomp1, 'Z')
            app.UIAxesRF1 = uiaxes(app.UIFigure);
            title(app.UIAxesRF1, 'RF')
            xlabel(app.UIAxesRF1, 'X')
            ylabel(app.UIAxesRF1, 'Y')
            zlabel(app.UIAxesRF1, 'Z')
            app.UIAxesRcomp2 = uiaxes(app.UIFigure);
            title(app.UIAxesRcomp2, 'R component')
            xlabel(app.UIAxesRcomp2, 'X')
            ylabel(app.UIAxesRcomp2, 'Y')
            zlabel(app.UIAxesRcomp2, 'Z')
            app.UIAxesZcomp2 = uiaxes(app.UIFigure);
            title(app.UIAxesZcomp2, 'Z component')
            xlabel(app.UIAxesZcomp2, 'X')
            ylabel(app.UIAxesZcomp2, 'Y')
            zlabel(app.UIAxesZcomp2, 'Z')
            app.UIAxesRF2 = uiaxes(app.UIFigure);
            title(app.UIAxesRF2, 'RF (denoised)')
            xlabel(app.UIAxesRF2, 'X')
            ylabel(app.UIAxesRF2, 'Y')
            zlabel(app.UIAxesRF2, 'Z')
            app.RadonTransformPanel = uilabel(app.UIFigure);
            app.RadonTransformPanel.Text = 'Radon Transform Panel';
            app.RadonParamPanel = uipanel(app.UIFigure);
            app.RadonParamPanel.Title = 'Radon Param';
            app.PminskmEditFieldLabel = uilabel(app.RadonParamPanel);
            app.PminskmEditFieldLabel.Text = 'Pmin (s/km):';
            app.PminskmEditField = uieditfield(app.RadonParamPanel, 'numeric');
            app.PmaxskmLabel = uilabel(app.RadonParamPanel);
            app.PmaxskmLabel.Text = 'Pmax (s/km):';
            app.PmaxskmEditField = uieditfield(app.RadonParamPanel, 'numeric');
            app.N2EditFieldLabel = uilabel(app.RadonParamPanel);
            app.N2EditFieldLabel.Text = 'N2:';
            app.N2EditField = uieditfield(app.RadonParamPanel, 'numeric');
            app.N1Label = uilabel(app.RadonParamPanel);
            app.N1Label.Text = 'N1 (CG ):';
            app.N1CGEditField = uieditfield(app.RadonParamPanel, 'numeric');
            app.SelectEventsDropDown = uidropdown(app.RadonParamPanel);
            app.SelectEventsDropDown.Items = {'Select Events'};
            app.SelectEventsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEventsDropDownValueChanged, true);
            app.SelectEventsDropDown.Value = 'Select Events';
            app.RunButton = uibutton(app.RadonParamPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.RadonParamPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.dpEditFieldLabel = uilabel(app.RadonParamPanel);
            app.dpEditFieldLabel.Text = 'dp:';
            app.dpEditField = uieditfield(app.RadonParamPanel, 'numeric');
            app.EventIDLabel = uilabel(app.RadonParamPanel);
            app.EventIDLabel.Text = 'Event ID:';
            app.TypeLabel = uilabel(app.RadonParamPanel);
            app.TypeLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.RadonParamPanel);
            app.TypeDropDown.Items = {'2D', '3D'};
            app.TypeDropDown.Value = '2D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadonTransformApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end