classdef RankReductionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadMenu                      matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        PlotComparisonButton          matlab.ui.control.Button
        EventSelectionDropDown        matlab.ui.control.DropDown
        SelectEventDropDownLabel      matlab.ui.control.Label
        statusLabel                   matlab.ui.control.Label
        processingWorkflowLabel       matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        RankReductionPanelLabel       matlab.ui.control.Label
        latmaxEditField               matlab.ui.control.NumericEditField
        latmaxEditFieldLabel          matlab.ui.control.Label
        latminEditField               matlab.ui.control.NumericEditField
        latminEditFieldLabel          matlab.ui.control.Label
        nyEditField                   matlab.ui.control.NumericEditField
        nyEditFieldLabel              matlab.ui.control.Label
        nxEditField                   matlab.ui.control.NumericEditField
        nxEditFieldLabel              matlab.ui.control.Label
        modeEditField                 matlab.ui.control.NumericEditField
        modeEditFieldLabel            matlab.ui.control.Label
        tmaxEditField                 matlab.ui.control.NumericEditField
        tmaxEditFieldLabel            matlab.ui.control.Label
        epsEditField                  matlab.ui.control.NumericEditField
        epsEditFieldLabel             matlab.ui.control.Label
        niterEditField                matlab.ui.control.NumericEditField
        niterEditFieldLabel           matlab.ui.control.Label
        KEditField                    matlab.ui.control.NumericEditField
        KEditFieldLabel               matlab.ui.control.Label
        rankEditField                 matlab.ui.control.NumericEditField
        rankEditFieldLabel            matlab.ui.control.Label
        fhighEditField                matlab.ui.control.NumericEditField
        fhighEditFieldLabel           matlab.ui.control.Label
        flowEditField                 matlab.ui.control.NumericEditField
        flowEditFieldLabel            matlab.ui.control.Label
        lonmaxEditField               matlab.ui.control.NumericEditField
        lonmaxEditFieldLabel          matlab.ui.control.Label
        lonminEditField               matlab.ui.control.NumericEditField
        lonminEditFieldLabel          matlab.ui.control.Label
        GridParametersLabel           matlab.ui.control.Label
        RankReductionParametersLabel  matlab.ui.control.Label
        RunRankReductionButton        matlab.ui.control.Button
        CreateGridButton              matlab.ui.control.Button
        verboseCheckBox               matlab.ui.control.CheckBox
        RawWaveformUIAxes             matlab.ui.control.UIAxes
        DenoisedWaveformUIAxes        matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        DataStructDRR    % 用于存放处理后的地震数据
        Events           % 存放所有事件信息的结构数组
        Stations         % 存放所有台站信息的结构数组
        selectedEvid     % 存放选取的事件序号
        selectedStacode  % 存放选取的台站序号
    end
    
    properties (Access = public)
        DataStruct          % 用于存放地震数据
        RankReductionParam  % 用于存放处理参数
        mainApp             % 主程序App
    end
    
    
    methods (Access = public)
        function loadConfigData(app, defaultParam)
            % 1) 把输入的 config 暂存到 app 的属性
            app.RankReductionParam = defaultParam;

            % 2) 将 inputConfig 各字段值写到对应的控件：
            app.fhighEditField.Value = defaultParam.fhigh;
            app.flowEditField.Value = defaultParam.flow;
            app.lonminEditField.Value = defaultParam.lonmin;
            app.latminEditField.Value = defaultParam.latmin;
            app.nxEditField.Value = defaultParam.nx;
            app.nyEditField.Value = defaultParam.ny;
            app.rankEditField.Value = defaultParam.rank;
            app.niterEditField.Value = defaultParam.niter;
            app.modeEditField.Value = defaultParam.mode;
            app.verboseCheckBox.Value = defaultParam.verb;
            app.epsEditField.Value = defaultParam.eps;
            app.KEditField.Value = defaultParam.K;
            app.tmaxEditField.Value = defaultParam.tmax;
            % 同时更新事件列表
            updateEventDropdown(app)
        end
    end
    
    methods (Access = private)
        function updateEventDropdown(app)
            if isempty(app.DataStruct)
                app.EventSelectionDropDown.Items = {'(no events)'};
                app.EventSelectionDropDown.Value = '(no events)';
                return;
            end

            eList = getEvents(app.DataStruct);
            eIDs  = {eList.evid};
            % 在这里也可以加一个 "All" 选项:
            items = [{'None','All'}, eIDs];

            app.EventSelectionDropDown.Items = items;
            app.EventSelectionDropDown.Value = 'None'; % 或 items{1}
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            updateEventDropdown(app)
        end

        % Button pushed function: CreateGridButton
        function CreateGridButtonPushed(app, event)
            
            %% 1. Get station info
            gather = app.DataStruct;
            stationList = getStations(gather);
            % stationList should have fields .stlo, .stla
            % Flatten stlo, stla to vector if needed
            stlo = [stationList.stlo]';  
            stla = [stationList.stla]';
            
            % transform lat lon to x y (relative to param.lonmin, param.latmin)
            [rx, ry] = latlon2xy(stlo, stla, app.lonminEditField.Value, app.latminEditField.Value);
            % shift to ensure min coordinate=0
            rx = rx - min(rx);
            ry = ry - min(ry);
            
            %% 2. Define grid
            ox = 0;  % origin x
            oy = 0;  % origin y
            mx = ceil(max(rx));  % max value of x
            my = ceil(max(ry));  % max value of y
            
            dx = (mx - ox) / (app.nxEditField.Value - 1);
            dy = (my - oy) / (app.nyEditField.Value - 1);
            
            xout = ox : dx : mx;  % regular x-grid
            yout = oy : dy : my;  % regular y-grid

            nx = app.nxEditField.Value;
            ny = app.nyEditField.Value;

            xx= ox+(0:nx-1)*dx;
            yy= oy+(0:ny-1)*dy;
            [Xgrid,Ygrid] = meshgrid(xx,yy);

            delete(allchild(app.UIAxes));
            scatter(app.UIAxes,rx,ry,50,'b^'); hold(app.UIAxes, 'on');
            scatter(app.UIAxes,Xgrid,Ygrid,50,'ro')
            set(app.UIAxes, 'xlim',[ox-10 mx+10], 'ylim',[oy-10 my+10]);
            hold(app.UIAxes, 'off')
            
            app.RankReductionParam.rx = rx;
            app.RankReductionParam.ry = ry;
        
            app.RankReductionParam.nx = nx;
            app.RankReductionParam.ny = ny;
            app.RankReductionParam.ox = ox;
            app.RankReductionParam.oy = oy;
            app.RankReductionParam.mx = mx;
            app.RankReductionParam.my = my;

            app.statusLabel.Text = 'Grid construction done!';
                    
        end

        % Menu selected function: LoadMenu
        function onLoadMenuSelected(app, event)
            % 该函数在点击 File->Load 菜单时执行。
            % 旨在加载已经保存的 DRR 文件 (含 DataStructDRR / RankReductionParam)
            try
                [filename, pathname] = uigetfile({'*.mat'}, '请选择要加载的数据文件');
                if isequal(filename,0) || isequal(pathname,0)
                    % 用户取消
                    return;
                end
                fullpath = fullfile(pathname, filename);

                loaded = load(fullpath);

                % 1) 如果文件中存在 DataStructDRR，则将其赋给 app.DataStruct (或 app.DataStructDRR)
                if isfield(loaded, 'DataStructDRR')
                    % 若您想让该 DRR 数据成为本 App 的“当前数据”，可存 app.DataStruct
                    app.DataStruct = loaded.DataStructDRR;
                    % 或者如果想区分“原始”和“处理后”，可用 app.DataStructDRR = ...
                    % app.DataStructDRR = loaded.DataStructDRR;

                    % 给个提示
                    fprintf('已加载 DataStructDRR, 大小 = %d 条记录.\n', numel(loaded.DataStructDRR));
                elseif isfield(loaded, 'DataStruct')
                    % 如果是老式文件结构, 则读到 DataStruct
                    app.DataStruct = loaded.DataStruct;
                    fprintf('已加载旧格式 DataStruct.\n');
                else
                    % 否则提示无可用数据
                    uialert(app.UIFigure, ...
                        '文件中未找到 DataStructDRR / DataStruct 字段。','提示','Icon','info');
                end

                % 2) 如果文件包含 RankReductionParam，也可更新
                if isfield(loaded, 'RankReductionParam')
                    app.RankReductionParam = loaded.RankReductionParam;
                    fprintf('已加载 RankReductionParam.\n');
                else
                    % 若无此字段也可不做操作
                    fprintf('该文件无 RankReductionParam 字段.\n');
                end

                % 3) 给用户反馈
                uialert(app.UIFigure, ...
                    sprintf('已成功加载: %s\n可在界面中查看或继续处理。',filename), ...
                    '加载完成','Icon','success');
                % 更新事件列表
                updateEventDropdown(app)
            catch ME
                % 出错处理
                uialert(app.UIFigure, ...
                    ['加载数据时出现错误: ', ME.message], ...
                    '错误','Icon','error');
            end

        
        end

        % Button pushed function: RunRankReductionButton
        function RunRankReductionButtonPushed(app, event)
            % 添加数据加载检查
            if isempty(app.DataStruct)
                uialert(app.UIFigure, '请先加载数据！', '错误', 'Icon','error');
                return;
            end
            % 检查参数合法性
            if app.rankEditField.Value <= 0
                uialert(app.UIFigure, 'rank 必须为正整数', '参数错误', 'Icon','error');
                return;
            end
            
            if isempty(app.selectedEvid)
                uialert(app.UIFigure,'尚未选择任何事件！','错误');
                return;
            end
%             eventList = getEvents(app.DataStruct);  % 返回 struct array of event info
%             eventIDs  = {eventList.evid};

            % 如果 selectedEvid 是 cell array of strings (事件 ID),
            % 如果用户选 "All", app.selectedEvid == all eIDs
            eventIDs = app.selectedEvid;  

            DataStructDRR = [];  % 存储重建后的结果
            app.RankReductionParam.flow = app.flowEditField.Value;
            app.RankReductionParam.fhigh = app.fhighEditField.Value;
    
            app.RankReductionParam.rank = app.rankEditField.Value;
            app.RankReductionParam.K = app.KEditField.Value;
            app.RankReductionParam.niter = app.niterEditField.Value;
            app.RankReductionParam.eps = app.epsEditField.Value;
            app.RankReductionParam.verb = app.verboseCheckBox.Value;
            app.RankReductionParam.mode = app.modeEditField.Value;
    
            minStationCount = 50; % 阈值: 若事件台站数小于此数, 则跳过
            % 添加进度条
            h = uiprogressdlg(app.UIFigure, 'Title','处理中', 'Message','正在降噪...');

            for iEvent = 1:length(eventIDs)
                % 更新进度
                h.Message = sprintf('处理事件 %d/%d', iEvent, length(eventIDs));
                h.Value = iEvent/length(eventIDs);
                evid = eventIDs{iEvent};
                [gather, matchIndex] = getCommonEventGather(app.DataStruct, evid);
            
                if length(gather) < minStationCount
                    fprintf('Event %s only has %d stations (<%d). Skipped.\n', ...
                        evid, length(gather), minStationCount);
                    continue;
                end

                % 调用rankReduction对该事件进行3D数据重构
                % gather => [ gather(i).RF.itr ...], gather(i).StationInfo ...
                [gatherReconstructed, d1_otg] = rankReduction(gather, app.RankReductionParam);
            
                % 将结果并入 DataStruct_drr
                DataStructDRR = [DataStructDRR; gatherReconstructed(:)];
            
                % 若需要调试/可视化 d1_otg，可在 rankReduction 内部或此处实现
                % close all;  % 如果怕产生过多图窗
            end
            close(h)

            % 将结果转置回与 DataStruct 类似的形状(若需要)
            app.DataStructDRR = DataStructDRR';
            app.statusLabel.Text = 'DRR done!';
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % 将其赋值给主 App
            app.mainApp.config.RankReductionParam = app.RankReductionParam;
            app.mainApp.DataStruct = app.DataStructDRR;
            app.mainApp.rankReductionStatus = 'OK';
            % 关闭窗口
            delete(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.mainApp.rankReductionStatus = 'Cancel';
            delete(app.UIFigure);
        end

        % Value changed function: EventSelectionDropDown
        function onEventSelectionChanged(app, event)
           % 记录用户选的 event id
            selected = app.EventSelectionDropDown.Value;
            if strcmp(selected,'All')
                % 如果用户选择“All”，让 selectedEvid = 所有事件的 ID
                eList = getEvents(app.DataStruct);  % struct array of events
                app.selectedEvid = {eList.evid};    % 这里 eList.evid 本身可能是标量/数组
            else
                % 用户只选中了一个事件ID, 需要包装成 cell array
                app.selectedEvid = {selected};
            end
        end

        % Button pushed function: PlotComparisonButton
        function PlotComparisonButtonPushed(app, event)
            % 1) 确定要对比的事件
            eSelected = app.EventSelectionDropDown.Value; % 单选
            if strcmp(eSelected,'All') || strcmp(eSelected,'(no events)')
                uialert(app.UIFigure,'请选择具体事件','提示');
                return;
            end

            % 2) 从原数据/处理后数据里获取 gather
            [gatherRaw, ~]   = getCommonEventGather(app.DataStruct, eSelected);
            [gatherDRR, ~]   = getCommonEventGather(app.DataStructDRR, eSelected);

            if isempty(gatherRaw) || isempty(gatherDRR)
                uialert(app.UIFigure,'无法获取选中事件数据','错误');
                return;
            end

            % 3) 在 RawWaveformUIAxes 上绘制“原始”
            cla(app.RawWaveformUIAxes,'reset');
            plotCommonEventGather(gatherRaw,[],'trace','imagesc', app.RawWaveformUIAxes);
            title(app.RawWaveformUIAxes, ['Raw Waveforms - Event ', eSelected]);

            % 4) 在 DenoisedWaveformUIAxes 上绘制“处理后”
            cla(app.DenoisedWaveformUIAxes,'reset');
            plotCommonEventGather(gatherDRR,[],'trace','imagesc',app.DenoisedWaveformUIAxes)            
            title(app.DenoisedWaveformUIAxes, ['Denoised Waveforms - Event ', eSelected]);
        end

        % Menu selected function: SaveMenu
        function onSaveMenuSelected(app, event)
            % 当用户点击 "File -> 保存 (Save)" 菜单时执行
            try
                % 1) 如果要保存的处理后数据是 app.DataStructDRR，则先检查是否非空
                if isempty(app.DataStructDRR)
                    uialert(app.UIFigure, ...
                        '暂无 DRR 处理结果可保存，请先运行降秩处理！',...
                        '提示', 'Icon','info');
                    return;
                end

                % 2) 自动生成一个默认文件名
                % 例如加上时间戳，也可以在末尾加上事件ID等关键信息
                timestamp  = datestr(now,'yyyymmdd_HHMMSS');
                defaultName= ['DataStructDRR_', timestamp, '.mat'];

                % 3) 弹出文件保存对话框
                [filename, pathname] = uiputfile(defaultName, '保存 DRR 结果文件');
                if isequal(filename,0) || isequal(pathname,0)
                    % 用户取消选择
                    return;
                end
                fullpath = fullfile(pathname, filename);

                % 4) 将要保存的变量从 app 属性中取出
                %    通常只需保存 "DataStructDRR" 即可，也可一并保存 Param
                DataStructDRR = app.DataStructDRR;
                RankReductionParam = app.RankReductionParam;

                % 5) 执行保存
                save(fullpath,'DataStructDRR','RankReductionParam','-v7.3');
                % "-v7.3" 允许保存大于2GB的数据

                % 6) 提示
                uialert(app.UIFigure, ['已保存到: ', fullpath], '保存成功','Icon','success');

            catch ME
                % 出错处理
                uialert(app.UIFigure, ...
                    ['保存数据时出现错误: ', ME.message], ...
                    '错误','Icon','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @onLoadMenuSelected, true);
            app.LoadMenu.Text = '加载 (Load)';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @onSaveMenuSelected, true);
            app.SaveMenu.Text = '保存 (Save)';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Grid Geometry')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DenoisedWaveformUIAxes = uiaxes(app.GridLayout);
            title(app.DenoisedWaveformUIAxes, 'Reconstructed Waveforms')
            xlabel(app.DenoisedWaveformUIAxes, 'X')
            ylabel(app.DenoisedWaveformUIAxes, 'Time (sec)')
            zlabel(app.DenoisedWaveformUIAxes, 'Z')
            app.DenoisedWaveformUIAxes.Visible = 'off';
            app.RawWaveformUIAxes = uiaxes(app.GridLayout);
            title(app.RawWaveformUIAxes, 'Raw Waveforms')
            ylabel(app.RawWaveformUIAxes, 'Time (sec)')
            zlabel(app.RawWaveformUIAxes, 'Z')
            app.RawWaveformUIAxes.Visible = 'off';
            app.verboseCheckBox = uicheckbox(app.GridLayout);
            app.verboseCheckBox.Text = 'verbose';
            app.CreateGridButton = uibutton(app.GridLayout, 'push');
            app.CreateGridButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGridButtonPushed, true);
            app.CreateGridButton.Text = 'Create Grid';
            app.RunRankReductionButton = uibutton(app.GridLayout, 'push');
            app.RunRankReductionButton.ButtonPushedFcn = createCallbackFcn(app, @RunRankReductionButtonPushed, true);
            app.RunRankReductionButton.Text = 'Run Rank Reduction';
            app.RankReductionParametersLabel = uilabel(app.GridLayout);
            app.RankReductionParametersLabel.Text = 'Rank Reduction Parameters';
            app.GridParametersLabel = uilabel(app.GridLayout);
            app.GridParametersLabel.Text = 'Grid Parameters';
            app.lonminEditFieldLabel = uilabel(app.GridLayout);
            app.lonminEditFieldLabel.Text = 'lonmin';
            app.lonminEditField = uieditfield(app.GridLayout, 'numeric');
            app.lonminEditField.Value = 109;
            app.lonmaxEditFieldLabel = uilabel(app.GridLayout);
            app.lonmaxEditFieldLabel.Text = 'lonmax';
            app.lonmaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.flowEditFieldLabel = uilabel(app.GridLayout);
            app.flowEditFieldLabel.Text = 'flow';
            app.flowEditField = uieditfield(app.GridLayout, 'numeric');
            app.flowEditField.Value = 0.1;
            app.fhighEditFieldLabel = uilabel(app.GridLayout);
            app.fhighEditFieldLabel.Text = 'fhigh';
            app.fhighEditField = uieditfield(app.GridLayout, 'numeric');
            app.fhighEditField.Value = 1.2;
            app.rankEditFieldLabel = uilabel(app.GridLayout);
            app.rankEditFieldLabel.Text = 'rank';
            app.rankEditField = uieditfield(app.GridLayout, 'numeric');
            app.rankEditField.Value = 5;
            app.KEditFieldLabel = uilabel(app.GridLayout);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.GridLayout, 'numeric');
            app.KEditField.Value = 4;
            app.niterEditFieldLabel = uilabel(app.GridLayout);
            app.niterEditFieldLabel.Text = 'niter';
            app.niterEditField = uieditfield(app.GridLayout, 'numeric');
            app.niterEditField.Value = 10;
            app.epsEditFieldLabel = uilabel(app.GridLayout);
            app.epsEditFieldLabel.Text = 'eps';
            app.epsEditField = uieditfield(app.GridLayout, 'numeric');
            app.epsEditField.Value = 0.0001;
            app.tmaxEditFieldLabel = uilabel(app.GridLayout);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.tmaxEditField.Value = 50;
            app.modeEditFieldLabel = uilabel(app.GridLayout);
            app.modeEditFieldLabel.Text = 'mode';
            app.modeEditField = uieditfield(app.GridLayout, 'numeric');
            app.modeEditField.Value = 1;
            app.nxEditFieldLabel = uilabel(app.GridLayout);
            app.nxEditFieldLabel.Text = 'nx';
            app.nxEditField = uieditfield(app.GridLayout, 'numeric');
            app.nxEditField.Value = 11;
            app.nyEditFieldLabel = uilabel(app.GridLayout);
            app.nyEditFieldLabel.Text = 'ny';
            app.nyEditField = uieditfield(app.GridLayout, 'numeric');
            app.nyEditField.Value = 14;
            app.latminEditFieldLabel = uilabel(app.GridLayout);
            app.latminEditFieldLabel.Text = 'latmin';
            app.latminEditField = uieditfield(app.GridLayout, 'numeric');
            app.latminEditField.Value = 41.1667;
            app.latmaxEditFieldLabel = uilabel(app.GridLayout);
            app.latmaxEditFieldLabel.Text = 'latmax';
            app.latmaxEditField = uieditfield(app.GridLayout, 'numeric');
            app.RankReductionPanelLabel = uilabel(app.GridLayout);
            app.RankReductionPanelLabel.Text = 'Rank Reduction Panel';
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.processingWorkflowLabel = uilabel(app.GridLayout);
            app.processingWorkflowLabel.Text = 'Status:';
            app.statusLabel = uilabel(app.GridLayout);
            app.statusLabel.Text = '';
            app.SelectEventDropDownLabel = uilabel(app.GridLayout);
            app.SelectEventDropDownLabel.Text = 'Select Event';
            app.EventSelectionDropDown = uidropdown(app.GridLayout);
            app.EventSelectionDropDown.Items = {'All'};
            app.EventSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @onEventSelectionChanged, true);
            app.EventSelectionDropDown.Value = 'All';
            app.PlotComparisonButton = uibutton(app.GridLayout, 'push');
            app.PlotComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @PlotComparisonButtonPushed, true);
            app.PlotComparisonButton.Text = 'Plot Comparison';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RankReductionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end